classdef PSC < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        graphingtypeDropDown           matlab.ui.control.DropDown
        ManualSelectionLabel           matlab.ui.control.Label
        stopmanualButton               matlab.ui.control.Button
        startmanualButton              matlab.ui.control.Button
        NrejectedEditField             matlab.ui.control.NumericEditField
        NrejectedEditFieldLabel        matlab.ui.control.Label
        NacceptedEditField             matlab.ui.control.NumericEditField
        NacceptedEditFieldLabel        matlab.ui.control.Label
        currentPSCSpinner              matlab.ui.control.Spinner
        currentPSCSpinnerLabel         matlab.ui.control.Label
        manualPanel                    matlab.ui.container.Panel
        REJECTButton                   matlab.ui.control.Button
        ACCEPTButton                   matlab.ui.control.Button
        RIGHTButton                    matlab.ui.control.Button
        LEFTButton                     matlab.ui.control.Button
        viewDropDown                   matlab.ui.control.DropDown
        viewDropDownLabel              matlab.ui.control.Label
        filePanel                      matlab.ui.container.Panel
        saveasExcelButton              matlab.ui.control.Button
        resetButton                    matlab.ui.control.Button
        analysisfileEditField          matlab.ui.control.EditField
        analysisfileEditFieldLabel     matlab.ui.control.Label
        saveasMATButton                matlab.ui.control.Button
        chooseMATfileButton            matlab.ui.control.Button
        resultsPanel                   matlab.ui.container.Panel
        intereventintervalEditField    matlab.ui.control.NumericEditField
        intereventintervalmsEditFieldLabel  matlab.ui.control.Label
        risetimeEditField              matlab.ui.control.NumericEditField
        risetimemsLabel                matlab.ui.control.Label
        chargeEditField                matlab.ui.control.NumericEditField
        chargefCEditFieldLabel         matlab.ui.control.Label
        amplitudeEditField             matlab.ui.control.NumericEditField
        amplitudepAEditFieldLabel      matlab.ui.control.Label
        NEditField                     matlab.ui.control.NumericEditField
        NEditFieldLabel                matlab.ui.control.Label
        stddevLabel                    matlab.ui.control.Label
        meanLabel                      matlab.ui.control.Label
        intereventintervalSDEditField  matlab.ui.control.NumericEditField
        risetimeSDEditField            matlab.ui.control.NumericEditField
        chargeSDEditField              matlab.ui.control.NumericEditField
        amplitudeSDEditField           matlab.ui.control.NumericEditField
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        CallingApp % LCSMS_analysis
    end
    
    properties (Access = private)
        AnalyzedData % analysis results from LCSMS_analysis
        PlotHandle % handle to plotted traces
        ManualSelectionOn % true if in manual selection mode
    end
    
    
    methods (Access = private)
        
        function [] = fillinresults(app)
            idx = app.AnalyzedData.accept;
            viewType = app.viewDropDown.Value;
            switch viewType
                case 'all PSCs'
                    idx = ones(app.AnalyzedData.N,1);
                    traceColor = [];
                case 'rejected PSCs'
                    idx = ~idx;
                    traceColor = 'r';
                otherwise
                    traceColor = 'b';
            end
            N = nnz(idx);
            if N==0
                cla(app.UIAxes)
                app.NEditField.Value = N;
                app.amplitudeEditField.Value = 0;
                app.amplitudeSDEditField.Value = 0;
                app.chargeEditField.Value = 0;
                app.chargeSDEditField.Value = 0;
                app.risetimeEditField.Value = 0;
                app.risetimeSDEditField.Value = 0;
                app.intereventintervalEditField.Value = 0;
                app.intereventintervalSDEditField.Value = 0;
                return
            else
                amp = app.AnalyzedData.amp(find(idx));
                area = app.AnalyzedData.area(find(idx));
                rise = app.AnalyzedData.rise(find(idx));
                foo = app.AnalyzedData.startPt(find(idx));
                iei = diff(foo)*app.AnalyzedData.dt; % msec between events
                iei(iei<0) = [];
                app.NEditField.Value = N;
                app.amplitudeEditField.Value = mean(amp);
                app.amplitudeSDEditField.Value = std(amp);
                app.chargeEditField.Value = mean(area);
                app.chargeSDEditField.Value = std(area);
                app.risetimeEditField.Value = mean(rise);
                app.risetimeSDEditField.Value = std(rise);
                if ~isempty(iei)
                    app.intereventintervalEditField.Value = mean(iei);
                    app.intereventintervalSDEditField.Value = std(iei);
                else
                    app.intereventintervalEditField.Value = 0;
                    app.intereventintervalSDEditField.Value = 0;
                end
                xdata = (1:length(app.AnalyzedData.clipped))*app.AnalyzedData.dt-app.AnalyzedData.dt;
                ydata = app.AnalyzedData.clipped';
                ydata = ydata(:,find(idx)); %#ok<FNDSB>
                switch app.graphingtypeDropDown.Value
                    case 'individual traces'
                        app.UIAxes.XLabel.String = 'Time (msec)';
                        app.UIAxes.YLabel.String = 'Current (pA)';
                        if  strcmp(viewType,'all PSCs')
                            plot(app.UIAxes,xdata,ydata);
                        else
                            plot(app.UIAxes,xdata,ydata,"Color",traceColor)
                        end
                    case 'histogram'
                        app.UIAxes.XLabel.String = 'Current (pA)';
                        app.UIAxes.YLabel.String = 'Number';
                        if isempty(traceColor)
                            traceColor = 'm';
                        end
                        histogram(app.UIAxes,amp,'BinWidth',1,'FaceColor',traceColor)
                    case 'mean trace'
                        app.UIAxes.XLabel.String = 'Time (msec)';
                        app.UIAxes.YLabel.String = 'Current (pA)';             
                        if isempty(traceColor)
                            traceColor = 'm';
                        end                
                        plot(app.UIAxes,xdata,mean(ydata,2),'Color',traceColor)                       
                    otherwise
                        
                end

                
           end
            
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp, fileName)
            app.ManualSelectionLabel.Text = '';
            app.ManualSelectionOn = false;
            if nargin>1
                app.CallingApp = MainApp;
                app.analysisfileEditField.Value = fileName;
                load(fileName,'analyzedData')
                app.AnalyzedData = analyzedData;
                N = numel(analyzedData.amp);
                app.AnalyzedData.N = N;
                app.AnalyzedData.accept = ones(N,1);
                fillinresults(app)
            end

        end

        % Button pushed function: startmanualButton
        function startmanualButtonPushed(app, event)
            app.ManualSelectionLabel.Text = 'Manual Selection';
            app.ManualSelectionOn = true;
            app.currentPSCSpinner.Value = 1;
            idx = app.AnalyzedData.accept;
            app.NacceptedEditField.Value = nnz(idx);
            app.NrejectedEditField.Value = nnz(~idx);
            cla(app.UIAxes)
            ydata = app.AnalyzedData.clipped(1,:);
            xdata = (1:size(ydata,2))*app.AnalyzedData.dt;
            if app.AnalyzedData.accept(1)
                plot(app.UIAxes,xdata,ydata,'b')
            else
                plot(app.UIAxes,xdata,ydata,'r')
            end
            app.viewDropDown.Value = 'accepted PSCs';

        end

        % Button pushed function: stopmanualButton
        function stopmanualButtonPushed(app, event)
            app.ManualSelectionLabel.Text = '';
            app.ManualSelectionOn = false;
            fillinresults(app)
            
        end

        % Value changed function: currentPSCSpinner
        function currentPSCSpinnerValueChanged(app, event)
            value = app.currentPSCSpinner.Value;
            if ~app.ManualSelectionOn
                disp('Press start manual button first')
                return
            end
            if value>app.AnalyzedData.N
                value = app.AnalyzedData.N;
                app.currentPSCSpinner.Value = value;
            end
            cla(app.UIAxes)
            ydata = app.AnalyzedData.clipped(value,:);
            xdata = (1:size(ydata,2))*app.AnalyzedData.dt;
            if app.AnalyzedData.accept(value)
                plot(app.UIAxes,xdata,ydata,'b')
            else
                plot(app.UIAxes,xdata,ydata,'r')
            end
            
        end

        % Button pushed function: RIGHTButton
        function RIGHTButtonPushed(app, event)
            if ~app.ManualSelectionOn
                disp('Press start manual button first')
                return
            end
            if app.currentPSCSpinner.Value<app.AnalyzedData.N
                app.currentPSCSpinner.Value = app.currentPSCSpinner.Value + 1;
            else
                return
            end
            currentPSCSpinnerValueChanged(app)
        end

        % Button pushed function: LEFTButton
        function LEFTButtonPushed(app, event)
            if ~app.ManualSelectionOn
                disp('Press start manual button first')
                return
            end
            if app.currentPSCSpinner.Value>1
                app.currentPSCSpinner.Value = app.currentPSCSpinner.Value - 1;
            else
                return
            end
            currentPSCSpinnerValueChanged(app)            
        end

        % Button pushed function: ACCEPTButton
        function ACCEPTButtonPushed(app, event)
            if ~app.ManualSelectionOn
                disp('Press start manual button first')
                return
            end
            app.AnalyzedData.accept(app.currentPSCSpinner.Value) = true;
            fillinresults(app)
            idx = app.AnalyzedData.accept;
            app.NacceptedEditField.Value = nnz(idx);
            app.NrejectedEditField.Value = nnz(~idx);
            ydata = app.AnalyzedData.clipped(app.currentPSCSpinner.Value,:);
            xdata = (1:size(ydata,2))*app.AnalyzedData.dt;
            plot(app.UIAxes,xdata,ydata,'b')
            if app.currentPSCSpinner.Value<app.AnalyzedData.N
                app.currentPSCSpinner.Value = app.currentPSCSpinner.Value + 1;
                currentPSCSpinnerValueChanged(app)
            end
            
        end

        % Button pushed function: REJECTButton
        function REJECTButtonPushed(app, event)
            if ~app.ManualSelectionOn
                disp('Press start manual button first')
                return
            end
            app.AnalyzedData.accept(app.currentPSCSpinner.Value) = false;
            fillinresults(app)
            idx = app.AnalyzedData.accept;
            app.NacceptedEditField.Value = nnz(idx);
            app.NrejectedEditField.Value = nnz(~idx);
            ydata = app.AnalyzedData.clipped(app.currentPSCSpinner.Value,:);
            xdata = (1:size(ydata,2))*app.AnalyzedData.dt;
            plot(app.UIAxes,xdata,ydata,'r')
            if app.currentPSCSpinner.Value<app.AnalyzedData.N
                app.currentPSCSpinner.Value = app.currentPSCSpinner.Value + 1;
                currentPSCSpinnerValueChanged(app)
            end

        end

        % Value changed function: graphingtypeDropDown, viewDropDown
        function viewDropDownValueChanged(app, event)
            if app.ManualSelectionOn
                disp('We are in manual selection mode')
                disp('Turn off manual selection before changing view')
                return
            end
            fillinresults(app)
        end

        % Button pushed function: saveasMATButton
        function saveasMATButtonPushed(app, event)
            fileName = app.analysisfileEditField.Value;
            analyzedData = app.AnalyzedData;
            save(fileName,'analyzedData')
            
        end

        % Button pushed function: resetButton
        function resetButtonPushed(app, event)
            N = app.AnalyzedData.N;
            app.AnalyzedData.accept = ones(N,1);
            if ~app.ManualSelectionOn
                fillinresults(app)
            end

        end

        % Button pushed function: chooseMATfileButton
        function chooseMATfileButtonPushed(app, event)
            [fileName,fileDirectory] = uigetfile([pwd,filesep,'*.mat']);
            if ~isempty(fileName)
                app.analysisfileEditField.Value = fileName;
                load([fileDirectory,fileName],'analyzedData')
                app.AnalyzedData = analyzedData;
                fillinresults(app)
            end
            
        end

        % Button pushed function: saveasExcelButton
        function saveasExcelButtonPushed(app, event)
            fileName = app.analysisfileEditField.Value;
            dotPosition = strfind(fileName,'.');
            if ~isempty(dotPosition)
                fileName = [fileName(1:dotPosition),'xlsx'];
            else
                fileName = [fileName,'.xlsx'];
            end
            idx = find(app.AnalyzedData.accept);
            analyzedData = app.AnalyzedData;
            analyzedData.clipped = analyzedData.clipped(idx,:);
            analyzedData.amp = analyzedData.amp(idx);
            analyzedData.rise = analyzedData.rise(idx);
            analyzedData.area = analyzedData.area(idx);
            analyzedData.second = analyzedData.second(idx);
            analyzedData.startPt = analyzedData.startPt(idx);
            analyzedData.peakPt = analyzedData.peakPt(idx);
            warning off
            writematrix(analyzedData.clipped',fileName,'Sheet',1,'Range','A2')
            writematrix('Raw Data: each column is one 60-msec event',fileName,'Sheet',1,'Range','A1')
            columnLabels = {'amplitude','rise time','charge','second','startPt','peakPt'};
            writecell(columnLabels,fileName,'Sheet',2,'Range','A1')
            boo = [analyzedData.amp, analyzedData.rise, analyzedData.area, analyzedData.second, analyzedData.startPt, analyzedData.peakPt];
            writematrix(boo,fileName,'Sheet',2,'Range','A2')
            warning on

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.Tag = 'PSC_GUI';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.resultsPanel = uipanel(app.UIFigure);
            app.resultsPanel.Title = 'results';
            app.amplitudeSDEditField = uieditfield(app.resultsPanel, 'numeric');
            app.amplitudeSDEditField.Limits = [0 Inf];
            app.amplitudeSDEditField.ValueDisplayFormat = '%.2f';
            app.amplitudeSDEditField.Editable = 'off';
            app.chargeSDEditField = uieditfield(app.resultsPanel, 'numeric');
            app.chargeSDEditField.Limits = [0 Inf];
            app.chargeSDEditField.ValueDisplayFormat = '%.2f';
            app.chargeSDEditField.Editable = 'off';
            app.risetimeSDEditField = uieditfield(app.resultsPanel, 'numeric');
            app.risetimeSDEditField.Limits = [0 Inf];
            app.risetimeSDEditField.ValueDisplayFormat = '%.2f';
            app.risetimeSDEditField.Editable = 'off';
            app.risetimeSDEditField.Value = 0.1;
            app.intereventintervalSDEditField = uieditfield(app.resultsPanel, 'numeric');
            app.intereventintervalSDEditField.Limits = [0 Inf];
            app.intereventintervalSDEditField.ValueDisplayFormat = '%.1f';
            app.intereventintervalSDEditField.Editable = 'off';
            app.meanLabel = uilabel(app.resultsPanel);
            app.meanLabel.Text = 'mean';
            app.stddevLabel = uilabel(app.resultsPanel);
            app.stddevLabel.Text = 'std. dev.';
            app.NEditFieldLabel = uilabel(app.resultsPanel);
            app.NEditFieldLabel.Text = 'N';
            app.NEditField = uieditfield(app.resultsPanel, 'numeric');
            app.NEditField.Limits = [0 Inf];
            app.NEditField.ValueDisplayFormat = '%.0f';
            app.NEditField.Editable = 'off';
            app.amplitudepAEditFieldLabel = uilabel(app.resultsPanel);
            app.amplitudepAEditFieldLabel.Text = 'amplitude (pA)';
            app.amplitudeEditField = uieditfield(app.resultsPanel, 'numeric');
            app.amplitudeEditField.Limits = [0 Inf];
            app.amplitudeEditField.ValueDisplayFormat = '%.2f';
            app.amplitudeEditField.Editable = 'off';
            app.chargefCEditFieldLabel = uilabel(app.resultsPanel);
            app.chargefCEditFieldLabel.Text = 'charge (fC)';
            app.chargeEditField = uieditfield(app.resultsPanel, 'numeric');
            app.chargeEditField.Limits = [0 Inf];
            app.chargeEditField.ValueDisplayFormat = '%.2f';
            app.chargeEditField.Editable = 'off';
            app.risetimemsLabel = uilabel(app.resultsPanel);
            app.risetimemsLabel.Text = 'rise time (ms)';
            app.risetimeEditField = uieditfield(app.resultsPanel, 'numeric');
            app.risetimeEditField.Limits = [0 Inf];
            app.risetimeEditField.ValueDisplayFormat = '%.2f';
            app.risetimeEditField.Editable = 'off';
            app.risetimeEditField.Value = 0.1;
            app.intereventintervalmsEditFieldLabel = uilabel(app.resultsPanel);
            app.intereventintervalmsEditFieldLabel.Text = 'interevent interval (ms)';
            app.intereventintervalEditField = uieditfield(app.resultsPanel, 'numeric');
            app.intereventintervalEditField.Limits = [0 Inf];
            app.intereventintervalEditField.ValueDisplayFormat = '%.1f';
            app.intereventintervalEditField.Editable = 'off';
            app.filePanel = uipanel(app.UIFigure);
            app.filePanel.Title = 'file';
            app.chooseMATfileButton = uibutton(app.filePanel, 'push');
            app.chooseMATfileButton.ButtonPushedFcn = createCallbackFcn(app, @chooseMATfileButtonPushed, true);
            app.chooseMATfileButton.Text = 'choose MAT file';
            app.saveasMATButton = uibutton(app.filePanel, 'push');
            app.saveasMATButton.ButtonPushedFcn = createCallbackFcn(app, @saveasMATButtonPushed, true);
            app.saveasMATButton.Text = 'save as MAT';
            app.analysisfileEditFieldLabel = uilabel(app.filePanel);
            app.analysisfileEditFieldLabel.Text = 'analysis file';
            app.analysisfileEditField = uieditfield(app.filePanel, 'text');
            app.resetButton = uibutton(app.filePanel, 'push');
            app.resetButton.ButtonPushedFcn = createCallbackFcn(app, @resetButtonPushed, true);
            app.resetButton.Text = 'reset';
            app.saveasExcelButton = uibutton(app.filePanel, 'push');
            app.saveasExcelButton.ButtonPushedFcn = createCallbackFcn(app, @saveasExcelButtonPushed, true);
            app.saveasExcelButton.Text = 'save as Excel';
            app.viewDropDownLabel = uilabel(app.UIFigure);
            app.viewDropDownLabel.Text = 'view';
            app.viewDropDown = uidropdown(app.UIFigure);
            app.viewDropDown.Items = {'all PSCs', 'accepted PSCs', 'rejected PSCs'};
            app.viewDropDown.ValueChangedFcn = createCallbackFcn(app, @viewDropDownValueChanged, true);
            app.viewDropDown.Value = 'all PSCs';
            app.manualPanel = uipanel(app.UIFigure);
            app.manualPanel.Title = 'manual';
            app.LEFTButton = uibutton(app.manualPanel, 'push');
            app.LEFTButton.ButtonPushedFcn = createCallbackFcn(app, @LEFTButtonPushed, true);
            app.LEFTButton.Text = 'LEFT';
            app.RIGHTButton = uibutton(app.manualPanel, 'push');
            app.RIGHTButton.ButtonPushedFcn = createCallbackFcn(app, @RIGHTButtonPushed, true);
            app.RIGHTButton.Text = 'RIGHT';
            app.ACCEPTButton = uibutton(app.manualPanel, 'push');
            app.ACCEPTButton.ButtonPushedFcn = createCallbackFcn(app, @ACCEPTButtonPushed, true);
            app.ACCEPTButton.Text = 'ACCEPT';
            app.REJECTButton = uibutton(app.manualPanel, 'push');
            app.REJECTButton.ButtonPushedFcn = createCallbackFcn(app, @REJECTButtonPushed, true);
            app.REJECTButton.Text = 'REJECT';
            app.currentPSCSpinnerLabel = uilabel(app.UIFigure);
            app.currentPSCSpinnerLabel.Text = 'current PSC';
            app.currentPSCSpinner = uispinner(app.UIFigure);
            app.currentPSCSpinner.Limits = [1 Inf];
            app.currentPSCSpinner.ValueDisplayFormat = '%.0f';
            app.currentPSCSpinner.ValueChangedFcn = createCallbackFcn(app, @currentPSCSpinnerValueChanged, true);
            app.currentPSCSpinner.Value = 1;
            app.NacceptedEditFieldLabel = uilabel(app.UIFigure);
            app.NacceptedEditFieldLabel.Text = 'N accepted';
            app.NacceptedEditField = uieditfield(app.UIFigure, 'numeric');
            app.NacceptedEditField.Limits = [0 Inf];
            app.NacceptedEditField.ValueDisplayFormat = '%.0f';
            app.NacceptedEditField.Editable = 'off';
            app.NrejectedEditFieldLabel = uilabel(app.UIFigure);
            app.NrejectedEditFieldLabel.Text = 'N rejected';
            app.NrejectedEditField = uieditfield(app.UIFigure, 'numeric');
            app.NrejectedEditField.Limits = [0 Inf];
            app.NrejectedEditField.ValueDisplayFormat = '%.0f';
            app.NrejectedEditField.Editable = 'off';
            app.startmanualButton = uibutton(app.UIFigure, 'push');
            app.startmanualButton.ButtonPushedFcn = createCallbackFcn(app, @startmanualButtonPushed, true);
            app.startmanualButton.Text = 'start manual';
            app.stopmanualButton = uibutton(app.UIFigure, 'push');
            app.stopmanualButton.ButtonPushedFcn = createCallbackFcn(app, @stopmanualButtonPushed, true);
            app.stopmanualButton.Text = 'stop manual';
            app.ManualSelectionLabel = uilabel(app.UIFigure);
            app.ManualSelectionLabel.Text = 'Manual Selection';
            app.graphingtypeDropDown = uidropdown(app.UIFigure);
            app.graphingtypeDropDown.Items = {'individual traces', 'histogram', 'mean trace'};
            app.graphingtypeDropDown.ValueChangedFcn = createCallbackFcn(app, @viewDropDownValueChanged, true);
            app.graphingtypeDropDown.Value = 'individual traces';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PSC(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end