classdef parameters < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        EvokedTab                       matlab.ui.container.Tab
        synapticcurrentsDropDown_2Label  matlab.ui.control.Label
        synapticcurrentsEvokedDropDown  matlab.ui.control.DropDown
        stimulustime1EditFieldLabel     matlab.ui.control.Label
        stimulustime1EditField          matlab.ui.control.NumericEditField
        stimulustime2EditFieldLabel     matlab.ui.control.Label
        stimulustime2EditField          matlab.ui.control.NumericEditField
        baselineEditFieldLabel          matlab.ui.control.Label
        baselineEditField               matlab.ui.control.EditField
        relativetostimulustimeLabel     matlab.ui.control.Label
        eg52whenstimulustime500msLabel  matlab.ui.control.Label
        meanstherange495to498msLabel    matlab.ui.control.Label
        peakEditFieldLabel              matlab.ui.control.Label
        peakEditField                   matlab.ui.control.EditField
        NonEvokedTab                    matlab.ui.container.Tab
        SixtyHzFilterCheckBox           matlab.ui.control.CheckBox
        synapticcurrentsDropDownLabel   matlab.ui.control.Label
        synapticcurrentsNonEvokedDropDown  matlab.ui.control.DropDown
        sPSCrisetimeEditFieldLabel      matlab.ui.control.Label
        sPSCrisetimeEditField           matlab.ui.control.NumericEditField
        sPSCamplitudeEditFieldLabel     matlab.ui.control.Label
        sPSCamplitudeEditField          matlab.ui.control.NumericEditField
        sPSCareaEditFieldLabel          matlab.ui.control.Label
        sPSCareaEditField               matlab.ui.control.NumericEditField
        IntrinsicTab                    matlab.ui.container.Tab
        spikethresholdmVmsDropDownLabel  matlab.ui.control.Label
        spikethresholdmVmsDropDown      matlab.ui.control.DropDown
        minimumspikeheightmVEditFieldLabel  matlab.ui.control.Label
        minimumspikeheightEditField     matlab.ui.control.NumericEditField
        analysisparametersLabel         matlab.ui.control.Label
        acceptButton                    matlab.ui.control.Button
        defaultButton                   matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %             fDir = [userpath,filesep,'patchclamp\parameters_and_gui'];
            fDir = pwd;
            try
                load([fDir,filesep,'analysisParameters.mat'],'ap')            
                app.spikethresholdmVmsDropDown.Value = num2str(ap.spikeThreshold);
                app.minimumspikeheightEditField.Value = ap.minimumSpikeHeight;
                app.stimulustime1EditField.Value = ap.stimulusTime1;
                app.stimulustime2EditField.Value = ap.stimulusTime2;
                app.baselineEditField.Value = num2str(ap.baseline);
                app.peakEditField.Value = num2str(ap.peak);
                if ap.synapticEvokedDirection == -1
                    app.synapticcurrentsEvokedDropDown.Value = 'inward';
                else
                    app.synapticcurrentsEvokedDropDown.Value = 'outward';
                end
                if ap.synapticNonEvokedDirection == -1
                    app.synapticcurrentsNonEvokedDropDown.Value = 'inward';
                else
                    app.synapticcurrentsNonEvokedDropDown.Value = 'outward';
                end      
                app.sPSCrisetimeEditField.Value = ap.riseTime;
                app.sPSCamplitudeEditField.Value = ap.amplitude;
                app.sPSCareaEditField.Value = ap.area;
                app.SixtyHzFilterCheckBox.Value = ap.sixtyHz;
            catch
                defaultButtonPushed(app)                
            end
        end

        % Value changed function: baselineEditField, peakEditField
        function baseline1EditFieldValueChanged(app, event)
            value = app.baselineEditField.Value;
            try
                foo = str2num(value); 
                if numel(foo)==2 && isnumeric(foo(1)) && isnumeric(foo(2)) && (foo(1)<=foo(2))
                    % all is well (probably)
                else
                    warndlg('Entry should be [time1 time2] where time1<time2','REENTER VALUES')
                end
            catch 
                warndlg('Entry should be [time1 time2] where time1<time2','REENTER VALUES')
            end
        end

        % Button pushed function: defaultButton
        function defaultButtonPushed(app, event)
            app.spikethresholdmVmsDropDown.Value = '20';
            app.minimumspikeheightEditField.Value = 0;
            app.synapticcurrentsEvokedDropDown.Value = 'inward';
            app.stimulustime1EditField.Value = 500;
            app.stimulustime2EditField.Value = 550;
            app.baselineEditField.Value = '[-5 -2]';
            app.peakEditField.Value = '[5 20]';
            app.synapticcurrentsNonEvokedDropDown.Value = 'inward';
            app.sPSCrisetimeEditField.Value = 1;
            app.sPSCamplitudeEditField.Value = 5;
            app.sPSCareaEditField.Value = 10;
            app.SixtyHzFilterCheckBox.Value = 1;
            acceptButtonPushed(app);
            
        end

        % Button pushed function: acceptButton
        function acceptButtonPushed(app, event)
            ap.spikeThreshold = str2double(app.spikethresholdmVmsDropDown.Value);
            ap.minimumSpikeHeight = app.minimumspikeheightEditField.Value;
            ap.stimulusTime1 = app.stimulustime1EditField.Value;
            ap.stimulusTime2 = app.stimulustime2EditField.Value;
            ap.baseline = str2num(app.baselineEditField.Value); %#ok<*ST2NM> 
            ap.peak = str2num(app.peakEditField.Value);
            if strcmp(app.synapticcurrentsEvokedDropDown.Value,'inward')
                ap.synapticEvokedDirection = -1;
            else
                ap.synapticEvokedDirection = 1;
            end
            if strcmp(app.synapticcurrentsNonEvokedDropDown.Value,'inward')
                ap.synapticNonEvokedDirection = -1;
            else
                ap.synapticNonEvokedDirection = 1;
            end
            ap.riseTime = app.sPSCrisetimeEditField.Value;
            ap.amplitude = app.sPSCamplitudeEditField.Value;
            ap.area = app.sPSCareaEditField.Value;
            ap.sixtyHz = app.SixtyHzFilterCheckBox.Value;
            
            filename = [pwd,filesep,'analysisParameters.mat'];
            save(filename,'ap')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.EvokedTab = uitab(app.TabGroup);
            app.EvokedTab.Title = 'Evoked';
            app.synapticcurrentsDropDown_2Label = uilabel(app.EvokedTab);
            app.synapticcurrentsDropDown_2Label.Text = 'synaptic currents';
            app.synapticcurrentsEvokedDropDown = uidropdown(app.EvokedTab);
            app.synapticcurrentsEvokedDropDown.Items = {'inward', 'outward'};
            app.synapticcurrentsEvokedDropDown.Value = 'inward';
            app.stimulustime1EditFieldLabel = uilabel(app.EvokedTab);
            app.stimulustime1EditFieldLabel.Text = 'stimulus time 1';
            app.stimulustime1EditField = uieditfield(app.EvokedTab, 'numeric');
            app.stimulustime1EditField.Limits = [0 Inf];
            app.stimulustime1EditField.ValueDisplayFormat = '%.1f';
            app.stimulustime1EditField.Value = 500;
            app.stimulustime2EditFieldLabel = uilabel(app.EvokedTab);
            app.stimulustime2EditFieldLabel.Text = 'stimulus time 2';
            app.stimulustime2EditField = uieditfield(app.EvokedTab, 'numeric');
            app.stimulustime2EditField.Limits = [0 Inf];
            app.stimulustime2EditField.ValueDisplayFormat = '%.1f';
            app.stimulustime2EditField.Value = 550;
            app.baselineEditFieldLabel = uilabel(app.EvokedTab);
            app.baselineEditFieldLabel.Text = 'baseline';
            app.baselineEditField = uieditfield(app.EvokedTab, 'text');
            app.baselineEditField.ValueChangedFcn = createCallbackFcn(app, @baseline1EditFieldValueChanged, true);
            app.baselineEditField.Value = '[-5 -2]';
            app.relativetostimulustimeLabel = uilabel(app.EvokedTab);
            app.relativetostimulustimeLabel.Text = 'relative to stimulus time';
            app.eg52whenstimulustime500msLabel = uilabel(app.EvokedTab);
            app.eg52whenstimulustime500msLabel.Text = 'e.g., [-5 -2] when stimulus time = 500 ms';
            app.meanstherange495to498msLabel = uilabel(app.EvokedTab);
            app.meanstherange495to498msLabel.Text = 'means the range 495 to 498 ms';
            app.peakEditFieldLabel = uilabel(app.EvokedTab);
            app.peakEditFieldLabel.Text = 'peak';
            app.peakEditField = uieditfield(app.EvokedTab, 'text');
            app.peakEditField.ValueChangedFcn = createCallbackFcn(app, @baseline1EditFieldValueChanged, true);
            app.peakEditField.Value = '[5 20]';
            app.NonEvokedTab = uitab(app.TabGroup);
            app.NonEvokedTab.Title = 'Non-Evoked';
            app.SixtyHzFilterCheckBox = uicheckbox(app.NonEvokedTab);
            app.SixtyHzFilterCheckBox.Text = '60 Hz filter';
            app.SixtyHzFilterCheckBox.Value = true;
            app.synapticcurrentsDropDownLabel = uilabel(app.NonEvokedTab);
            app.synapticcurrentsDropDownLabel.Text = 'synaptic currents';
            app.synapticcurrentsNonEvokedDropDown = uidropdown(app.NonEvokedTab);
            app.synapticcurrentsNonEvokedDropDown.Items = {'inward', 'outward'};
            app.synapticcurrentsNonEvokedDropDown.Value = 'inward';
            app.sPSCrisetimeEditFieldLabel = uilabel(app.NonEvokedTab);
            app.sPSCrisetimeEditFieldLabel.Text = 'sPSC rise time';
            app.sPSCrisetimeEditField = uieditfield(app.NonEvokedTab, 'numeric');
            app.sPSCrisetimeEditField.Limits = [0.2 10];
            app.sPSCrisetimeEditField.ValueDisplayFormat = '%5.1f';
            app.sPSCrisetimeEditField.Value = 1;
            app.sPSCamplitudeEditFieldLabel = uilabel(app.NonEvokedTab);
            app.sPSCamplitudeEditFieldLabel.Text = 'sPSC amplitude';
            app.sPSCamplitudeEditField = uieditfield(app.NonEvokedTab, 'numeric');
            app.sPSCamplitudeEditField.Limits = [1 50];
            app.sPSCamplitudeEditField.RoundFractionalValues = 'on';
            app.sPSCamplitudeEditField.ValueDisplayFormat = '%.0f';
            app.sPSCamplitudeEditField.Value = 5;
            app.sPSCareaEditFieldLabel = uilabel(app.NonEvokedTab);
            app.sPSCareaEditFieldLabel.Text = 'sPSC area';
            app.sPSCareaEditField = uieditfield(app.NonEvokedTab, 'numeric');
            app.sPSCareaEditField.Limits = [10 500];
            app.sPSCareaEditField.RoundFractionalValues = 'on';
            app.sPSCareaEditField.ValueDisplayFormat = '%.0f';
            app.sPSCareaEditField.Value = 10;
            app.IntrinsicTab = uitab(app.TabGroup);
            app.IntrinsicTab.Title = 'Intrinsic';
            app.spikethresholdmVmsDropDownLabel = uilabel(app.IntrinsicTab);
            app.spikethresholdmVmsDropDownLabel.Text = 'spike threshold (mV/ms)';
            app.spikethresholdmVmsDropDown = uidropdown(app.IntrinsicTab);
            app.spikethresholdmVmsDropDown.Items = {'10', '20', '30', '40'};
            app.spikethresholdmVmsDropDown.Value = '20';
            app.minimumspikeheightmVEditFieldLabel = uilabel(app.IntrinsicTab);
            app.minimumspikeheightmVEditFieldLabel.Text = 'minimum spike height (mV)';
            app.minimumspikeheightEditField = uieditfield(app.IntrinsicTab, 'numeric');
            app.minimumspikeheightEditField.Limits = [-40 50];
            app.minimumspikeheightEditField.RoundFractionalValues = 'on';
            app.minimumspikeheightEditField.ValueDisplayFormat = '%.0f';
            app.analysisparametersLabel = uilabel(app.UIFigure);
            app.analysisparametersLabel.Text = 'analysis parameters';
            app.acceptButton = uibutton(app.UIFigure, 'push');
            app.acceptButton.ButtonPushedFcn = createCallbackFcn(app, @acceptButtonPushed, true);
            app.acceptButton.Text = 'accept';
            app.defaultButton = uibutton(app.UIFigure, 'push');
            app.defaultButton.ButtonPushedFcn = createCallbackFcn(app, @defaultButtonPushed, true);
            app.defaultButton.Text = 'default';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = parameters

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end