classdef LCSMS_analysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        refreshButton              matlab.ui.control.Button
        TabGroup2                  matlab.ui.container.TabGroup
        legend1Tab                 matlab.ui.container.Tab
        legendEditField_8          matlab.ui.control.EditField
        legendEditField_7          matlab.ui.control.EditField
        legendEditField_6          matlab.ui.control.EditField
        legendEditField_5          matlab.ui.control.EditField
        legendEditField_4          matlab.ui.control.EditField
        legendEditField_3          matlab.ui.control.EditField
        legendEditField_2          matlab.ui.control.EditField
        legendEditField_1          matlab.ui.control.EditField
        legend2Tab                 matlab.ui.container.Tab
        legendEditField_16         matlab.ui.control.EditField
        legendEditField_15         matlab.ui.control.EditField
        legendEditField_14         matlab.ui.control.EditField
        legendEditField_13         matlab.ui.control.EditField
        legendEditField_12         matlab.ui.control.EditField
        legendEditField_11         matlab.ui.control.EditField
        legendEditField_10         matlab.ui.control.EditField
        legendEditField_9          matlab.ui.control.EditField
        legend3Tab                 matlab.ui.container.Tab
        legendEditField_24         matlab.ui.control.EditField
        legendEditField_23         matlab.ui.control.EditField
        legendEditField_22         matlab.ui.control.EditField
        legendEditField_21         matlab.ui.control.EditField
        legendEditField_20         matlab.ui.control.EditField
        legendEditField_19         matlab.ui.control.EditField
        legendEditField_18         matlab.ui.control.EditField
        legendEditField_17         matlab.ui.control.EditField
        clearButton                matlab.ui.control.Button
        fileinfoTextArea           matlab.ui.control.TextArea
        fileinfoTextAreaLabel      matlab.ui.control.Label
        UIChannels                 matlab.ui.control.Table
        TabGroup                   matlab.ui.container.TabGroup
        displayTab                 matlab.ui.container.Tab
        averagingSwitch            matlab.ui.control.ToggleSwitch
        allsweepsButton            matlab.ui.control.StateButton
        autoscaleCheckBox          matlab.ui.control.CheckBox
        replaceCheckBox            matlab.ui.control.CheckBox
        stimuliButton              matlab.ui.control.Button
        baselinesubtractCheckBox   matlab.ui.control.CheckBox
        deltaEditField             matlab.ui.control.NumericEditField
        deltaEditFieldLabel        matlab.ui.control.Label
        startEditField             matlab.ui.control.NumericEditField
        startEditFieldLabel        matlab.ui.control.Label
        firstsweepButton           matlab.ui.control.Button
        sweepsLabel                matlab.ui.control.Label
        lastEditField              matlab.ui.control.NumericEditField
        lastEditFieldLabel         matlab.ui.control.Label
        firstEditField             matlab.ui.control.NumericEditField
        firstEditFieldLabel        matlab.ui.control.Label
        forwardButton              matlab.ui.control.Button
        backButton                 matlab.ui.control.Button
        displayButton              matlab.ui.control.Button
        analysisTab                matlab.ui.container.Tab
        replaceAnalysisCheckBox    matlab.ui.control.CheckBox
        fullSwitch                 matlab.ui.control.Switch
        fullSwitchLabel            matlab.ui.control.Label
        parametersButton           matlab.ui.control.Button
        allsweepsButton_2          matlab.ui.control.StateButton
        analysistypeDropDown       matlab.ui.control.DropDown
        analysistypeDropDownLabel  matlab.ui.control.Label
        startEditField_2           matlab.ui.control.NumericEditField
        startEditField_2Label      matlab.ui.control.Label
        stopEditField              matlab.ui.control.NumericEditField
        stopEditFieldLabel         matlab.ui.control.Label
        resetrangeButton           matlab.ui.control.Button
        rangeLabel                 matlab.ui.control.Label
        analyzeButton              matlab.ui.control.Button
        lastEditField_2            matlab.ui.control.NumericEditField
        lastEditField_2Label       matlab.ui.control.Label
        firstEditField_2           matlab.ui.control.NumericEditField
        firstEditField_2Label      matlab.ui.control.Label
        firstsweepButton_2         matlab.ui.control.Button
        sweepsLabel_2              matlab.ui.control.Label
        fittingTab                 matlab.ui.container.Tab
        fittingresultTextArea      matlab.ui.control.TextArea
        deltaTextArea              matlab.ui.control.TextArea
        deltaTextAreaLabel         matlab.ui.control.Label
        cursor2TextArea            matlab.ui.control.TextArea
        cursor2TextAreaLabel       matlab.ui.control.Label
        cursor1TextArea            matlab.ui.control.TextArea
        cursor1TextAreaLabel       matlab.ui.control.Label
        clearfittingtracesButton   matlab.ui.control.Button
        cleardatacursorsButton     matlab.ui.control.Button
        integralButton             matlab.ui.control.Button
        averageButton              matlab.ui.control.Button
        exponentialButton          matlab.ui.control.Button
        linearButton               matlab.ui.control.Button
        placedatacursorsButton     matlab.ui.control.Button
        savingTab                  matlab.ui.container.Tab
        fileformatButtonGroup      matlab.ui.container.ButtonGroup
        ASCIIButton                matlab.ui.control.ToggleButton
        ExcelButton                matlab.ui.control.ToggleButton
        MatlabButton               matlab.ui.control.ToggleButton
        directoryEditField         matlab.ui.control.EditField
        directoryEditFieldLabel    matlab.ui.control.Label
        changedirectoryButton_2    matlab.ui.control.Button
        tracesListBox              matlab.ui.control.ListBox
        tracesListBoxLabel         matlab.ui.control.Label
        saveButton                 matlab.ui.control.Button
        filesListBox               matlab.ui.control.ListBox
        changedirectoryButton      matlab.ui.control.Button
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        WorkingFile % file or files currently being displayed or analyzed
        PlotHandle % handle for the plotted traces
        MaxNumTraces % maximum number of traces
        DataCursors % handle to data cursors
        FittingCursors % handle to fitting cursors
        FittedTrace % handle to fitted trace
        SaveDirectory % folder in which to save analyses
    end
    
    properties (Access = public)
        SelectedPath % directory in which data files are saved
        StimuliApp = []; % handle to the stimulis app
        TraceNo % identifying number of each plotted trace
        ColorOrder % order of colors for plotting
        FileNames % filenames of plotted data
        TraceInfo % channel number and first/last sweeps of plotted traces
        RangeLeft % left range line
        RangeRight % right range line
    end
    
    methods (Access = private)
        
        function [] = updatefilelist(app)
            files = dir([app.SelectedPath,filesep,'*.mat']);
            fileNames = struct2cell(files);
            app.filesListBox.Items = fileNames(1,:);
            if ~isempty(app.filesListBox.Items)
                app.filesListBox.Value = app.filesListBox.Items{1};
                filesListBoxValueChanged(app)
            end
        end
        
        
        function txt = displaydatacursors(app)
            info = getCursorInfo(app.DataCursors);
            x = info(1).Position(1);
            y = info(1).Position(2);
            txt = ['(' num2str(x) ', ' num2str(y) ')'];
            foo = strings(2,1);
            foo(1) = num2str(x,'%0.1f');
            foo(2) = num2str(y,'%0.1f');
            if numel(info)==1
                app.cursor1TextArea.Value = foo;
            else
                app.cursor2TextArea.Value = foo;
                deltaX = x-info(2).Position(1);
                deltaY = y-info(2).Position(2);
                bar = strings(2,1);
                bar(1) = num2str(deltaX,'%0.1f');
                bar(2) = num2str(deltaY,'%0.1f');
                app.deltaTextArea.Value = bar;
            end
        end
        
        function infoNew = checkcursors(app)
            delete(app.FittedTrace)
            if ~isempty(app.FittingCursors)
                delete(app.FittingCursors);
                app.FittingCursors = [];
            end
            info = getCursorInfo(app.DataCursors);
            if numel(info)<2
                warndlg('Place data cursors first','Data cursor error')
                return
            end
            foo = ones(numel(info),1);
            for ii = 1:numel(info)
                foo(ii) = info(ii).DataIndex;
            end
            [dataIndex, cursorNo] = sort(foo);
            infoNew(1) = info(cursorNo(1));
            infoNew(2) = info(cursorNo(end));
            if infoNew(1).Target.SeriesIndex ~= infoNew(2).Target.SeriesIndex
                ydata = infoNew(1).Target.YData;
                infoNew(2).Position(2) = ydata(dataIndex(2));
            end
            app.FittingCursors(1) = plot(app.UIAxes,infoNew(1).Position(1),infoNew(1).Position(2),'o','markersize',12,'markerfacecolor','g');
            app.FittingCursors(2) = plot(app.UIAxes,infoNew(2).Position(1),infoNew(2).Position(2),'o','markersize',12,'markerfacecolor','g');
        end
        
        function [] = rangeMoving(app, ~, ~)
           
            zoom(app.UIAxes,'off')
            foo = round(app.RangeLeft.Position,2);
            app.RangeLeft.Position(2,1) = foo(1,1);
            app.startEditField_2.Value = foo(1,1);
            boo = round(app.RangeRight.Position,2);
            app.RangeRight.Position(2,1) = boo(1,1);
            app.stopEditField.Value = boo(1,1);
            
        end
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.SelectedPath = userpath;
            updatefilelist(app)
            channels = cell(1,8);
            app.UIChannels.Data = channels;
            app.UIChannels.Data = false(1,8);
            app.UIChannels.Data(1,1) = true;
            
            app.TraceNo = 0;
            app.PlotHandle = [];
            app.MaxNumTraces = 24;
            app.ColorOrder = get(app.UIAxes,'colororder');
            if size(app.ColorOrder,1)<app.MaxNumTraces
                nColors= size(app.ColorOrder,1);
                for ii = nColors+1:app.MaxNumTraces
                    app.ColorOrder(ii,:) = [rand(1) rand(1) rand(1)];
                end
            end
            
            for ii = 1:app.MaxNumTraces
                fStr = ['legendEditField_',num2str(ii)];
                app.(fStr).Value = '';
                app.(fStr).Enable = false;
            end
            
            app.DataCursors = datacursormode(app.UIFigure);
            app.DataCursors.Enable = 'off';
            app.FittingCursors = [];
            app.FittedTrace = [];
            app.SaveDirectory = pwd;
        end

        % Value changed function: filesListBox
        function filesListBoxValueChanged(app, event)
            app.WorkingFile = app.filesListBox.Value;
            fStr = [app.SelectedPath,filesep,app.WorkingFile{1}];
            listOfVariables = who('-file',fStr);
            if ismember('Pars',listOfVariables)
                foo = load(fStr,'Pars');
                Pars = foo.Pars;
            else
                return
            end
            if ~isfield(Pars,'mouseid') % for compatibility with old daq program (ni)
                return
            end
            if isnat(Pars.DOB)
                gStr = [Pars.mouseid,Pars.sex,datestr(Pars.triggerTime(1)),Pars.notes(:)'];
            else
                gStr = [Pars.mouseid,Pars.sex,datestr(Pars.DOB),datestr(Pars.triggerTime(1)),Pars.notes(:)'];
            end
            chan = ['channels ',num2str(find(~strcmp(Pars.channelStatus,'off'))')];
            dur = ['duration (msec) ',num2str(Pars.duration)];
            steps = ['number of steps ',num2str(Pars.stepsNo)];
            reps = ['repetitions ',num2str(Pars.repetitions)];
            sweeps = ['completed sweeps ',num2str(numel(Pars.orderOfSteps))];
            gStr = [gStr, chan, dur, steps, reps, sweeps];
            app.fileinfoTextArea.Value = gStr;
        end

        % Button pushed function: changedirectoryButton
        function changedirectoryButtonPushed(app, event)
            newPath = uigetdir(app.SelectedPath,'Choose a directory');
            if newPath
                app.SelectedPath = newPath;
                updatefilelist(app)
            end
        end

        % Button pushed function: displayButton
        function displayButtonPushed(app, event)
            first = app.firstEditField.Value;
            last = app.lastEditField.Value;
            try
                data = load([app.SelectedPath,filesep,app.WorkingFile{1}],'Pars','inputData');
                inputData = data.inputData;
                Pars = data.Pars;
            catch
                warndlg('Select a file before trying to display the data.', 'File selection error')
                return
            end
            dt = 1000/Pars.sampleRate;
            if isempty(inputData)
                warndlg('No data contained in file.','Data absent error')
                return
            end
            selectedChannels = find(app.UIChannels.Data);
            if isempty(selectedChannels)
                warndlg('Select a channel to display first.','Channel selection error')
                return
            end
            acquiredChannels = find(~strcmp(Pars.channelStatus,'off'));
            missingChannels = selectedChannels(~ismember(selectedChannels,acquiredChannels));
            if ~isempty(missingChannels)
                warndlg(['The following selected channels were not acquired: ',num2str(missingChannels)], 'Channel selection error');
                return
            end
            channelIdx = ismember(acquiredChannels,selectedChannels);
            nSweeps = size(inputData,2);
            if app.allsweepsButton.Value == true
                first = 1;
                last = nSweeps;
                app.firstEditField.Value = first;
                app.lastEditField.Value = last;
            end
            if nSweeps<first
                first = 1;
                app.firstEditField.Value = first;
            end
            if nSweeps<last
                last = nSweeps;
                app.lastEditField.Value = last;
            end
            inputData = inputData(:,first:last,channelIdx);
            if strcmp(app.averagingSwitch.Value,'average')
                meanTrial = mean(inputData,1);
                inputData = inputData(:,~isnan(meanTrial));
                inputData = mean(inputData,2);
            end
            if app.baselinesubtractCheckBox.Value==true
                start = app.startEditField.Value;
                finish = start + app.deltaEditField.Value;
                start = floor(start/dt)+1;
                finish = floor(finish/dt);
                if finish>size(inputData,1)
                    warndlg('Specified baseline is longer than the recording. Please check baseline parameters.','Baseline subtraction error');
                    return
                end
                baseline = mean(inputData(start:finish,:,:),1);
                inputData = inputData - baseline;
            end
            if app.replaceCheckBox.Value==true
                cla(app.UIAxes)
                app.TraceNo = 0;
                for ii = 1:8
                    fStr = ['legendEditField_',num2str(ii)];
                    app.(fStr).Value = '';
                    app.(fStr).Enable = false;
                end
                app.PlotHandle = [];
            end
            t = (1:size(inputData,1))*dt - dt;
            for ii = 1:size(inputData,3)
                hold(app.UIAxes,'on')
                app.TraceNo = max(rem(app.TraceNo + 1,app.MaxNumTraces+1),1);
                labelStr = ['legendEditField_',num2str(app.TraceNo)];
                nameStr = ['exp_',num2str(Pars.experimentNo),'_trial_',...
                    num2str(Pars.trialNo),'_chan_',...
                    num2str(selectedChannels(ii)),'_first_',num2str(first),...
                    '_last_',num2str(last)];
                if strcmp(app.averagingSwitch.Value,'average')
                    nameStr = [nameStr,'_AVE']; 
                end
                for jj = 1:app.TraceNo-1
                    labelStr0 = ['legendEditField_',num2str(jj)];
                    if strcmp(nameStr,app.(labelStr0).Value)
                        disp('Trace has already been plotted.')
                        app.TraceNo = app.TraceNo-1;
                        return
                    end
                end
                app.PlotHandle{app.TraceNo} = plot(app.UIAxes,t,inputData(:,:,ii),'color',app.ColorOrder(app.TraceNo,:));
                app.FileNames{app.TraceNo} = [app.SelectedPath,filesep,app.WorkingFile{1}];
                app.(labelStr).Enable = true;
                app.(labelStr).Value = nameStr;
                app.(labelStr).FontColor = app.ColorOrder(app.TraceNo,:);
                app.(labelStr).Editable = 'on';
                app.TraceInfo{app.TraceNo}.channel = selectedChannels(ii);
                app.TraceInfo{app.TraceNo}.sweeps = [first last];
            end
            if app.autoscaleCheckBox.Value== true
                axis(app.UIAxes,'auto')
            end
            for nn = 1:numel(Pars.channelStatus)
                CC = any(contains(Pars.channelStatus,'current clamp'));
                FP = any(contains(Pars.channelStatus,'field potential'));
                VC = any(contains(Pars.channelStatus,'voltage clamp'));
                str = '';
                if CC
                    str = [str,'  V_m (mV)'];
                end
                if VC
                    str = [str,'  I (pA)'];
                end
                if FP
                    str = [str,'  FP (mV)'];
                end
                str = strtrim(str);
                app.UIAxes.YLabel.String = str;
            end
            app.UIAxes.XLabel.String = 'Time (msec)';


        end

        % Value changed function: firstEditField
        function firstEditFieldValueChanged(app, event)
            value = app.firstEditField.Value;
            if value>app.lastEditField.Value
                app.lastEditField.Value = value;
            end
        end

        % Value changed function: lastEditField
        function lastEditFieldValueChanged(app, event)
            value = app.lastEditField.Value;
            if value<app.firstEditField.Value
                app.firstEditField.Value = value;
            end
        end

        % Button pushed function: firstsweepButton
        function firstsweepButtonPushed(app, event)
            app.firstEditField.Value = 1;
            app.lastEditField.Value = 1;
            app.allsweepsButton.Value = false;
        end

        % Button pushed function: clearButton
        function clearButtonPushed(app, event)
            cla(app.UIAxes)
            app.UIAxes.XLabel.String = '';
            app.UIAxes.YLabel.String = '';
            hold(app.UIAxes,'off')
            app.TraceNo = 0;
            for ii = 1:app.MaxNumTraces
                fStr = ['legendEditField_',num2str(ii)];
                app.(fStr).Value = '';
                app.(fStr).Enable = false;
            end
            app.PlotHandle = [];
            app.FileNames = [];
            app.FittingCursors = [];
            if strcmp(app.TabGroup.SelectedTab.Title,'analysis')
                delete(app.RangeLeft)
                delete(app.RangeRight)  
                disableDefaultInteractivity(app.UIAxes)
                axis(app.UIAxes,'auto')
%                 foo = app.UIAxes.XLim;
%                 boo = app.UIAxes.YLim;
%                 app.startEditField_2.Value = foo(1);
%                 app.stopEditField.Value = foo(2);
                boo = app.UIAxes.YLim;
                foo(1) = app.startEditField_2.Value;
                foo(2) = app.stopEditField.Value;
                app.RangeLeft = images.roi.Line(app.UIAxes,'Position',[foo(1) boo(1); foo(1) boo(2)],'Color','k');
                app.RangeRight = images.roi.Line(app.UIAxes,'Position',[foo(2) boo(1); foo(2) boo(2)],'Color','k');
                addlistener(app.RangeLeft,'ROIMoved',@app.rangeMoving);
                addlistener(app.RangeRight,'ROIMoved',@app.rangeMoving);
            end
    
            
        end

        % Value changed function: legendEditField_1, legendEditField_10, 
        % legendEditField_11, legendEditField_12, legendEditField_13, 
        % legendEditField_14, legendEditField_15, legendEditField_16, 
        % legendEditField_17, legendEditField_18, legendEditField_19, 
        % legendEditField_2, legendEditField_20, legendEditField_21, 
        % legendEditField_22, legendEditField_23, legendEditField_24, 
        % legendEditField_3, legendEditField_4, legendEditField_5, 
        % legendEditField_6, legendEditField_7, legendEditField_8, 
        % legendEditField_9
        function legendEditField_1ValueChanged(app, event)
            tag = event.Source.Tag;
            currentTraceNo = str2double(tag(7:8));
            labelStr = ['legendEditField_',num2str(currentTraceNo)];
            value = app.(labelStr).Value;
            if isempty(value)
                delete(app.PlotHandle{currentTraceNo});
                app.FileNames(currentTraceNo) = [];
                if app.TraceNo > currentTraceNo
                    for jj = currentTraceNo : app.TraceNo-1
                        labelStr0 = ['legendEditField_',num2str(jj)];
                        labelStr1 = ['legendEditField_',num2str(jj+1)];
                        app.(labelStr0).Value = app.(labelStr1).Value;
                        xdata = app.PlotHandle{jj+1}(1).XData;
                        nSweeps = numel(app.PlotHandle{jj+1});
                        ydata = zeros(nSweeps,numel(xdata));
                        for kk = 1:nSweeps
                            ydata(kk,:) = app.PlotHandle{jj+1}(kk).YData;
                        end
                        app.PlotHandle{jj} = plot(app.UIAxes,xdata,ydata,'color',app.ColorOrder(jj,:));
                        app.FileNames{jj} = app.FileNames{jj+1};
                        delete(app.PlotHandle{jj+1});
                    end
                    app.(labelStr1).Value = '';
                    app.(labelStr1).Enable = false;
                    app.PlotHandle(jj+1) = [];
                    app.FileNames(jj+1) = [];
                else
                    app.(labelStr).Enable = false;
                end
                app.TraceNo = app.TraceNo - 1;
            end
        end

        % Button pushed function: forwardButton
        function forwardButtonPushed(app, event)
            try
                foo = load([app.SelectedPath,filesep,app.WorkingFile{1}],'Pars');
                Pars = foo.Pars;
            catch
                warndlg('Select a file before trying to display the data.', 'File selection error')
                return
            end
            first = app.firstEditField.Value + 1;
            nSweeps = Pars.stepsNo*Pars.repetitions;
            first = min(first,nSweeps);
            app.firstEditField.Value = first;
            app.lastEditField.Value = first;
            app.allsweepsButton.Value = false;
            displayButtonPushed(app);
        end

        % Button pushed function: backButton
        function backButtonPushed(app, event)
            try
                load([app.SelectedPath,filesep,app.WorkingFile{1}],'Pars');
            catch
                warndlg('Select a file before trying to display the data.', 'File selection error')
                return
            end
            last = app.lastEditField.Value - 1;
            nSweeps = Pars.stepsNo*Pars.repetitions;
            last = min(last,nSweeps);
            last = max(last,1);
            app.lastEditField.Value = last;
            app.firstEditField.Value = last;
            app.allsweepsButton.Value = false;
            displayButtonPushed(app);
        end

        % Button pushed function: stimuliButton
        function stimuliButtonPushed(app, event)
            if isempty(app.StimuliApp)
                app.StimuliApp = stimuli(app);
            else
                pushRefreshListButton(app.StimuliApp)
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if ~isempty(app.StimuliApp)
                delete(app.StimuliApp)
            end
            delete(app)
        end

        % Button pushed function: placedatacursorsButton
        function placedatacursorsButtonPushed(app, event)
            zoom(app.UIAxes,'off')
            cleardatacursorsButtonPushed(app)
            app.DataCursors.UpdateFcn = @(obj,evt)displaydatacursors(app);
            
        end

        % Button pushed function: cleardatacursorsButton
        function cleardatacursorsButtonPushed(app, event)
            delete(app.FittedTrace)
            delete(findall(app.UIFigure,'Type','hggroup'));
            app.cursor1TextArea.Value = '';
            app.cursor2TextArea.Value = '';
            app.deltaTextArea.Value = '';
            if ~isempty(app.FittingCursors)
                delete(app.FittingCursors);
                app.FittingCursors = [];
            end
        end

        % Button pushed function: averageButton
        function averageButtonPushed(app, event)
            d = checkcursors(app);
            ydata = d(1).Target.YData(d(1).DataIndex:d(2).DataIndex);
            meanValue = mean(ydata);
            fStr = ['Mean = ',num2str(meanValue,'%0.1f')];
            app.fittingresultTextArea.Value = fStr;
            
        end

        % Button pushed function: linearButton
        function linearButtonPushed(app, event)
            d = checkcursors(app);
            ydata = d(1).Target.YData(d(1).DataIndex:d(2).DataIndex);
            xdata = d(1).Target.XData(d(1).DataIndex:d(2).DataIndex);
            p = polyfit(xdata,ydata,1);
            fStr = ['(y=mx+b) m = ',num2str(p(1),'%0.2f'),' , b = ',num2str(p(2),'%0.2f')];
            app.fittingresultTextArea.Value = fStr;
            ydata1 = p(1)*xdata + p(2);
            app.FittedTrace = plot(app.UIAxes,xdata,ydata1,'k-');
            
        end

        % Button pushed function: exponentialButton
        function exponentialButtonPushed(app, event)
            d = checkcursors(app);
            ydata = d(1).Target.YData(d(1).DataIndex:d(2).DataIndex);
            xdata = d(1).Target.XData(d(1).DataIndex:d(2).DataIndex);
            startPt = mean(ydata(1:5));
            endPt = mean(ydata(end-4:end));
            tauGuess = max(0.01,(xdata(end)-xdata(1))/3); % assume three time constants are included in fit
            xdata1 = xdata - xdata(1);
            fitType = fittype('a-b*exp(-c*x)');
            f0 = fit(xdata1(:),ydata(:),fitType,'StartPoint',[endPt,endPt-startPt,1/tauGuess]);
            ydata1 = (f0.a-f0.b.*exp(-f0.c*xdata1));
            app.FittedTrace = plot(app.UIAxes,xdata,ydata1,'k-');
            fStr = ['tau = ',num2str(1/f0.c,'%0.2f')];
            app.fittingresultTextArea.Value = fStr;
            
        end

        % Button pushed function: integralButton
        function integralButtonPushed(app, event)
            d = checkcursors(app);
            ydata = d(1).Target.YData(d(1).DataIndex:d(2).DataIndex);
            xdata = d(1).Target.XData(d(1).DataIndex:d(2).DataIndex);
            Q = trapz(xdata,ydata);
            fStr = ['integral = ',num2str(Q,'%0.2f')];
            app.fittingresultTextArea.Value = fStr;
            
        end

        % Button pushed function: analyzeButton
        function analyzeButtonPushed(app, event)
            if ~exist('analysisParameters.mat','file')
                warndlg('Select analysis parameters first','Warning')
                parameters
                return
            else
                load('analysisParameters.mat','ap')
            end
            selectedChannel = find(app.UIChannels.Data, 1);
            if isempty(selectedChannel)
                warndlg('Select a channel to analyze first.','Channel selection error')
                return
            end
            nFiles = numel(app.WorkingFile);
            for ii = 1:nFiles
                first = app.firstEditField_2.Value;
                last = app.lastEditField_2.Value;
                try
                    foo = load([app.SelectedPath,filesep,app.WorkingFile{ii}],'Pars','inputData','outputData');
                    inputData = foo.inputData;
                    outputData = foo.outputData;
                    Pars = foo.Pars;
                catch
                    warndlg('Select a file before trying to analyze the data.', 'File selection error')
                    return
                end
                if isempty(inputData)
                    warndlg('No data contained in file.','Data absent error')
                    return
                end
                acquiredChannels = find(~strcmp(Pars.channelStatus,'off'));
                if ~ismember(selectedChannel,acquiredChannels)
                    warndlg('Selected channel was not acquired.','Channel selection error')
                    return
                end
                channelIdx = find(ismember(acquiredChannels,selectedChannel),1);
                nSweeps = size(inputData,2);
                if app.allsweepsButton_2.Value == true
                    first = 1;
                    last = nSweeps;
                    app.firstEditField_2.Value = first;
                    app.lastEditField_2.Value = last;
                end
                if nSweeps<first
                    first = 1;
                    app.firstEditField_2.Value = first;
                end
                if nSweeps<last
                    last = nSweeps;
                    app.lastEditField_2.Value = last;
                end
                inputs{ii} = inputData(:,first:last,channelIdx);        %#ok<*AGROW>
                if ndims(outputData)==3
                    outputs{ii} = outputData(:,Pars.orderOfSteps,channelIdx);
                else
                    outputs{ii} = outputData(:,Pars.orderOfSteps);
                end
                outputs{ii} = outputs{ii}(:,first:last);
                dt{ii} = 1000/Pars.sampleRate;
                nSamplePts(ii) = length(inputs{ii});
                channelStatus{ii} = Pars.channelStatus{channelIdx};
            end
            
            if app.replaceAnalysisCheckBox.Value
                clearButtonPushed(app);
            end
            

            analysisType = app.analysistypeDropDown.Value;
            hold(app.UIAxes,'on')
            TraceNo0 = max(rem(app.TraceNo + 1,app.MaxNumTraces+1),1);
            labelStr = ['legendEditField_',num2str(TraceNo0)];
            nameStr = ['exp_',num2str(Pars.experimentNo),'_trial_',...
                num2str(Pars.trialNo),'_chan_',...
                num2str(selectedChannel),'_first_',num2str(first),...
                '_last_',num2str(last)];
            app.RangeLeft.Visible = 'on';
            app.RangeRight.Visible = 'on';
            if strcmp(app.fullSwitch.Value,'on')
                app.startEditField_2.Value = 0;
                app.stopEditField.Value = length(inputs{1})*dt{1};
                app.RangeLeft.Position(1,1) = 0;
                app.RangeLeft.Position(2,1) = 0;
                app.RangeRight.Position(1,1) = length(inputs{1}*dt{1});
                app.RangeRight.Position(2,1) = length(inputs{1}*dt{1});  
            end
            switch analysisType
                case 'average'
                    if numel(unique(nSamplePts))>1
                        warndlg('To average multiple files, all recordings must be the same length', 'NO AVERAGE MADE');
                        return
                    end
                    result = [];
                    for jj = 1:numel(inputs)
                        result = [result, inputs{ii}];
                    end
                    meanTrial = mean(result,1); % get rid of NaNs
                    result = result(:,~isnan(meanTrial));
                    ydata = mean(result,2);
                    xdata = (1:length(ydata))*dt{1};                    
                    nameStr = [nameStr,'_AVE'];
                    markers = false;
                    xStr = 'Time (msec)';
                    CC = strcmp(channelStatus{1},'current clamp');
                    FP = strcmp(channelStatus{1},'field potential');
                    VC = strcmp(channelStatus{1},'voltage clamp');
                    if CC
                        yStr = 'V_m (mV)';
                    end
                    if VC
                        yStr = 'I (pA)';
                    end
                    if FP
                        yStr = 'field potential (mV)';
                    end
                case {'f-I','ISI','spike threshold','spike width','spike height'}
                    ydata0 = [];
                    xdata0 = [];
                    if strcmp(analysisType,'f-I')
                        iter0 = numel(inputs);
                    else
                        iter0 = 1;
                    end
                    for jj = 1:iter0
                        dt1 = dt{jj};
                        startPt = max(round(app.startEditField_2.Value/dt1),1);
                        stopPt = min(round(app.stopEditField.Value/dt1),length(inputs{jj}(:,:)));
                        data = inputs{jj}(startPt:stopPt,:);
                        output = outputs{jj}(startPt:stopPt,:);
                        pulsePts = zeros(2,size(output,2));
                        currents = zeros(size(output,2),1);
                        frequency = zeros(size(output,2),1);
                        pks = [];
                        locs = [];
                        wds = [];
                        thrs = [];
                        rheo = [];
                        warning off
                        for kk = 1:size(output,2)
                            foo = find(diff(output(:,kk)),2);
                            if numel(foo)==2
                                pulsePts(:,kk) = foo;
                                currents(kk) = output(foo(1)+10,kk);
                                data1 = data(foo(1):foo(2),kk);
                                baseline = mean(data(foo(1)-round(10/dt1):foo(1)));
                                [p,l,w] = findpeaks(data1,1/dt1,'MinPeakHeight',ap.minimumSpikeHeight);
                                if jj==1 && ~isempty(p)
                                    if isempty(rheo)
                                        rheo = kk;
                                    end
                                    n = numel(p);
                                    pks(1:n,kk) = p-baseline;
                                    locs(1:n,kk) = l;
                                    wds(1:n,kk) = w;
                                    if strcmp(analysisType,'spike threshold')
                                        thrs(1:n,kk) = calculatespikethreshold(data1,dt1,l);
                                    end
                                end
                                frequency(kk) = numel(l)*1000/(dt1*(foo(2)-foo(1)));
                            end
                        end
                        warning on
                        ydata0(end+1:end+numel(frequency)) = frequency;
                        xdata0(end+1:end+numel(currents)) = currents;
                    end
                    
                    if strcmp(analysisType,'f-I')
                        
                        [currents,idx] = sort(xdata0);
                        frequency = ydata0(idx);
                        
                        xdata = unique(currents);
                        ydata = zeros(numel(xdata),1);
                        for mm = 1:numel(xdata)
                            idx1 = currents==xdata(mm);
                            ydata(mm) = mean(frequency(idx1));
                        end
                        
                        if TraceNo0>1
                            labelStr1 = ['legendEditField_',num2str(1)];
                            nameStr1 = app.(labelStr1).Value;
                            if ~strcmp(nameStr1(end-2:end),'FIC')
                                for ii = 1:TraceNo0-1
                                    fStr = ['legendEditField_',num2str(ii)];
                                    app.(fStr).Value = '';
                                    app.(fStr).Enable = false;
                                    delete(app.PlotHandle{ii})
                                end
                                app.PlotHandle = [];
                                app.FileNames = [];
                                TraceNo0 = 1;
                                labelStr = ['legendEditField_',num2str(TraceNo0)];
                            end
                        end
                        nameStr = [nameStr,'_FIC'];
                        markers = true;
                        app.RangeLeft.Visible = 'off';
                        app.RangeRight.Visible = 'off';
                        xStr = 'current amplitude (pA)';
                        yStr = 'frequency (Hz)';
                    else  % ISI, threshold, widths, height
                        if isempty(pks)
                            disp('No spikes detected')
                            return
                        end
                        if strcmp(analysisType,'ISI') && size(pks,1)==1
                            disp('Only one spike detected -- no ISI')
                        end
                        ydata = NaN(size(pks,1),size(pks,2));
                        xdata = 1:size(pks,1); xdata = xdata(:);
                        if strcmp(analysisType,'ISI')
                            xdata(end) = [];
                            ydata(end,:) = [];
                        end
                        if isempty(thrs)
                            thrs = pks*0;
                        end
                        for pp = 1:size(pks,2)
                            if pp>=rheo 
                                [ydata0,yStr] = otherbasicproperties(analysisType,pks(:,pp),locs(:,pp),wds(:,pp),thrs(:,pp));
                                ydata(1:length(ydata0),pp) = ydata0;
                            end
                        end
                        ydata(ydata==0) = NaN;
                        markers = true;
                        xStr = 'spike number';
                    end
                        
                case 'evoked amplitude'
                    stimTime = ap.stimulusTime1;
                    ydata = [];
                    manualCheck = questdlg('Check traces manually first?','MANUAL CHECK','Yes','No','No');
                    if strcmp(manualCheck,'Yes')
                        acceptIdx = checkevokedmanually('evoked',inputs,dt{1},app,ap);
                    else
                        acceptIdx = true(1000,1);
                    end
                    for jj = 1:numel(inputs)
                        dt1 = dt{jj};
                        startPt = max(round(app.startEditField_2.Value/dt1),1);
                        stopPt = min(round(app.stopEditField.Value/dt1),length(inputs{jj}(:,:)));
                        data = inputs{jj}(startPt:stopPt,:);
                        data = ap.synapticEvokedDirection * data;
                        stimTime = round(stimTime/dt1 - startPt);
                        amp = zeros(size(data,2),1);
                        for mm = 1:size(data,2) 
                                data(:,mm) = data(:,mm) - mean(data(stimTime+round(ap.baseline(1)/dt1):stimTime+round(ap.baseline(2)/dt1),mm));
                                data1 = data(stimTime+round(ap.peak(1)/dt1):stimTime+round(ap.peak(2)/dt1),mm);
                                data1 = smooth(data1); % smooth using a 5-point moving average
                                amp(mm) = max(data1);
                        end
                        ydata(end+1:end+size(data,2)) = amp;
                    end
                    xdata = 1:size(ydata,2);
                    try
                        ydata = ydata(acceptIdx(1:numel(ydata)));
                        xdata = xdata(acceptIdx(1:numel(xdata)));
                    catch
                        % do not use acceptIdx
                    end
                    markers = true;
                    app.RangeLeft.Visible = 'off';
                    app.RangeRight.Visible = 'off';
                    nameStr = [nameStr,'_EVK'];
                    xStr = 'Time (msec)';
                    yStr = 'I (pA)';
                    
                case 'paired pulse'
                    stimTimes = [ap.stimulusTime1, ap.stimulusTime2];
                    nStimuli = numel(stimTimes);
                    if nStimuli < 2, return, end
                    ydata = [];
                    manualCheck = questdlg('Check traces manually first?','MANUAL CHECK','Yes','No','No');
                    if strcmp(manualCheck,'Yes')
                        acceptIdx = checkevokedmanually('ppr',inputs,dt{1},app,ap);
                    else
                        acceptIdx = true(1000,1);
                    end            
                    for jj = 1:numel(inputs)
                        dt1 = dt{jj};
                        startPt = max(round(app.startEditField_2.Value/dt1),1);
                        stopPt = min(round(app.stopEditField.Value/dt1),length(inputs{jj}(:,:)));
                        data = inputs{jj}(startPt:stopPt,:);
                        data = ap.synapticEvokedDirection * data;
                        stimTimes = round(stimTimes/dt1 - startPt) + round(1/dt1);
                        amp = zeros(nStimuli,size(data,2));
                        for mm = 1:size(data,2)
                            for kk = 1:numel(stimTimes)
                                st = stimTimes(kk);
                                data(:,mm) = data(:,mm) - mean(data(st+round(ap.baseline(1)/dt1):st+round(ap.baseline(2)/dt1),mm));
                                data1 = data(st+round(ap.peak(1)/dt1):st+round(ap.peak(2)/dt1),mm);
                                data1 = smooth(data1); % smooth using a 5-point moving average
                                amp(kk,mm) = max(data1);
                            end
                            
                        end
                        ydata(:,end+1:end+size(data,2)) = amp;
                    end
                    ydata = ydata(2,:)./ydata(1,:);
                    xdata = 1:length(ydata);
                    try
                        ydata = ydata(acceptIdx(1:numel(ydata)));
                        xdata = xdata(acceptIdx(1:numel(xdata)));
                    catch
                        % do not use acceptIdx
                    end
                    markers = true;
                    app.RangeLeft.Visible = 'off';
                    app.RangeRight.Visible = 'off';
                    nameStr = [nameStr,'_PPR'];
                    xStr = 'Time (msec)';
                    yStr = 'I (pA)';
                    
                case 'non-evoked PSCs'
         
                    analyzedData = detectcurrents([]);
                    fNames = fieldnames(analyzedData);                    
                    for jj = 1:numel(inputs)
                        dt1 = dt{jj};
                        startPt = max(round(app.startEditField_2.Value/dt1),1);
                        stopPt = min(round(app.stopEditField.Value/dt1),length(inputs{jj}(:,:)));
                        data = inputs{jj}(startPt:stopPt,:);
                        output = outputs{jj}(startPt:stopPt,:);
                        for kk = 1:size(data,2)
                            [out,~,eventSamplePoints] = detectcurrents(data(:,kk),output,ap,dt1);
                            if ~isempty(out.(fNames{1}))
                                nDetected = size(out.(fNames{1}),1);
                                for mm = 1:numel(fNames)
                                    analyzedData.(fNames{mm})(end+1:end+nDetected,:) = out.(fNames{mm}); 
                                end
                            end
                        end
                    end
                    if isempty(analyzedData.(fNames{1}))
                        disp('No spontaneous PSCs detected')
                        return
                    end
                    analyzedData.dt = dt1;
                    N = numel(analyzedData.amp);
                    analyzedData.N = N;
                    analyzedData.accept = ones(N,1);
                    xdata = (1:eventSamplePoints)*dt1-dt1;
                    ydata = analyzedData.clipped';
                    markers = false; 
                    nameStr= [nameStr,'_PSC'];
                    xStr = 'Time (msec)';
                    yStr = 'I (pA)';

                case 'basic properties'
                    xdata0 = []; 
                    ydata0 = [];
                    dt1 = dt{1};
                    startPt = max(round(app.startEditField_2.Value/dt1),1);
                    stopPt = round(app.stopEditField.Value/dt1);
                    [data,output] = concatenatefiles(inputs,outputs,startPt,stopPt);   
                    pulsePts = zeros(2,size(output,2));
                    currents = zeros(size(output,2),1);
                    frequency = zeros(size(output,2),1);
                    pks = [];
                    locs = [];
                    wds = [];
                    thrs = [];
                    rheo = [];
                    sag = zeros(size(output,2),1);
                    hyperIdx = [];
                    warning off
                    for kk = 1:size(output,2)
                        foo = find(diff(output(:,kk)),2);
                        if numel(foo)==2
                            pulsePts(:,kk) = foo;
                            currents(kk) = output(foo(1)+10,kk);
                            data1 = data(foo(1):foo(2),kk);
                            baseline = mean(data1(1:round(10/dt1)));
                            [p,l,w] = findpeaks(data1,1/dt1,'MinPeakHeight',ap.minimumSpikeHeight);
                            if ~isempty(p)
                                if isempty(rheo)
                                    rheo = kk;
                                end
                                n = numel(p);
                                pks(1:n,kk) = p-baseline;
                                locs(1:n,kk) = l;
                                wds(1:n,kk) = w;
                                thrs(1:n,kk) = calculatespikethreshold(data1,dt1,l);
                            end
                            frequency(kk) = numel(l)*1000/(dt1*(foo(2)-foo(1)));
                            if currents(kk)<0
                                sag(kk) = min(data1(round(end/2):end))-min(data1(1:round(end/2)));
                            end
                            if currents(kk)<0
                                hyperIdx = kk;
                                hyperParams.current = currents(kk);
                                hyperParams.pulsePts = foo;
                                hyperParams.dt = dt1;
                            end
                            if isempty(hyperIdx) && currents(kk)>0
                                hyperIdx = kk;
                                hyperParams.current = currents(kk);
                                hyperParams.pulsePts = foo;
                                hyperParams.dt = dt1;
                            end
                        end
                        ydata0(end+1) = frequency(kk);
                        xdata0(end+1) = currents(kk);
                    end
                    warning on

                    [restingPotential,inputResistance1,membraneTimeConstant] = passiveproperties(data(:,hyperIdx),hyperParams);
                    [inputResistance2] = inputresistance(data,currents,hyperIdx,hyperParams);

                    rheoIdx = rheo;
                    if ~isempty(rheoIdx)
                        rheo = max(output(:,rheoIdx));
                    end
                    [currents,idx] = sort(xdata0);
                    frequency = ydata0(idx);     
                    AI = zeros(size(data,2),1);
                    AI2 = NaN(size(data,2),1);
                    if rheo>0
                        foo = find(diff(output(:,rheoIdx)),2);
                        dataRheo = data(foo(1):foo(2),rheoIdx);
                        [ahp,ahpLatency,ahpHalfWidth,ahpThirdWidth,ahpSlope] = calculateahp(dataRheo,locs(:,rheoIdx),thrs(1,rheoIdx),dt1);
                        if size(data,2)>rheoIdx
                            [~,~,~,~,ahpSlope2] = calculateahp(data(foo(1):foo(2),rheoIdx+1),locs(:,rheoIdx+1),thrs(1,rheoIdx+1),dt1);
                        else
                            ahpSlope2 = [];
                        end
                        for mm = rheoIdx:size(data,2)
                            if numel(locs(:,mm))>2
                                locs1 = locs(:,mm);
                                locs1(locs1==0) = [];
                                isi = diff(locs1);
                                for nn = 1:numel(isi)-1
                                    AI(mm) = AI(mm) + (isi(nn+1)-isi(nn))/(isi(nn+1)+isi(nn));
                                end
                                AI(mm) = AI(mm)/(numel(isi)-1);
                            end
                            midway = round((foo(2)-foo(1))/2) * dt1;
                            locs1 = locs(:,mm);
                            locs1(locs1==0) = [];
                            firstSpikes = numel(find(locs1<=midway));
                            lastSpikes = numel(locs1) - firstSpikes;
                            if firstSpikes
                                AI2(mm) = lastSpikes/firstSpikes;
                            end
                        end
                    end
                    AI(AI==0) = NaN;

                    [xdata,boo] = unique(currents);
                    ydata = zeros(numel(xdata),1);
                    locs = locs(:,boo);
                    for mm = 1:numel(xdata)
                        idx1 = currents==xdata(mm);
                        ydata(mm) = mean(frequency(idx1));
                    end
                                    
                    
                    if TraceNo0>1
                        labelStr1 = ['legendEditField_',num2str(1)];
                        nameStr1 = app.(labelStr1).Value;
                        if ~strcmp(nameStr1(end-2:end),'FIC')
                            for ii = 1:TraceNo0-1
                                fStr = ['legendEditField_',num2str(ii)];
                                app.(fStr).Value = '';
                                app.(fStr).Enable = false;
                                delete(app.PlotHandle{ii})
                            end
                            app.PlotHandle = [];
                            app.FileNames = [];
                            TraceNo0 = 1;
                            labelStr = ['legendEditField_',num2str(TraceNo0)];
                        end
                    end
                    nameStr = [nameStr,'_FIC'];
                    markers = true;
                    app.RangeLeft.Visible = 'off';
                    app.RangeRight.Visible = 'off';
                    xStr = 'current amplitude (pA)';
                    yStr = 'frequency (Hz)';
                    
                    
                otherwise
                    
                    
            end
            
            if markers
                markerStyle = 'o';
            else
                markerStyle = 'none';
            end
            app.TraceNo = TraceNo0;
            app.PlotHandle{app.TraceNo} = plot(app.UIAxes,xdata,ydata,'color',app.ColorOrder(app.TraceNo,:),'marker',markerStyle);
            app.FileNames{app.TraceNo} = [app.SelectedPath,filesep,app.WorkingFile{1}];
            app.(labelStr).Enable = true;
            app.(labelStr).Value = nameStr;
            app.(labelStr).FontColor = app.ColorOrder(app.TraceNo,:);
            app.(labelStr).Editable = 'on';
            app.TraceInfo{app.TraceNo}.channel = selectedChannel;
            app.TraceInfo{app.TraceNo}.sweeps = [first last];
            app.UIAxes.XLabel.String = xStr;
            app.UIAxes.YLabel.String = yStr;
            
            
            if strcmp(analysisType,'non-evoked PSCs')
                app.TraceNo = app.TraceNo + 1;
                app.PlotHandle{app.TraceNo} = plot(app.UIAxes,xdata,mean(ydata,2),'color',app.ColorOrder(app.TraceNo,:),'linewidth',3);
                labelStr = ['legendEditField_',num2str(app.TraceNo)];
                nameStr = [nameStr,'_AVE'];
                app.(labelStr).Enable = true;
                app.(labelStr).Value = nameStr;
                app.(labelStr).FontColor = app.ColorOrder(app.TraceNo,:);
                app.(labelStr).Editable = 'on';
                app.TraceInfo{app.TraceNo}.channel = selectedChannel;
                app.TraceInfo{app.TraceNo}.sweeps = [first last];                
                answer1 = questdlg('What would you like to do?','PSC choices','save PSC results','check PSCs manually','cancel','cancel');
                switch answer1
                    case 'cancel'
                        % do nothing
                    case 'save PSC results'
                        answer2 = questdlg('What file type?','Save choices','MAT','Excel','MAT');
                        fileDirectory = [app.SelectedPath,filesep,'non evoked PSCs',filesep];
                        if ~exist(fileDirectory,'dir')
                            mkdir(fileDirectory)
                        end
                        if strcmp(answer2,'MAT')
                            fileName = [nameStr,'_results.mat'];
                            save([fileDirectory,fileName],'analyzedData')                            
                        elseif strcmp(answer2,'Excel')
                            fileName = [nameStr,'_results.xlsx'];
                            warning off
                            writematrix(analyzedData.clipped',fileName,'Sheet',1,'Range','A2')
                            writematrix('Raw Data: each column is one 60-msec event',fileName,'Sheet',1,'Range','A1')
                            columnLabels = {'amplitude','rise time','charge','second','startPt','peakPt'};
                            writecell(columnLabels,fileName,'Sheet',2,'Range','A1')
                            boo = [analyzedData.amp, analyzedData.rise, analyzedData.area, analyzedData.second, analyzedData.startPt, analyzedData.peakPt];
                            writematrix(boo,fileName,'Sheet',2,'Range','A2')                            
                            warning on                                                  
                        end
                    case 'check PSCs manually'
                        fileDirectory = [app.SelectedPath,filesep,'non evoked PSCs',filesep];
                        if ~exist(fileDirectory,'dir')
                            mkdir(fileDirectory)
                        end      
                        fileName = [fileDirectory,nameStr,'_temp.mat'];
                        save(fileName,'analyzedData')
                        hFig= findall(0,'Tag','PSC_GUI');
                        close(hFig)  
                        PSC(app,fileName);
                        
                end
            end
            
            if strcmp(analysisType,'basic properties')
                x0 = xlim(app.UIAxes);
                y0 = ylim(app.UIAxes);
                x = x0(1) + (x0(2) - x0(1))*0.05;
                y = y0(2) - (y0(2) - y0(1))*0.05;
                excelname = [userpath,filesep,'patchclamp',filesep,'BasicProperties.xlsx'];                
                sheetname = app.FileNames{app.TraceNo};
                baz = strfind(sheetname,filesep);
                baz = baz(end);
                sheetname = sheetname(baz+1:end);
%                 boo = strfind(sheetname,'experiment');
%                 ex = sheetname(boo:end-4);
%                 ex(3:10) = []; ex(8:10)=[];
%                 goo = datestr(Pars.time);
%                 day = goo(1:2);
%                 mo = goo(4:6);
%                 yr = goo(8:11);
%                 sheetname = [yr,'_',mo,'_',day,'_',ex];

                if strcmp(sheetname(1:7),'Kareena')
                    sheetname = sheetname(12:end);
                end

                if length(sheetname)>31
                    sheetname = sheetname(1:31);
                end
                text(app.UIAxes,x,y,'Data stored to Excel file')
                try
                    writematrix(xdata(:),excelname,'Sheet',sheetname,'Range','A2')
                    writematrix('Currents',excelname,'Sheet',sheetname,'Range','A1')
                    writematrix(ydata(:),excelname,'Sheet',sheetname,'Range','B2')
                    writematrix('Firing Rates',excelname,'Sheet',sheetname,'Range','B1')
                    writematrix(AI(:),excelname,'Sheet',sheetname,'Range','C2')
                    writematrix('Adaptation Index',excelname,'Sheet',sheetname,'Range','C1')
                    writematrix(AI2(:),excelname,'Sheet',sheetname,'Range','D2')
                    writematrix('Adaptation Index 2',excelname,'Sheet',sheetname,'Range','D1')
                    writematrix(sag,excelname,'Sheet',sheetname,'Range','E2')
                    writematrix('Sag',excelname,'Sheet',sheetname,'Range','E1')
                    writematrix(restingPotential,excelname,'Sheet',sheetname,'Range','F2')
                    writematrix('V_rest',excelname,'Sheet',sheetname,'Range','F1')
                    writematrix(inputResistance1,excelname,'Sheet',sheetname,'Range','F5')
                    writematrix('R_input_smallest_step',excelname,'Sheet',sheetname,'Range','F4')
                    writematrix(inputResistance2,excelname,'Sheet',sheetname,'Range','F8')
                    writematrix('R_input_slope',excelname,'Sheet',sheetname,'Range','F7')
                    writematrix(membraneTimeConstant,excelname,'Sheet',sheetname,'Range','F11')
                    writematrix('tau',excelname,'Sheet',sheetname,'Range','F10')
                    writematrix(rheo,excelname,'Sheet',sheetname,'Range','F14')
                    writematrix('rheobase',excelname,'Sheet',sheetname,'Range','F13')
                catch
                    warndlg('Make sure the Basic Properties Excel file is closed','Excel Warning')
                    return
                end
                if ~isempty(rheoIdx)
                    wds1 = wds(1,rheoIdx);
                    thrs1 = thrs(1,rheoIdx);
                    pks1 = pks(1,rheoIdx);
                    locs1 = locs(1,rheoIdx);
                    writematrix(thrs1,excelname,'Sheet',sheetname,'Range','G2')
                    writematrix('threshold',excelname,'Sheet',sheetname,'Range','G1')
                    writematrix(wds1,excelname,'Sheet',sheetname,'Range','G5')
                    writematrix('spike width',excelname,'Sheet',sheetname,'Range','G4')
                    writematrix(pks1,excelname,'Sheet',sheetname,'Range','G8')
                    writematrix('spike amplitude',excelname,'Sheet',sheetname,'Range','G7')
                    writematrix(ahp,excelname,'Sheet',sheetname,'Range','G11')
                    writematrix('AHP amplitude',excelname,'Sheet',sheetname,'Range','G10')
                    writematrix(ahpLatency,excelname,'Sheet',sheetname,'Range','G14')
                    writematrix('AHP latency',excelname,'Sheet',sheetname,'Range','G13')
                    writematrix(ahpHalfWidth,excelname,'Sheet',sheetname,'Range','G17')
                    writematrix('AHP half-width',excelname,'Sheet',sheetname,'Range','G16')
                    writematrix(ahpThirdWidth,excelname,'Sheet',sheetname,'Range','G20')
                    writematrix('AHP third-width',excelname,'Sheet',sheetname,'Range','G19')                    
                    writematrix(locs1,excelname,'Sheet',sheetname,'Range','G23')
                    writematrix('spike latency',excelname,'Sheet',sheetname,'Range','G22')
                    writematrix(ahpSlope,excelname,'Sheet',sheetname,'Range','G27')
                    if ~isempty(ahpSlope2)
                        writematrix(ahpSlope2,excelname,'Sheet',sheetname,'Range','G28')
                    end
                    aTypes = {'0-5', '0-10', '0-50', '0-100', '0-200', '50-100', '50-200'};
                    writecell(aTypes,excelname,'Sheet',sheetname,'Range','G26')
                    writecell({'rheo'},excelname,'Sheet',sheetname,'Range','F27')
                    writecell({'rheo+1'},excelname,'Sheet',sheetname,'Range','F28')                    
                    writematrix('AHP slope',excelname,'Sheet',sheetname,'Range','G25')
                    try
                        locs = locs(:,rheoIdx:end);
                        locs(locs==0) = NaN;
                        spks = xdata(rheoIdx:end);
                        spks = [spks(:)'; locs];
                        writematrix(spks,excelname,'Sheet',sheetname,'Range','O2')
                        writecell({'Current (pA)'},excelname,'Sheet',sheetname,'Range','N2')
                    catch
                        % no ISI
                    end
                end
                   
                    
            end
            
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            zoom(app.UIAxes,'off')      
            if strcmp(selectedTab.Title,'analysis')
                delete(app.RangeLeft)
                delete(app.RangeRight)          
                disableDefaultInteractivity(app.UIAxes)
                axis(app.UIAxes,'auto')
                foo = app.UIAxes.XLim;
                boo = app.UIAxes.YLim;
                app.startEditField_2.Value = foo(1);
                app.stopEditField.Value = foo(2);
                app.RangeLeft = images.roi.Line(app.UIAxes,'Position',[foo(1) boo(1); foo(1) boo(2)],'Color','k');
                app.RangeRight = images.roi.Line(app.UIAxes,'Position',[foo(2) boo(1); foo(2) boo(2)],'Color','k');
                addlistener(app.RangeLeft,'ROIMoved',@app.rangeMoving);
                addlistener(app.RangeRight,'ROIMoved',@app.rangeMoving);
            else
                enableDefaultInteractivity(app.UIAxes)
                delete(app.RangeLeft)
                delete(app.RangeRight)
            end
            if strcmp(selectedTab.Title,'saving')
                if app.TraceNo<1, return, end
                for ii = 1:app.TraceNo
                    labelStr = ['legendEditField_',num2str(ii)];
                    nameStr{ii} = app.(labelStr).Value;
                end
                app.tracesListBox.Items = nameStr;
                app.directoryEditField.Value = app.SaveDirectory;
            end
        end

        % Button pushed function: resetrangeButton
        function resetrangeButtonPushed(app, event)
            zoom(app.UIAxes,'off')          
            foo = app.UIAxes.XLim;
            boo = app.UIAxes.YLim;
            app.startEditField_2.Value = foo(1);
            app.stopEditField.Value = foo(2);
            try
                app.RangeLeft.Position = [foo(1) boo(1); foo(1) boo(2)];
                app.RangeRight.Position = [foo(2) boo(1); foo(2) boo(2)];
            catch
                % this should work
            end
            app.RangeLeft.Visible = 'on';
            app.RangeRight.Visible = 'on';
        end

        % Value changed function: startEditField_2
        function startEditField_2ValueChanged(app, event)
            value = app.startEditField_2.Value;
            if ~isvalid(app.RangeLeft)
                boo = app.UIAxes.YLim;
                app.RangeLeft = images.roi.Line(app.UIAxes,'Position',[value boo(1); value boo(2)],'Color','k');
            else
                app.RangeLeft.Position(1,1) = value;
                app.RangeLeft.Position(2,1) = value;
            end
            
        end

        % Value changed function: stopEditField
        function stopEditFieldValueChanged(app, event)
            value = app.stopEditField.Value;
            if ~isvalid(app.RangeRight)
                boo = app.UIAxes.YLim;
                app.RangeRight = images.roi.Line(app.UIAxes,'Position',[value boo(1); value boo(2)],'Color','k');
            else
                app.RangeRight.Position(1,1) = value;
                app.RangeRight.Position(2,1) = value;
            end
            
        end

        % Button pushed function: firstsweepButton_2
        function firstsweepButton_2Pushed(app, event)
            app.firstEditField_2.Value = 1;
            app.lastEditField_2.Value = 1;
            app.allsweepsButton_2.Value = false;
        end

        % Button pushed function: clearfittingtracesButton
        function clearfittingtracesButtonPushed(app, event)
            delete(app.FittedTrace)
        end

        % Button pushed function: parametersButton
        function parametersButtonPushed(app, event)
            parameters
        end

        % Button pushed function: changedirectoryButton_2
        function changedirectoryButton_2Pushed(app, event)
            newPath = uigetdir(app.SaveDirectory,'Choose a directory');
            if newPath
                app.SaveDirectory = newPath;
                app.directoryEditField.Value = newPath;
            end
            
        end

        % Button pushed function: saveButton
        function saveButtonPushed(app, event)
            items = app.tracesListBox.Items;
            values = app.tracesListBox.Value;
            idx = find(ismember(items,values));
            choices = {'Matlab','Excel','ASCII'};
            for ii = 1:numel(choices)
                if app.([choices{ii},'Button']).Value
                    choice = choices{ii};
                    break
                end
            end
            
            for ii = 1:numel(idx)
                traceNo = idx(ii);
                labelStr = ['legendEditField_',num2str(traceNo)];                
                nameStr = app.(labelStr).Value;
                h = app.PlotHandle{traceNo};
                xdata = zeros(length(h(1).XData),numel(h));
                ydata = xdata;
                for jj = 1:numel(h)
                    xdata(:,jj) = h(jj).XData(:);
                    ydata(:,jj) = h(jj).YData(:);
                end
                filename = [app.SaveDirectory,filesep,nameStr];
                switch choice
                    case 'Matlab'
                        filename = [filename,'.mat'];
                        save(filename,'xdata','ydata','-mat')
                    case 'Excel'
                        filename = [filename,'.xlsx'];
                        warning off
                        writematrix(xdata,filename,'Sheet',1)
                        writematrix(ydata,filename,'Sheet',2)
                        warning on
                    case 'ASCII'
                        filenameX = [filename,'_X.csv'];
                        filenameY = [filename,'_Y.csv'];
                        writematrix(xdata,filenameX)
                        writematrix(ydata,filenameY)
                end
            end


        end

        % Button pushed function: refreshButton
        function refreshButtonPushed(app, event)
            updatefilelist(app)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'analysis')
            xlabel(app.UIAxes, 'Time (ms)')
            ylabel(app.UIAxes, 'V_m (mV)')
            app.changedirectoryButton = uibutton(app.UIFigure, 'push');
            app.changedirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @changedirectoryButtonPushed, true);
            app.changedirectoryButton.Text = 'change directory';
            app.filesListBox = uilistbox(app.UIFigure);
            app.filesListBox.Multiselect = 'on';
            app.filesListBox.ValueChangedFcn = createCallbackFcn(app, @filesListBoxValueChanged, true);
            app.filesListBox.Value = {'Item 1'};
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.displayTab = uitab(app.TabGroup);
            app.displayTab.Title = 'display';
            app.displayButton = uibutton(app.displayTab, 'push');
            app.displayButton.ButtonPushedFcn = createCallbackFcn(app, @displayButtonPushed, true);
            app.displayButton.Text = 'display';
            app.backButton = uibutton(app.displayTab, 'push');
            app.backButton.ButtonPushedFcn = createCallbackFcn(app, @backButtonPushed, true);
            app.backButton.Text = '<';
            app.forwardButton = uibutton(app.displayTab, 'push');
            app.forwardButton.ButtonPushedFcn = createCallbackFcn(app, @forwardButtonPushed, true);
            app.forwardButton.Text = '>';
            app.firstEditFieldLabel = uilabel(app.displayTab);
            app.firstEditFieldLabel.Text = 'first';
            app.firstEditField = uieditfield(app.displayTab, 'numeric');
            app.firstEditField.Limits = [1 1000];
            app.firstEditField.ValueDisplayFormat = '%.0f';
            app.firstEditField.ValueChangedFcn = createCallbackFcn(app, @firstEditFieldValueChanged, true);
            app.firstEditField.Value = 1;
            app.lastEditFieldLabel = uilabel(app.displayTab);
            app.lastEditFieldLabel.Text = 'last';
            app.lastEditField = uieditfield(app.displayTab, 'numeric');
            app.lastEditField.Limits = [1 1000];
            app.lastEditField.ValueDisplayFormat = '%.0f';
            app.lastEditField.ValueChangedFcn = createCallbackFcn(app, @lastEditFieldValueChanged, true);
            app.lastEditField.Value = 1;
            app.sweepsLabel = uilabel(app.displayTab);
            app.sweepsLabel.Text = 'sweeps';
            app.firstsweepButton = uibutton(app.displayTab, 'push');
            app.firstsweepButton.ButtonPushedFcn = createCallbackFcn(app, @firstsweepButtonPushed, true);
            app.firstsweepButton.Text = 'first sweep';
            app.startEditFieldLabel = uilabel(app.displayTab);
            app.startEditFieldLabel.Text = 'start';
            app.startEditField = uieditfield(app.displayTab, 'numeric');
            app.startEditField.Limits = [0 Inf];
            app.startEditField.RoundFractionalValues = 'on';
            app.startEditField.ValueDisplayFormat = '%.0f';
            app.deltaEditFieldLabel = uilabel(app.displayTab);
            app.deltaEditFieldLabel.Text = 'delta';
            app.deltaEditField = uieditfield(app.displayTab, 'numeric');
            app.deltaEditField.Limits = [1 Inf];
            app.deltaEditField.RoundFractionalValues = 'on';
            app.deltaEditField.ValueDisplayFormat = '%.0f';
            app.deltaEditField.Value = 1;
            app.baselinesubtractCheckBox = uicheckbox(app.displayTab);
            app.baselinesubtractCheckBox.Text = 'baseline subtract';
            app.stimuliButton = uibutton(app.displayTab, 'push');
            app.stimuliButton.ButtonPushedFcn = createCallbackFcn(app, @stimuliButtonPushed, true);
            app.stimuliButton.Text = 'stimuli';
            app.replaceCheckBox = uicheckbox(app.displayTab);
            app.replaceCheckBox.Text = 'replace';
            app.replaceCheckBox.Value = true;
            app.autoscaleCheckBox = uicheckbox(app.displayTab);
            app.autoscaleCheckBox.Text = 'autoscale';
            app.autoscaleCheckBox.Value = true;
            app.allsweepsButton = uibutton(app.displayTab, 'state');
            app.allsweepsButton.Text = 'all sweeps';
            app.averagingSwitch = uiswitch(app.displayTab, 'toggle');
            app.averagingSwitch.Items = {'individual', 'average'};
            app.averagingSwitch.Value = 'individual';
            app.analysisTab = uitab(app.TabGroup);
            app.analysisTab.Title = 'analysis';
            app.sweepsLabel_2 = uilabel(app.analysisTab);
            app.sweepsLabel_2.Text = 'sweeps';
            app.firstsweepButton_2 = uibutton(app.analysisTab, 'push');
            app.firstsweepButton_2.ButtonPushedFcn = createCallbackFcn(app, @firstsweepButton_2Pushed, true);
            app.firstsweepButton_2.Text = 'first sweep';
            app.firstEditField_2Label = uilabel(app.analysisTab);
            app.firstEditField_2Label.Text = 'first';
            app.firstEditField_2 = uieditfield(app.analysisTab, 'numeric');
            app.firstEditField_2.Limits = [1 1000];
            app.firstEditField_2.ValueDisplayFormat = '%.0f';
            app.firstEditField_2.Value = 1;
            app.lastEditField_2Label = uilabel(app.analysisTab);
            app.lastEditField_2Label.Text = 'last';
            app.lastEditField_2 = uieditfield(app.analysisTab, 'numeric');
            app.lastEditField_2.Limits = [1 1000];
            app.lastEditField_2.ValueDisplayFormat = '%.0f';
            app.lastEditField_2.Value = 1;
            app.analyzeButton = uibutton(app.analysisTab, 'push');
            app.analyzeButton.ButtonPushedFcn = createCallbackFcn(app, @analyzeButtonPushed, true);
            app.analyzeButton.Text = 'analyze';
            app.rangeLabel = uilabel(app.analysisTab);
            app.rangeLabel.Text = 'range';
            app.resetrangeButton = uibutton(app.analysisTab, 'push');
            app.resetrangeButton.ButtonPushedFcn = createCallbackFcn(app, @resetrangeButtonPushed, true);
            app.resetrangeButton.Text = 'reset range';
            app.stopEditFieldLabel = uilabel(app.analysisTab);
            app.stopEditFieldLabel.Text = 'stop';
            app.stopEditField = uieditfield(app.analysisTab, 'numeric');
            app.stopEditField.ValueDisplayFormat = '%0.1f';
            app.stopEditField.ValueChangedFcn = createCallbackFcn(app, @stopEditFieldValueChanged, true);
            app.startEditField_2Label = uilabel(app.analysisTab);
            app.startEditField_2Label.Text = 'start';
            app.startEditField_2 = uieditfield(app.analysisTab, 'numeric');
            app.startEditField_2.ValueDisplayFormat = '%0.1f';
            app.startEditField_2.ValueChangedFcn = createCallbackFcn(app, @startEditField_2ValueChanged, true);
            app.analysistypeDropDownLabel = uilabel(app.analysisTab);
            app.analysistypeDropDownLabel.Text = 'analysis type';
            app.analysistypeDropDown = uidropdown(app.analysisTab);
            app.analysistypeDropDown.Items = {'average', 'f-I', 'ISI', 'spike threshold', 'spike width', 'spike height', 'evoked amplitude', 'paired pulse', 'non-evoked PSCs', 'basic properties'};
            app.analysistypeDropDown.Value = 'average';
            app.allsweepsButton_2 = uibutton(app.analysisTab, 'state');
            app.allsweepsButton_2.Text = 'all sweeps';
            app.parametersButton = uibutton(app.analysisTab, 'push');
            app.parametersButton.ButtonPushedFcn = createCallbackFcn(app, @parametersButtonPushed, true);
            app.parametersButton.Text = 'parameters';
            app.fullSwitchLabel = uilabel(app.analysisTab);
            app.fullSwitchLabel.Text = 'full';
            app.fullSwitch = uiswitch(app.analysisTab, 'slider');
            app.fullSwitch.Items = {'off', 'on'};
            app.fullSwitch.Value = 'on';
            app.replaceAnalysisCheckBox = uicheckbox(app.analysisTab);
            app.replaceAnalysisCheckBox.Text = 'replace';
            app.fittingTab = uitab(app.TabGroup);
            app.fittingTab.Title = 'fitting';
            app.placedatacursorsButton = uibutton(app.fittingTab, 'push');
            app.placedatacursorsButton.ButtonPushedFcn = createCallbackFcn(app, @placedatacursorsButtonPushed, true);
            app.placedatacursorsButton.Text = {'place'; 'data '; 'cursors'};
            app.linearButton = uibutton(app.fittingTab, 'push');
            app.linearButton.ButtonPushedFcn = createCallbackFcn(app, @linearButtonPushed, true);
            app.linearButton.Text = 'linear';
            app.exponentialButton = uibutton(app.fittingTab, 'push');
            app.exponentialButton.ButtonPushedFcn = createCallbackFcn(app, @exponentialButtonPushed, true);
            app.exponentialButton.Text = 'exponential';
            app.averageButton = uibutton(app.fittingTab, 'push');
            app.averageButton.ButtonPushedFcn = createCallbackFcn(app, @averageButtonPushed, true);
            app.averageButton.Text = 'average';
            app.integralButton = uibutton(app.fittingTab, 'push');
            app.integralButton.ButtonPushedFcn = createCallbackFcn(app, @integralButtonPushed, true);
            app.integralButton.Text = 'integral';
            app.cleardatacursorsButton = uibutton(app.fittingTab, 'push');
            app.cleardatacursorsButton.ButtonPushedFcn = createCallbackFcn(app, @cleardatacursorsButtonPushed, true);
            app.cleardatacursorsButton.Text = {'clear'; 'data '; 'cursors'};
            app.clearfittingtracesButton = uibutton(app.fittingTab, 'push');
            app.clearfittingtracesButton.ButtonPushedFcn = createCallbackFcn(app, @clearfittingtracesButtonPushed, true);
            app.clearfittingtracesButton.Text = {'clear'; 'fitting'; 'traces'};
            app.cursor1TextAreaLabel = uilabel(app.fittingTab);
            app.cursor1TextAreaLabel.Text = 'cursor 1';
            app.cursor1TextArea = uitextarea(app.fittingTab);
            app.cursor1TextArea.Editable = 'off';
            app.cursor2TextAreaLabel = uilabel(app.fittingTab);
            app.cursor2TextAreaLabel.Text = 'cursor 2';
            app.cursor2TextArea = uitextarea(app.fittingTab);
            app.cursor2TextArea.Editable = 'off';
            app.deltaTextAreaLabel = uilabel(app.fittingTab);
            app.deltaTextAreaLabel.Text = 'delta';
            app.deltaTextArea = uitextarea(app.fittingTab);
            app.deltaTextArea.Editable = 'off';
            app.fittingresultTextArea = uitextarea(app.fittingTab);
            app.savingTab = uitab(app.TabGroup);
            app.savingTab.Title = 'saving';
            app.saveButton = uibutton(app.savingTab, 'push');
            app.saveButton.ButtonPushedFcn = createCallbackFcn(app, @saveButtonPushed, true);
            app.saveButton.Text = 'save';
            app.tracesListBoxLabel = uilabel(app.savingTab);
            app.tracesListBoxLabel.Text = 'traces';
            app.tracesListBox = uilistbox(app.savingTab);
            app.tracesListBox.Items = {};
            app.tracesListBox.Multiselect = 'on';
            app.tracesListBox.Value = {};
            app.changedirectoryButton_2 = uibutton(app.savingTab, 'push');
            app.changedirectoryButton_2.ButtonPushedFcn = createCallbackFcn(app, @changedirectoryButton_2Pushed, true);
            app.changedirectoryButton_2.Text = 'change directory';
            app.directoryEditFieldLabel = uilabel(app.savingTab);
            app.directoryEditFieldLabel.Text = 'directory';
            app.directoryEditField = uieditfield(app.savingTab, 'text');
            app.fileformatButtonGroup = uibuttongroup(app.savingTab);
            app.fileformatButtonGroup.Title = 'file format';
            app.MatlabButton = uitogglebutton(app.fileformatButtonGroup);
            app.MatlabButton.Text = 'Matlab';
            app.MatlabButton.Value = true;
            app.ExcelButton = uitogglebutton(app.fileformatButtonGroup);
            app.ExcelButton.Text = 'Excel';
            app.ASCIIButton = uitogglebutton(app.fileformatButtonGroup);
            app.ASCIIButton.Text = 'ASCII';
            app.UIChannels = uitable(app.UIFigure);
            app.UIChannels.ColumnName = {'channel 1'; 'channel 2'; 'channel 3'; 'channel 4'; 'channel 5'; 'channel 6'; 'channel 7'; 'channel 8'};
            app.UIChannels.RowName = {};
            app.UIChannels.ColumnEditable = true;
            app.fileinfoTextAreaLabel = uilabel(app.UIFigure);
            app.fileinfoTextAreaLabel.Text = 'file info';
            app.fileinfoTextArea = uitextarea(app.UIFigure);
            app.clearButton = uibutton(app.UIFigure, 'push');
            app.clearButton.ButtonPushedFcn = createCallbackFcn(app, @clearButtonPushed, true);
            app.clearButton.Text = 'clear';
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.legend1Tab = uitab(app.TabGroup2);
            app.legend1Tab.Title = 'legend 1';
            app.legendEditField_1 = uieditfield(app.legend1Tab, 'text');
            app.legendEditField_1.ValueChangedFcn = createCallbackFcn(app, @legendEditField_1ValueChanged, true);
            app.legendEditField_1.Tag = 'legend01';
            app.legendEditField_2 = uieditfield(app.legend1Tab, 'text');
            app.legendEditField_2.ValueChangedFcn = createCallbackFcn(app, @legendEditField_1ValueChanged, true);
            app.legendEditField_2.Tag = 'legend02';
            app.legendEditField_3 = uieditfield(app.legend1Tab, 'text');
            app.legendEditField_3.ValueChangedFcn = createCallbackFcn(app, @legendEditField_1ValueChanged, true);
            app.legendEditField_3.Tag = 'legend03';
            app.legendEditField_4 = uieditfield(app.legend1Tab, 'text');
            app.legendEditField_4.ValueChangedFcn = createCallbackFcn(app, @legendEditField_1ValueChanged, true);
            app.legendEditField_4.Tag = 'legend04';
            app.legendEditField_5 = uieditfield(app.legend1Tab, 'text');
            app.legendEditField_5.ValueChangedFcn = createCallbackFcn(app, @legendEditField_1ValueChanged, true);
            app.legendEditField_5.Tag = 'legend05';
            app.legendEditField_6 = uieditfield(app.legend1Tab, 'text');
            app.legendEditField_6.ValueChangedFcn = createCallbackFcn(app, @legendEditField_1ValueChanged, true);
            app.legendEditField_6.Tag = 'legend06';
            app.legendEditField_7 = uieditfield(app.legend1Tab, 'text');
            app.legendEditField_7.ValueChangedFcn = createCallbackFcn(app, @legendEditField_1ValueChanged, true);
            app.legendEditField_7.Tag = 'legend07';
            app.legendEditField_8 = uieditfield(app.legend1Tab, 'text');
            app.legendEditField_8.ValueChangedFcn = createCallbackFcn(app, @legendEditField_1ValueChanged, true);
            app.legendEditField_8.Tag = 'legend08';
            app.legend2Tab = uitab(app.TabGroup2);
            app.legend2Tab.Title = 'legend 2';
            app.legendEditField_9 = uieditfield(app.legend2Tab, 'text');
            app.legendEditField_9.ValueChangedFcn = createCallbackFcn(app, @legendEditField_1ValueChanged, true);
            app.legendEditField_9.Tag = 'legend09';
            app.legendEditField_10 = uieditfield(app.legend2Tab, 'text');
            app.legendEditField_10.ValueChangedFcn = createCallbackFcn(app, @legendEditField_1ValueChanged, true);
            app.legendEditField_10.Tag = 'legend10';
            app.legendEditField_11 = uieditfield(app.legend2Tab, 'text');
            app.legendEditField_11.ValueChangedFcn = createCallbackFcn(app, @legendEditField_1ValueChanged, true);
            app.legendEditField_11.Tag = 'legend11';
            app.legendEditField_12 = uieditfield(app.legend2Tab, 'text');
            app.legendEditField_12.ValueChangedFcn = createCallbackFcn(app, @legendEditField_1ValueChanged, true);
            app.legendEditField_12.Tag = 'legend12';
            app.legendEditField_13 = uieditfield(app.legend2Tab, 'text');
            app.legendEditField_13.ValueChangedFcn = createCallbackFcn(app, @legendEditField_1ValueChanged, true);
            app.legendEditField_13.Tag = 'legend13';
            app.legendEditField_14 = uieditfield(app.legend2Tab, 'text');
            app.legendEditField_14.ValueChangedFcn = createCallbackFcn(app, @legendEditField_1ValueChanged, true);
            app.legendEditField_14.Tag = 'legend14';
            app.legendEditField_15 = uieditfield(app.legend2Tab, 'text');
            app.legendEditField_15.ValueChangedFcn = createCallbackFcn(app, @legendEditField_1ValueChanged, true);
            app.legendEditField_15.Tag = 'legend15';
            app.legendEditField_16 = uieditfield(app.legend2Tab, 'text');
            app.legendEditField_16.ValueChangedFcn = createCallbackFcn(app, @legendEditField_1ValueChanged, true);
            app.legendEditField_16.Tag = 'legend16';
            app.legend3Tab = uitab(app.TabGroup2);
            app.legend3Tab.Title = 'legend 3';
            app.legendEditField_17 = uieditfield(app.legend3Tab, 'text');
            app.legendEditField_17.ValueChangedFcn = createCallbackFcn(app, @legendEditField_1ValueChanged, true);
            app.legendEditField_17.Tag = 'legend17';
            app.legendEditField_18 = uieditfield(app.legend3Tab, 'text');
            app.legendEditField_18.ValueChangedFcn = createCallbackFcn(app, @legendEditField_1ValueChanged, true);
            app.legendEditField_18.Tag = 'legend18';
            app.legendEditField_19 = uieditfield(app.legend3Tab, 'text');
            app.legendEditField_19.ValueChangedFcn = createCallbackFcn(app, @legendEditField_1ValueChanged, true);
            app.legendEditField_19.Tag = 'legend19';
            app.legendEditField_20 = uieditfield(app.legend3Tab, 'text');
            app.legendEditField_20.ValueChangedFcn = createCallbackFcn(app, @legendEditField_1ValueChanged, true);
            app.legendEditField_20.Tag = 'legend20';
            app.legendEditField_21 = uieditfield(app.legend3Tab, 'text');
            app.legendEditField_21.ValueChangedFcn = createCallbackFcn(app, @legendEditField_1ValueChanged, true);
            app.legendEditField_21.Tag = 'legend21';
            app.legendEditField_22 = uieditfield(app.legend3Tab, 'text');
            app.legendEditField_22.ValueChangedFcn = createCallbackFcn(app, @legendEditField_1ValueChanged, true);
            app.legendEditField_22.Tag = 'legend22';
            app.legendEditField_23 = uieditfield(app.legend3Tab, 'text');
            app.legendEditField_23.ValueChangedFcn = createCallbackFcn(app, @legendEditField_1ValueChanged, true);
            app.legendEditField_23.Tag = 'legend23';
            app.legendEditField_24 = uieditfield(app.legend3Tab, 'text');
            app.legendEditField_24.ValueChangedFcn = createCallbackFcn(app, @legendEditField_1ValueChanged, true);
            app.legendEditField_24.Tag = 'legend24';
            app.refreshButton = uibutton(app.UIFigure, 'push');
            app.refreshButton.ButtonPushedFcn = createCallbackFcn(app, @refreshButtonPushed, true);
            app.refreshButton.Icon = 'Refresh_icon.svg';
            app.refreshButton.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LCSMS_analysis

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end