classdef LCSMS_patch_clamp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LCSMSpatchclampUIFigure         matlab.ui.Figure
        databrowserButton               matlab.ui.control.Button
        sequenceButton                  matlab.ui.control.Button
        resetButton                     matlab.ui.control.Button
        TabGroup3                       matlab.ui.container.TabGroup
        testpulseTab                    matlab.ui.container.Tab
        zeroButton                      matlab.ui.control.Button
        durationTestEditField           matlab.ui.control.NumericEditField
        durationEditField_2Label        matlab.ui.control.Label
        amplitudeTestEditField          matlab.ui.control.NumericEditField
        amplitudeEditFieldLabel         matlab.ui.control.Label
        testButton                      matlab.ui.control.Button
        Label_28                        matlab.ui.control.Label
        Label_27                        matlab.ui.control.Label
        Label_26                        matlab.ui.control.Label
        Label_25                        matlab.ui.control.Label
        UITestPulse                     matlab.ui.control.Table
        resistanceholdingTab            matlab.ui.container.Tab
        capcompButton_4                 matlab.ui.control.Button
        capcompButton_3                 matlab.ui.control.Button
        capcompButton_2                 matlab.ui.control.Button
        capcompButton_1                 matlab.ui.control.Button
        channel4Label                   matlab.ui.control.Label
        channel3Label                   matlab.ui.control.Label
        channel2Label                   matlab.ui.control.Label
        channel1Label                   matlab.ui.control.Label
        channel4holdOnButton            matlab.ui.control.Button
        channel3holdOnButton            matlab.ui.control.Button
        channel2holdOnButton            matlab.ui.control.Button
        channel1holdOnButton            matlab.ui.control.Button
        checkTab                        matlab.ui.container.Tab
        checkPropertiesButton           matlab.ui.control.StateButton
        channelDropDown                 matlab.ui.control.DropDown
        channelDropDownLabel            matlab.ui.control.Label
        holdingcurrentEditField         matlab.ui.control.NumericEditField
        holdingcurrentEditFieldLabel    matlab.ui.control.Label
        inputresistanceEditField        matlab.ui.control.NumericEditField
        inputresistanceEditFieldLabel   matlab.ui.control.Label
        seriesresistanceEditField       matlab.ui.control.NumericEditField
        seriesresistanceEditFieldLabel  matlab.ui.control.Label
        inputsPanel                     matlab.ui.container.Panel
        autoscaleGraph2Button           matlab.ui.control.Button
        autoscaleGraph1Button           matlab.ui.control.Button
        popupSwitch_2                   matlab.ui.control.Switch
        popupSwitch_2Label              matlab.ui.control.Label
        popupSwitch_1                   matlab.ui.control.Switch
        popupSwitchLabel                matlab.ui.control.Label
        Label_12                        matlab.ui.control.Label
        Label_11                        matlab.ui.control.Label
        Label_10                        matlab.ui.control.Label
        Label_9                         matlab.ui.control.Label
        Label_8                         matlab.ui.control.Label
        Label_7                         matlab.ui.control.Label
        Label_6                         matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
        graph2Label                     matlab.ui.control.Label
        graph1Label                     matlab.ui.control.Label
        UIPlottingChannels              matlab.ui.control.Table
        UIInputAxes2                    matlab.ui.control.UIAxes
        UIInputAxes1                    matlab.ui.control.UIAxes
        analysisPanel                   matlab.ui.container.Panel
        otheranalysesDropDown           matlab.ui.control.DropDown
        otheranalysesDropDownLabel      matlab.ui.control.Label
        stabilityButtonGroup            matlab.ui.container.ButtonGroup
        checkButton                     matlab.ui.control.ToggleButton
        donotcheckButton                matlab.ui.control.ToggleButton
        recordingPanel                  matlab.ui.container.Panel
        episodicDropDown                matlab.ui.control.DropDown
        saveDropDown                    matlab.ui.control.DropDown
        savelastpreviewButton           matlab.ui.control.Button
        startButton                     matlab.ui.control.Button
        sexSwitch                       matlab.ui.control.Switch
        DOBDatePicker                   matlab.ui.control.DatePicker
        DOBDatePickerLabel              matlab.ui.control.Label
        mouseidEditField                matlab.ui.control.EditField
        mouseidEditFieldLabel           matlab.ui.control.Label
        notesTextArea                   matlab.ui.control.TextArea
        notesTextAreaLabel              matlab.ui.control.Label
        descriptorEditField             matlab.ui.control.EditField
        descriptorEditFieldLabel        matlab.ui.control.Label
        outputsPanel                    matlab.ui.container.Panel
        protocolEditField               matlab.ui.control.EditField
        protocolEditFieldLabel          matlab.ui.control.Label
        analogLabel                     matlab.ui.control.Label
        updateoutputsButton             matlab.ui.control.Button
        digitalLabel                    matlab.ui.control.Label
        Label_20                        matlab.ui.control.Label
        Label_19                        matlab.ui.control.Label
        Label_18                        matlab.ui.control.Label
        Label_17                        matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        shuffleCheckBox                 matlab.ui.control.CheckBox
        durationEditField               matlab.ui.control.NumericEditField
        durationEditFieldLabel          matlab.ui.control.Label
        periodEditField                 matlab.ui.control.NumericEditField
        periodEditFieldLabel            matlab.ui.control.Label
        repetitionsEditField            matlab.ui.control.NumericEditField
        repetitionsEditFieldLabel       matlab.ui.control.Label
        saveoutputsButton               matlab.ui.control.Button
        loadoutputsButton               matlab.ui.control.Button
        UIOutputChannels                matlab.ui.control.Table
        UIOutputData                    matlab.ui.control.Table
        TabGroup2                       matlab.ui.container.TabGroup
        outputwaveformsTab              matlab.ui.container.Tab
        displayoutputsButton            matlab.ui.control.Button
        autoscaleButton                 matlab.ui.control.Button
        channelstodisplayDropDown       matlab.ui.control.DropDown
        channelstodisplayDropDownLabel  matlab.ui.control.Label
        UIOutputAxes                    matlab.ui.control.UIAxes
        stabilityTab                    matlab.ui.container.Tab
        autoscaleStabilityButton        matlab.ui.control.Button
        Label_24                        matlab.ui.control.Label
        Label_23                        matlab.ui.control.Label
        Label_22                        matlab.ui.control.Label
        Label_21                        matlab.ui.control.Label
        UIStability                     matlab.ui.control.Table
        endLabel                        matlab.ui.control.Label
        endStabilityEditField           matlab.ui.control.NumericEditField
        beginningLabel                  matlab.ui.control.Label
        beginningStabilityEditField     matlab.ui.control.NumericEditField
        clearStabilityButton            matlab.ui.control.Button
        UIAxesSeriesResistance          matlab.ui.control.UIAxes
        UIAxesRestingPotential          matlab.ui.control.UIAxes
        UIAxesInputResistance           matlab.ui.control.UIAxes
        analysisTab                     matlab.ui.container.Tab
        clearStabilityButton_2          matlab.ui.control.Button
        analysisChannelDropDown         matlab.ui.control.DropDown
        UIOutputAxes_2                  matlab.ui.control.UIAxes
        parametersPanel                 matlab.ui.container.Panel
        trialNumberEditField            matlab.ui.control.NumericEditField
        trialnumberLabel                matlab.ui.control.Label
        experimentNumberEditField       matlab.ui.control.NumericEditField
        experimentnumberEditFieldLabel  matlab.ui.control.Label
        readmulticlampButton            matlab.ui.control.Button
        writemulticlampButton           matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        channel1Tab                     matlab.ui.container.Tab
        enableSwitch_1                  matlab.ui.control.Switch
        holdingEditField_1              matlab.ui.control.NumericEditField
        holdingEditFieldLabel           matlab.ui.control.Label
        gainDropDown_1                  matlab.ui.control.DropDown
        gainDropDownLabel               matlab.ui.control.Label
        statusDropDown_1                matlab.ui.control.DropDown
        statusDropDownLabel             matlab.ui.control.Label
        channel2Tab                     matlab.ui.container.Tab
        enableSwitch_2                  matlab.ui.control.Switch
        holdingEditField_2              matlab.ui.control.NumericEditField
        holdingEditField_2Label         matlab.ui.control.Label
        gainDropDown_2                  matlab.ui.control.DropDown
        gainDropDown_2Label             matlab.ui.control.Label
        statusDropDown_2                matlab.ui.control.DropDown
        statusDropDown_2Label           matlab.ui.control.Label
        channel3Tab                     matlab.ui.container.Tab
        enableSwitch_3                  matlab.ui.control.Switch
        holdingEditField_3              matlab.ui.control.NumericEditField
        holdingEditField_3Label         matlab.ui.control.Label
        gainDropDown_3                  matlab.ui.control.DropDown
        gainDropDown_3Label             matlab.ui.control.Label
        statusDropDown_3                matlab.ui.control.DropDown
        statusDropDown_3Label           matlab.ui.control.Label
        channel4Tab                     matlab.ui.container.Tab
        enableSwitch_4                  matlab.ui.control.Switch
        holdingEditField_4              matlab.ui.control.NumericEditField
        holdingEditField_4Label         matlab.ui.control.Label
        gainDropDown_4                  matlab.ui.control.DropDown
        gainDropDown_4Label             matlab.ui.control.Label
        statusDropDown_4                matlab.ui.control.DropDown
        statusDropDown_4Label           matlab.ui.control.Label
        channel5Tab                     matlab.ui.container.Tab
        holdingEditField_5              matlab.ui.control.NumericEditField
        holdingEditField_5Label         matlab.ui.control.Label
        gainDropDown_5                  matlab.ui.control.DropDown
        gainDropDown_5Label             matlab.ui.control.Label
        statusDropDown_5                matlab.ui.control.DropDown
        statusDropDown_5Label           matlab.ui.control.Label
        channel6Tab                     matlab.ui.container.Tab
        holdingEditField_6              matlab.ui.control.NumericEditField
        holdingEditField_6Label         matlab.ui.control.Label
        gainDropDown_6                  matlab.ui.control.DropDown
        gainDropDown_6Label             matlab.ui.control.Label
        statusDropDown_6                matlab.ui.control.DropDown
        statusDropDown_6Label           matlab.ui.control.Label
        channel7Tab                     matlab.ui.container.Tab
        holdingEditField_7              matlab.ui.control.NumericEditField
        holdingEditField_7Label         matlab.ui.control.Label
        gainDropDown_7                  matlab.ui.control.DropDown
        gainDropDown_7Label             matlab.ui.control.Label
        statusDropDown_7                matlab.ui.control.DropDown
        statusDropDown_7Label           matlab.ui.control.Label
        channel8Tab                     matlab.ui.container.Tab
        holdingEditField_8              matlab.ui.control.NumericEditField
        holdingEditField_8Label         matlab.ui.control.Label
        gainDropDown_8                  matlab.ui.control.DropDown
        gainDropDown_8Label             matlab.ui.control.Label
        statusDropDown_8                matlab.ui.control.DropDown
        statusDropDown_8Label           matlab.ui.control.Label
        sampleRateDropDown              matlab.ui.control.DropDown
        samplerateLabel                 matlab.ui.control.Label
        hardwareButton                  matlab.ui.control.Button
        saveparametersButton            matlab.ui.control.Button
        loadparametersButton            matlab.ui.control.Button
        newexperimentButton             matlab.ui.control.Button
    end

    properties (Access = public)
        HardwareConfig % hardware configuration
        HardwareApp % handle to the hardware app
        TestPulse % seal test on or off
        Recording % recording on or off
    end
    
    properties (Access = private)
        CurrentParametersFolder % folder to start in when loadparameters is pushed
        CurrentOutputsFolder % folder to start in when loadoutputs is pushed
    end
    
    methods (Access = public)
        
        function [] = pushHardwareButton(app)
            app.hardwareButtonPushed;
        end
        
        function [] = pushTestButton(app)
            app.testButtonPushed;
        end
        
        function [] = pushStartButton(app)
            app.startButtonPushed;
        end
        
        function [] = pushUpdateOutputsButton(app)
            app.updateoutputsButtonPushed;
        end
        
        function [] = pushWriteMulticlampButton(app)
            app.writemulticlampButtonPushed;
        end
        
        function [] = pushReadMulticlampButton(app)
            app.readmulticlampButtonPushed;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global DAQPARS

            data = cell(12,1);
            data2 = cell(12,1);
            data3 = cell(12,1);
            app.UIOutputData.Data = [data, data2, data3];
            app.UIOutputChannels.Data = false(12,8);
            app.UITestPulse.Data = false(1,4);
            app.UITestPulse.Data(1) = true;
            app.TestPulse = false;
            app.UIPlottingChannels.Data = false(2,8);
            app.Recording = false;
            app.UIOutputAxes.YLimMode = 'auto';
            app.UIOutputAxes.XLimMode = 'auto';
            app.UIOutputAxes.XLimMode = 'auto';
            app.UIOutputAxes.XTickMode = 'auto';
            app.UIStability.Data = false(1,4);
            zoomObj = zoomInteraction;
            panObj = panInteraction;
            app.UIInputAxes1.Interactions = [zoomObj, panObj];
            app.UIInputAxes2.Interactions = [zoomObj, panObj];
            app.CurrentParametersFolder = [DAQPARS.daqFolder,'\user_files\parameters\'];
            app.CurrentOutputsFolder = [DAQPARS.daqFolder,'\user_files\outputs\'];
            warning('off')
            
        end

        % Button pushed function: hardwareButton
        function hardwareButtonPushed(app, event)
            app.HardwareApp = hardware(app);
        end

        % Button pushed function: newexperimentButton
        function newexperimentButtonPushed(app, event)
            global DAQPARS
            
            eNo = app.experimentNumberEditField.Value;
            app.experimentNumberEditField.Value = eNo + 1;
            app.trialNumberEditField.Value = 1;
            DAQPARS.experimentNo = eNo+1;
            DAQPARS.trialNo = 1;
            makefilename
            
        end

        % Value changed function: gainDropDown_1, gainDropDown_2, 
        % gainDropDown_3, gainDropDown_4, gainDropDown_5, gainDropDown_6, 
        % gainDropDown_7, gainDropDown_8, holdingEditField_1, 
        % holdingEditField_2, holdingEditField_3, holdingEditField_4, 
        % holdingEditField_5, holdingEditField_6, holdingEditField_7, 
        % holdingEditField_8, sampleRateDropDown, statusDropDown_1, 
        % statusDropDown_2, statusDropDown_3, statusDropDown_4, 
        % statusDropDown_5, statusDropDown_6, statusDropDown_7, 
        % statusDropDown_8
        function statusDropDown_1ValueChanged(app, event)

            updateparameters(app)

        end

        % Button pushed function: loadparametersButton
        function loadparametersButtonPushed(app, event)
            global DAQPARS
            [fileName,filePath] = uigetfile([app.CurrentParametersFolder,'*.mat']);
            if fileName==0, return, end
            app.CurrentParametersFolder = filePath;
            load([filePath,fileName]); %#ok<LOAD> 
            app.sampleRateDropDown.Value = num2str(sampleRate);
            nChannels = 8;
            for ii = 1:nChannels
                s = ['statusDropDown_',num2str(ii)];
                g = ['gainDropDown_',num2str(ii)];
                h = ['holdingEditField_',num2str(ii)];
                if ii<5 % only the first four channels have an enable switch
                    eStr = ['enableSwitch_',num2str(ii)];
                    if strcmp(app.(eStr).Value,'disable') && ~strcmp(status{ii},'off') %#ok<*USENS>
                        app.(eStr).Value = 'enable';
                        app.(s).Enable = true;
                    end
                end
                app.(s).Value = status{ii};
                app.(g).Value = num2str(gain(ii));
                app.(h).Value = holding(ii);
            end
            channelStatusOld = DAQPARS.channelStatus;
            DAQPARS.sampleRate = sampleRate;
            DAQPARS.channelStatus = status;
            DAQPARS.channelGain = gain;
            DAQPARS.channelHolding = holding;            
%             app.LCSMSpatchclampUIFigure.Visible = 'off';
%             app.LCSMSpatchclampUIFigure.Visible = 'on';
            writemulticlamp(app)
            updateBoard = updatenidaqboard(channelStatusOld,DAQPARS.channelStatus);
            if updateBoard
                DAQPARS.daqObj = nidaqboard;
            end

        end

        % Button pushed function: saveparametersButton
        function saveparametersButtonPushed(app, event)
            global DAQPARS %#ok<*GVMIS> 
            
            params.sampleRate = DAQPARS.sampleRate;
            params.status = DAQPARS.channelStatus;
            params.gain = DAQPARS.channelGain;
            params.holding = DAQPARS.channelHolding;
            [fileName,filePath] = ...
                uiputfile([app.CurrentParametersFolder,'*.mat'],'Enter file name to save parameters.');
            if fileName
                save([filePath,fileName],'-struct','params')
                app.CurrentParametersFolder = filePath;
            end
            app.LCSMSpatchclampUIFigure.Visible = 'off';
            app.LCSMSpatchclampUIFigure.Visible = 'on';
           
        end

        % Button pushed function: readmulticlampButton
        function readmulticlampButtonPushed(app, event)
            readmulticlamp(app)
        end

        % Button pushed function: writemulticlampButton
        function writemulticlampButtonPushed(app, event)
            writemulticlamp(app)            
        end

        % Button pushed function: updateoutputsButton
        function updateoutputsButtonPushed(app, event)
            try
                updateoutputs(app)
            catch
                warndlg('Error in output values. Please check.', 'Warning')
            end
        end

        % Cell selection callback: UIOutputChannels
        function UIOutputChannelsCellSelection(app, event)
            indices = event.Indices;
            buttonValue = app.UIOutputChannels.Data(indices(1),indices(2));
            if buttonValue
                app.UIOutputChannels.Data(indices(1),indices(2)) = false;
            else
                app.UIOutputChannels.Data(indices(1),indices(2)) = true;
            end
            
        end

        % Button pushed function: displayoutputsButton
        function displayoutputsButtonPushed(app, event)
            plotoutputs;
        end

        % Button pushed function: saveoutputsButton
        function saveoutputsButtonPushed(app, event)
            outputs.period = app.periodEditField.Value;
            outputs.repetitions = app.repetitionsEditField.Value;
            outputs.duration = app.durationEditField.Value;
            outputs.shuffle = app.shuffleCheckBox.Value;
            outputs.channels = app.UIOutputChannels.Data;
            outputs.data = app.UIOutputData.Data;
            defaultName= [app.CurrentOutputsFolder,app.protocolEditField.Value,'.mat'];
            [fileName,filePath] = ...
                uiputfile([app.CurrentOutputsFolder,'*.mat'],'Enter file name to save output values.',defaultName);
            if fileName
                save([filePath,fileName],'outputs')
                app.CurrentOutputsFolder = filePath;
                foo = strfind(fileName,'.');
                foo = foo(end);
                app.protocolEditField.Value = fileName(1:foo-1);
            end
        end

        % Button pushed function: loadoutputsButton
        function loadoutputsButtonPushed(app, event)
            [fileName,filePath] = uigetfile([app.CurrentOutputsFolder,'*.mat']);
            if ~fileName
                return
            end
            app.CurrentOutputsFolder = filePath;
            load([filePath,fileName]); %#ok<LOAD>
            app.periodEditField.Value = outputs.period;
            app.repetitionsEditField.Value = outputs.repetitions;
            app.durationEditField.Value = outputs.duration;
            app.shuffleCheckBox.Value = outputs.shuffle;
            app.UIOutputChannels.Data = outputs.channels;
            app.UIOutputData.Data = outputs.data;
            updateoutputs(app);
            foo = strfind(fileName,'.');
            foo = foo(end);
            app.protocolEditField.Value = fileName(1:foo-1);

        end

        % Button pushed function: autoscaleButton, autoscaleGraph1Button, 
        % autoscaleGraph2Button, autoscaleStabilityButton
        function autoscaleButtonPushed(app, event)
            tag = event.Source.Tag;
            switch tag
                case 'autoscale_uiinputaxes1'
                    ax = app.UIInputAxes1;
                case 'autoscale_uiinputaxes2'
                    ax = app.UIInputAxes2;
                case 'outputwaveforms'
                    ax = app.UIOutputAxes;
                case 'stabilitywaveforms'
                    ax(1) = app.UIAxesInputResistance;
                    ax(2) = app.UIAxesRestingPotential;
                    ax(3) = app.UIAxesSeriesResistance;
                otherwise
                    return
            end
            for ii = 1:numel(ax)                
                axis(ax(ii),'auto')
            end
        end

        % Button pushed function: startButton
        function startButtonPushed(app, event)
            if app.TestPulse==true      % turn off test pulse if start button pushed
                app.testButtonPushed;
            end
            app.Recording = ~app.Recording;  % toggle true and false
            if app.Recording
                try
                    updateoutputs(app)
                catch
                    warndlg('Error in output values. Please check.', 'Warning')
                end
            end
            startrecording;
            
        end

        % Button pushed function: testButton
        function testButtonPushed(app, event)
            
            if app.Recording
                disp('Recording in progress.')
                disp('Stop recording before starting test pulse.')
                return
            end                
            
            app.TestPulse = ~app.TestPulse;  % toggle true and false
            if app.TestPulse
                app.testButton.Text = 'stop';
            else
                app.testButton.Text = 'test';
            end
            drawnow limitrate
            testpulse(app);
                
        end

        % Value changed function: beginningStabilityEditField, 
        % endStabilityEditField
        function beginningStabilityEditFieldValueChanged(app, event)
            valueBeginning = app.beginningStabilityEditField.Value;
            valueEnd = app.endStabilityEditField.Value;
            if valueEnd <= valueBeginning
                valueEnd = valueBeginning + 1;
            end
            app.endStabilityEditField.Value = valueEnd;
        end

        % Button pushed function: clearStabilityButton
        function clearStabilityButtonPushed(app, event)
            global analysisCounter
            
            analysisCounter = [];
            cla(app.UIAxesInputResistance)
            cla(app.UIAxesRestingPotential)
            cla(app.UIAxesSeriesResistance)
            
        end

        % Button pushed function: channel1holdOnButton
        function channel1holdOnButtonPushed(app, event)
            turnonholding(app,1)
        end

        % Button pushed function: channel2holdOnButton
        function channel2holdOnButtonPushed(app, event)
            turnonholding(app,2)
        end

        % Button pushed function: channel3holdOnButton
        function channel3holdOnButtonPushed(app, event)
            turnonholding(app,3)
        end

        % Button pushed function: channel4holdOnButton
        function channel4holdOnButtonPushed(app, event)
            turnonholding(app,4)
        end

        % Button pushed function: zeroButton
        function zeroButtonPushed(app, event)
            
            result = zerotestpulse(app);
            if ~result
                disp('Error. Channel(s) not zeroed.')
            elseif app.TestPulse
                app.TestPulse = false;
                testButtonPushed(app)
            end
       
        end

        % Close request function: LCSMSpatchclampUIFigure
        function LCSMSpatchclampUIFigureCloseRequest(app, event)
            global DAQPARS
            
            DAQPARS = [];
            warning on
            daqreset
            delete(app)
            
        end

        % Value changed function: enableSwitch_1, enableSwitch_2, 
        % enableSwitch_3, enableSwitch_4
        function enableSwitch_1ValueChanged(app, event)
            tag = event.Source.Tag;
            eStr = ['enableSwitch_',tag(end)];
            sStr = ['statusDropDown_',tag(end)];
            switch app.(eStr).Value
                case 'enable'
                    app.(sStr).Enable = true;
                case 'disable'
                    app.(sStr).Value = 'off';
                    app.(sStr).Enable = false;
            end
            updateparameters(app)
        end

        % Button pushed function: resetButton
        function resetButtonPushed(app, event)
            global DAQPARS stopBackground
        
            delete(timerfindall)
            daqreset
            cla(app.UIInputAxes1)
            cla(app.UIInputAxes2)
            cla(app.UIOutputAxes)
            app.startButton.Text = 'start';
            app.Recording = false;
            app.TestPulse = false;   
            app.testButton.Text = 'test';
            stopBackground = [];
            app.saveDropDown.Value = 'save data';
            app.saveDropDown.BackgroundColor = [0.96, 0.96, 0.96];
            updateparameters(app)
            updateoutputs(app)
            DAQPARS.daqObj = nidaqboard;
            out = zeros(1,numel(DAQPARS.outputChannels));
            write(DAQPARS.daqObj,out)

        end

        % Value changed function: experimentNumberEditField
        function experimentNumberEditFieldValueChanged(app, event)
            global DAQPARS
            
            value = app.experimentNumberEditField.Value;
            foo = dir([DAQPARS.saveDirectory,'experiment',num2str(value,'%03i'),'*']);
            if ~isempty(foo)
                warndlg('Data with that experiment number already exists','Warning');
                app.experimentNumberEditField.Value = DAQPARS.experimentNo;
                return
            else
                DAQPARS.experimentNo = value;
                DAQPARS.trialNo = 1;
                app.trialNumberEditField.Value = 1;
                makefilename
            end


        end

        % Button pushed function: sequenceButton
        function sequenceButtonPushed(app, event)
            protocolsequence(app)
        end

        % Value changed function: descriptorEditField
        function descriptorEditFieldValueChanged(app, event)
            value = app.descriptorEditField.Value;
            foo = strfind(value,'/');
            value(foo) = [];
            boo = strfind(value,'\');
            value(boo) = [];
            app.descriptorEditField.Value = value;
            if ~isempty(foo) || ~isempty(boo)
                warndlg('File names cannot contain slashes')
            end
        end

        % Button pushed function: savelastpreviewButton
        function savelastpreviewButtonPushed(app, event)
            global DAQPARS

            try
                if isempty(DAQPARS.preview.tempName)
                    disp('No previewed data to save')
                    return
                end
                movefile(DAQPARS.preview.tempName,DAQPARS.preview.fName1)
                newexperiment('nextrial')
                app.saveDropDown.Value = 'save data';
                app.saveDropDown.BackgroundColor = [0.96, 0.96, 0.96];
                DAQPARS.preview.tempName = [];
            catch
                disp('Was not able to save previewed data')
            end


        end

        % Value changed function: saveDropDown
        function saveDropDownValueChanged(app, event)
            value = app.saveDropDown.Value;
            if strcmp(value,'preview')
                app.saveDropDown.BackgroundColor = [0.96, 0.5, 0.5];
            else
                app.saveDropDown.BackgroundColor = [0.96, 0.96, 0.96];
            end
        end

        % Button pushed function: capcompButton_1, capcompButton_2, 
        % capcompButton_3, capcompButton_4
        function capcompButton_1Pushed(app, event)
            global DAQPARS
            
            tag = event.Source.Tag;
            channelNo = str2double(tag(end));
            if strcmp(DAQPARS.channelStatus(channelNo),'voltage clamp')
                testPulseOn = app.TestPulse;
                if testPulseOn==true  % turn off test pulse momentarily
                    pushTestButton(app);
                end
                amplifierOkay = amplifiercommands(channelNo,'cap');
                if amplifierOkay
                    disp(['Fast & slow capacitance compensation executed on channel ',num2str(channelNo)])
                else
                    disp('Error: capacitance compensation not executed')
                end
                if testPulseOn==true        % turn test pulse on again
                    pushTestButton(app);
                end
            else
                disp('Automatic capacitance compensation only available for voltage clamp')
            end

        end

        % Button pushed function: databrowserButton
        function databrowserButtonPushed(app, event)
            databrowser(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LCSMSpatchclampUIFigure = uifigure('Visible', 'off');
            app.LCSMSpatchclampUIFigure.Name = 'LCSMS patch clamp';
            app.LCSMSpatchclampUIFigure.CloseRequestFcn = createCallbackFcn(app, @LCSMSpatchclampUIFigureCloseRequest, true);
            app.parametersPanel = uipanel(app.LCSMSpatchclampUIFigure);
            app.parametersPanel.Title = 'parameters';
            app.newexperimentButton = uibutton(app.parametersPanel, 'push');
            app.newexperimentButton.ButtonPushedFcn = createCallbackFcn(app, @newexperimentButtonPushed, true);
            app.newexperimentButton.Text = 'new experiment';
            app.loadparametersButton = uibutton(app.parametersPanel, 'push');
            app.loadparametersButton.ButtonPushedFcn = createCallbackFcn(app, @loadparametersButtonPushed, true);
            app.loadparametersButton.Text = 'load parameters';
            app.saveparametersButton = uibutton(app.parametersPanel, 'push');
            app.saveparametersButton.ButtonPushedFcn = createCallbackFcn(app, @saveparametersButtonPushed, true);
            app.saveparametersButton.Text = 'save parameters';
            app.hardwareButton = uibutton(app.parametersPanel, 'push');
            app.hardwareButton.ButtonPushedFcn = createCallbackFcn(app, @hardwareButtonPushed, true);
            app.hardwareButton.Text = 'hardware';
            app.samplerateLabel = uilabel(app.parametersPanel);
            app.samplerateLabel.Text = 'sample rate';
            app.sampleRateDropDown = uidropdown(app.parametersPanel);
            app.sampleRateDropDown.Items = {'1000', '2000', '5000', '10000', '20000', '50000', '100000', '200000', '500000'};
            app.sampleRateDropDown.ValueChangedFcn = createCallbackFcn(app, @statusDropDown_1ValueChanged, true);
            app.sampleRateDropDown.Tag = 'ni_sampleRate';
            app.sampleRateDropDown.Value = '10000';
            app.TabGroup = uitabgroup(app.parametersPanel);
            app.channel1Tab = uitab(app.TabGroup);
            app.channel1Tab.Title = 'channel 1';
            app.statusDropDownLabel = uilabel(app.channel1Tab);
            app.statusDropDownLabel.Text = 'status';
            app.statusDropDown_1 = uidropdown(app.channel1Tab);
            app.statusDropDown_1.Items = {'off', 'current clamp', 'voltage clamp', 'I=0', 'field potential'};
            app.statusDropDown_1.ValueChangedFcn = createCallbackFcn(app, @statusDropDown_1ValueChanged, true);
            app.statusDropDown_1.Tag = 'channelStatus1';
            app.statusDropDown_1.Value = 'off';
            app.gainDropDownLabel = uilabel(app.channel1Tab);
            app.gainDropDownLabel.Text = 'gain';
            app.gainDropDown_1 = uidropdown(app.channel1Tab);
            app.gainDropDown_1.Items = {'1', '2', '5', '10', '20', '50', '100', '200', '500', '1000', '2000', '5000', '10000'};
            app.gainDropDown_1.ValueChangedFcn = createCallbackFcn(app, @statusDropDown_1ValueChanged, true);
            app.gainDropDown_1.Value = '1';
            app.holdingEditFieldLabel = uilabel(app.channel1Tab);
            app.holdingEditFieldLabel.Text = 'holding';
            app.holdingEditField_1 = uieditfield(app.channel1Tab, 'numeric');
            app.holdingEditField_1.ValueChangedFcn = createCallbackFcn(app, @statusDropDown_1ValueChanged, true);
            app.enableSwitch_1 = uiswitch(app.channel1Tab, 'slider');
            app.enableSwitch_1.Items = {'disable', 'enable'};
            app.enableSwitch_1.ValueChangedFcn = createCallbackFcn(app, @enableSwitch_1ValueChanged, true);
            app.enableSwitch_1.Tag = 'enable1';
            app.enableSwitch_1.Value = 'enable';
            app.channel2Tab = uitab(app.TabGroup);
            app.channel2Tab.Title = 'channel 2';
            app.statusDropDown_2Label = uilabel(app.channel2Tab);
            app.statusDropDown_2Label.Text = 'status';
            app.statusDropDown_2 = uidropdown(app.channel2Tab);
            app.statusDropDown_2.Items = {'off', 'current clamp', 'voltage clamp', 'I=0', 'field potential'};
            app.statusDropDown_2.ValueChangedFcn = createCallbackFcn(app, @statusDropDown_1ValueChanged, true);
            app.statusDropDown_2.Tag = 'channelStatus2';
            app.statusDropDown_2.Value = 'off';
            app.gainDropDown_2Label = uilabel(app.channel2Tab);
            app.gainDropDown_2Label.Text = 'gain';
            app.gainDropDown_2 = uidropdown(app.channel2Tab);
            app.gainDropDown_2.Items = {'1', '2', '5', '10', '20', '50', '100', '200', '500', '1000', '2000', '5000', '10000'};
            app.gainDropDown_2.ValueChangedFcn = createCallbackFcn(app, @statusDropDown_1ValueChanged, true);
            app.gainDropDown_2.Value = '1';
            app.holdingEditField_2Label = uilabel(app.channel2Tab);
            app.holdingEditField_2Label.Text = 'holding';
            app.holdingEditField_2 = uieditfield(app.channel2Tab, 'numeric');
            app.holdingEditField_2.ValueChangedFcn = createCallbackFcn(app, @statusDropDown_1ValueChanged, true);
            app.enableSwitch_2 = uiswitch(app.channel2Tab, 'slider');
            app.enableSwitch_2.Items = {'disable', 'enable'};
            app.enableSwitch_2.ValueChangedFcn = createCallbackFcn(app, @enableSwitch_1ValueChanged, true);
            app.enableSwitch_2.Tag = 'enable2';
            app.enableSwitch_2.Value = 'disable';
            app.channel3Tab = uitab(app.TabGroup);
            app.channel3Tab.Title = 'channel 3';
            app.statusDropDown_3Label = uilabel(app.channel3Tab);
            app.statusDropDown_3Label.Text = 'status';
            app.statusDropDown_3 = uidropdown(app.channel3Tab);
            app.statusDropDown_3.Items = {'off', 'current clamp', 'voltage clamp', 'I=0', 'field potential'};
            app.statusDropDown_3.ValueChangedFcn = createCallbackFcn(app, @statusDropDown_1ValueChanged, true);
            app.statusDropDown_3.Tag = 'channelStatus3';
            app.statusDropDown_3.Value = 'off';
            app.gainDropDown_3Label = uilabel(app.channel3Tab);
            app.gainDropDown_3Label.Text = 'gain';
            app.gainDropDown_3 = uidropdown(app.channel3Tab);
            app.gainDropDown_3.Items = {'1', '2', '5', '10', '20', '50', '100', '200', '500', '1000', '2000', '5000', '10000'};
            app.gainDropDown_3.ValueChangedFcn = createCallbackFcn(app, @statusDropDown_1ValueChanged, true);
            app.gainDropDown_3.Value = '1';
            app.holdingEditField_3Label = uilabel(app.channel3Tab);
            app.holdingEditField_3Label.Text = 'holding';
            app.holdingEditField_3 = uieditfield(app.channel3Tab, 'numeric');
            app.holdingEditField_3.ValueChangedFcn = createCallbackFcn(app, @statusDropDown_1ValueChanged, true);
            app.enableSwitch_3 = uiswitch(app.channel3Tab, 'slider');
            app.enableSwitch_3.Items = {'disable', 'enable'};
            app.enableSwitch_3.ValueChangedFcn = createCallbackFcn(app, @enableSwitch_1ValueChanged, true);
            app.enableSwitch_3.Tag = 'enable3';
            app.enableSwitch_3.Value = 'disable';
            app.channel4Tab = uitab(app.TabGroup);
            app.channel4Tab.Title = 'channel 4';
            app.statusDropDown_4Label = uilabel(app.channel4Tab);
            app.statusDropDown_4Label.Text = 'status';
            app.statusDropDown_4 = uidropdown(app.channel4Tab);
            app.statusDropDown_4.Items = {'off', 'current clamp', 'voltage clamp', 'I=0', 'field potential'};
            app.statusDropDown_4.ValueChangedFcn = createCallbackFcn(app, @statusDropDown_1ValueChanged, true);
            app.statusDropDown_4.Tag = 'channelStatus4';
            app.statusDropDown_4.Value = 'off';
            app.gainDropDown_4Label = uilabel(app.channel4Tab);
            app.gainDropDown_4Label.Text = 'gain';
            app.gainDropDown_4 = uidropdown(app.channel4Tab);
            app.gainDropDown_4.Items = {'1', '2', '5', '10', '20', '50', '100', '200', '500', '1000', '2000', '5000', '10000'};
            app.gainDropDown_4.ValueChangedFcn = createCallbackFcn(app, @statusDropDown_1ValueChanged, true);
            app.gainDropDown_4.Value = '1';
            app.holdingEditField_4Label = uilabel(app.channel4Tab);
            app.holdingEditField_4Label.Text = 'holding';
            app.holdingEditField_4 = uieditfield(app.channel4Tab, 'numeric');
            app.holdingEditField_4.ValueChangedFcn = createCallbackFcn(app, @statusDropDown_1ValueChanged, true);
            app.enableSwitch_4 = uiswitch(app.channel4Tab, 'slider');
            app.enableSwitch_4.Items = {'disable', 'enable'};
            app.enableSwitch_4.ValueChangedFcn = createCallbackFcn(app, @enableSwitch_1ValueChanged, true);
            app.enableSwitch_4.Tag = 'enable4';
            app.enableSwitch_4.Value = 'disable';
            app.channel5Tab = uitab(app.TabGroup);
            app.channel5Tab.Title = 'channel 5';
            app.statusDropDown_5Label = uilabel(app.channel5Tab);
            app.statusDropDown_5Label.Text = 'status';
            app.statusDropDown_5 = uidropdown(app.channel5Tab);
            app.statusDropDown_5.Items = {'off', 'field potential', 'pressure'};
            app.statusDropDown_5.ValueChangedFcn = createCallbackFcn(app, @statusDropDown_1ValueChanged, true);
            app.statusDropDown_5.Tag = 'channelStatus5';
            app.statusDropDown_5.Value = 'off';
            app.gainDropDown_5Label = uilabel(app.channel5Tab);
            app.gainDropDown_5Label.Text = 'gain';
            app.gainDropDown_5 = uidropdown(app.channel5Tab);
            app.gainDropDown_5.Items = {'1', '2', '5', '10', '20', '50', '100', '200', '500', '1000', '2000', '5000', '10000'};
            app.gainDropDown_5.ValueChangedFcn = createCallbackFcn(app, @statusDropDown_1ValueChanged, true);
            app.gainDropDown_5.Value = '1';
            app.holdingEditField_5Label = uilabel(app.channel5Tab);
            app.holdingEditField_5Label.Text = 'holding';
            app.holdingEditField_5 = uieditfield(app.channel5Tab, 'numeric');
            app.holdingEditField_5.ValueChangedFcn = createCallbackFcn(app, @statusDropDown_1ValueChanged, true);
            app.channel6Tab = uitab(app.TabGroup);
            app.channel6Tab.Title = 'channel 6';
            app.statusDropDown_6Label = uilabel(app.channel6Tab);
            app.statusDropDown_6Label.Text = 'status';
            app.statusDropDown_6 = uidropdown(app.channel6Tab);
            app.statusDropDown_6.Items = {'off', 'field potential', 'pressure'};
            app.statusDropDown_6.ValueChangedFcn = createCallbackFcn(app, @statusDropDown_1ValueChanged, true);
            app.statusDropDown_6.Tag = 'channelStatus6';
            app.statusDropDown_6.Value = 'off';
            app.gainDropDown_6Label = uilabel(app.channel6Tab);
            app.gainDropDown_6Label.Text = 'gain';
            app.gainDropDown_6 = uidropdown(app.channel6Tab);
            app.gainDropDown_6.Items = {'1', '2', '5', '10', '20', '50', '100', '200', '500', '1000', '2000', '5000', '10000'};
            app.gainDropDown_6.ValueChangedFcn = createCallbackFcn(app, @statusDropDown_1ValueChanged, true);
            app.gainDropDown_6.Value = '1';
            app.holdingEditField_6Label = uilabel(app.channel6Tab);
            app.holdingEditField_6Label.Text = 'holding';
            app.holdingEditField_6 = uieditfield(app.channel6Tab, 'numeric');
            app.holdingEditField_6.ValueChangedFcn = createCallbackFcn(app, @statusDropDown_1ValueChanged, true);
            app.channel7Tab = uitab(app.TabGroup);
            app.channel7Tab.Title = 'channel 7';
            app.statusDropDown_7Label = uilabel(app.channel7Tab);
            app.statusDropDown_7Label.Text = 'status';
            app.statusDropDown_7 = uidropdown(app.channel7Tab);
            app.statusDropDown_7.Items = {'off', 'field potential', 'pressure'};
            app.statusDropDown_7.ValueChangedFcn = createCallbackFcn(app, @statusDropDown_1ValueChanged, true);
            app.statusDropDown_7.Tag = 'channelStatus7';
            app.statusDropDown_7.Value = 'off';
            app.gainDropDown_7Label = uilabel(app.channel7Tab);
            app.gainDropDown_7Label.Text = 'gain';
            app.gainDropDown_7 = uidropdown(app.channel7Tab);
            app.gainDropDown_7.Items = {'1', '2', '5', '10', '20', '50', '100', '200', '500', '1000', '2000', '5000', '10000'};
            app.gainDropDown_7.ValueChangedFcn = createCallbackFcn(app, @statusDropDown_1ValueChanged, true);
            app.gainDropDown_7.Value = '1';
            app.holdingEditField_7Label = uilabel(app.channel7Tab);
            app.holdingEditField_7Label.Text = 'holding';
            app.holdingEditField_7 = uieditfield(app.channel7Tab, 'numeric');
            app.holdingEditField_7.ValueChangedFcn = createCallbackFcn(app, @statusDropDown_1ValueChanged, true);
            app.channel8Tab = uitab(app.TabGroup);
            app.channel8Tab.Title = 'channel 8';
            app.statusDropDown_8Label = uilabel(app.channel8Tab);
            app.statusDropDown_8Label.Text = 'status';
            app.statusDropDown_8 = uidropdown(app.channel8Tab);
            app.statusDropDown_8.Items = {'off', 'field potential', 'pressure'};
            app.statusDropDown_8.ValueChangedFcn = createCallbackFcn(app, @statusDropDown_1ValueChanged, true);
            app.statusDropDown_8.Tag = 'channelStatus8';
            app.statusDropDown_8.Value = 'off';
            app.gainDropDown_8Label = uilabel(app.channel8Tab);
            app.gainDropDown_8Label.Text = 'gain';
            app.gainDropDown_8 = uidropdown(app.channel8Tab);
            app.gainDropDown_8.Items = {'1', '2', '5', '10', '20', '50', '100', '200', '500', '1000', '2000', '5000', '10000'};
            app.gainDropDown_8.ValueChangedFcn = createCallbackFcn(app, @statusDropDown_1ValueChanged, true);
            app.gainDropDown_8.Value = '1';
            app.holdingEditField_8Label = uilabel(app.channel8Tab);
            app.holdingEditField_8Label.Text = 'holding';
            app.holdingEditField_8 = uieditfield(app.channel8Tab, 'numeric');
            app.holdingEditField_8.ValueChangedFcn = createCallbackFcn(app, @statusDropDown_1ValueChanged, true);
            app.writemulticlampButton = uibutton(app.parametersPanel, 'push');
            app.writemulticlampButton.ButtonPushedFcn = createCallbackFcn(app, @writemulticlampButtonPushed, true);
            app.writemulticlampButton.Text = 'write multiclamp';
            app.readmulticlampButton = uibutton(app.parametersPanel, 'push');
            app.readmulticlampButton.ButtonPushedFcn = createCallbackFcn(app, @readmulticlampButtonPushed, true);
            app.readmulticlampButton.Text = 'read multiclamp';
            app.experimentnumberEditFieldLabel = uilabel(app.parametersPanel);
            app.experimentnumberEditFieldLabel.Text = 'experiment number';
            app.experimentNumberEditField = uieditfield(app.parametersPanel, 'numeric');
            app.experimentNumberEditField.Limits = [1 Inf];
            app.experimentNumberEditField.ValueDisplayFormat = '%.0f';
            app.experimentNumberEditField.ValueChangedFcn = createCallbackFcn(app, @experimentNumberEditFieldValueChanged, true);
            app.experimentNumberEditField.Value = 1;
            app.trialnumberLabel = uilabel(app.parametersPanel);
            app.trialnumberLabel.Text = 'trial number';
            app.trialNumberEditField = uieditfield(app.parametersPanel, 'numeric');
            app.trialNumberEditField.Limits = [1 Inf];
            app.trialNumberEditField.ValueDisplayFormat = '%.0f';
            app.trialNumberEditField.Value = 1;
            app.TabGroup2 = uitabgroup(app.LCSMSpatchclampUIFigure);
            app.outputwaveformsTab = uitab(app.TabGroup2);
            app.outputwaveformsTab.Title = 'output waveforms';
            app.UIOutputAxes = uiaxes(app.outputwaveformsTab);
            title(app.UIOutputAxes, 'waveforms')
            xlabel(app.UIOutputAxes, 'time (msec)')
            ylabel(app.UIOutputAxes, {'potential (mV), current (pA)'; 'digital (V), pressure (psi)'; ''; ' '})
            app.UIOutputAxes.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.channelstodisplayDropDownLabel = uilabel(app.outputwaveformsTab);
            app.channelstodisplayDropDownLabel.Text = 'channels to display';
            app.channelstodisplayDropDown = uidropdown(app.outputwaveformsTab);
            app.channelstodisplayDropDown.Items = {'all active', 'analog 1', 'analog 2', 'analog 3', 'analog 4', 'digital 5', 'digital 6', 'digital 7', 'digital 8'};
            app.channelstodisplayDropDown.Value = 'all active';
            app.autoscaleButton = uibutton(app.outputwaveformsTab, 'push');
            app.autoscaleButton.ButtonPushedFcn = createCallbackFcn(app, @autoscaleButtonPushed, true);
            app.autoscaleButton.Tag = 'outputwaveforms';
            app.autoscaleButton.Text = 'autoscale';
            app.displayoutputsButton = uibutton(app.outputwaveformsTab, 'push');
            app.displayoutputsButton.ButtonPushedFcn = createCallbackFcn(app, @displayoutputsButtonPushed, true);
            app.displayoutputsButton.Text = 'display outputs';
            app.stabilityTab = uitab(app.TabGroup2);
            app.stabilityTab.Title = 'stability';
            app.UIAxesInputResistance = uiaxes(app.stabilityTab);
            title(app.UIAxesInputResistance, 'input resistance (M\Omega)')
            app.UIAxesRestingPotential = uiaxes(app.stabilityTab);
            title(app.UIAxesRestingPotential, 'resting potential (mV) or holding current (pA)')
            app.UIAxesSeriesResistance = uiaxes(app.stabilityTab);
            title(app.UIAxesSeriesResistance, 'series resistance (M\Omega)')
            xlabel(app.UIAxesSeriesResistance, 'time (sec)')
            app.clearStabilityButton = uibutton(app.stabilityTab, 'push');
            app.clearStabilityButton.ButtonPushedFcn = createCallbackFcn(app, @clearStabilityButtonPushed, true);
            app.clearStabilityButton.Text = 'clear';
            app.beginningStabilityEditField = uieditfield(app.stabilityTab, 'numeric');
            app.beginningStabilityEditField.Limits = [0 2000];
            app.beginningStabilityEditField.RoundFractionalValues = 'on';
            app.beginningStabilityEditField.ValueDisplayFormat = '%.0f';
            app.beginningStabilityEditField.ValueChangedFcn = createCallbackFcn(app, @beginningStabilityEditFieldValueChanged, true);
            app.beginningStabilityEditField.Value = 25;
            app.beginningLabel = uilabel(app.stabilityTab);
            app.beginningLabel.Text = 'beginning';
            app.endStabilityEditField = uieditfield(app.stabilityTab, 'numeric');
            app.endStabilityEditField.Limits = [0 2000];
            app.endStabilityEditField.RoundFractionalValues = 'on';
            app.endStabilityEditField.ValueDisplayFormat = '%.0f';
            app.endStabilityEditField.ValueChangedFcn = createCallbackFcn(app, @beginningStabilityEditFieldValueChanged, true);
            app.endStabilityEditField.Value = 125;
            app.endLabel = uilabel(app.stabilityTab);
            app.endLabel.Text = 'end';
            app.UIStability = uitable(app.stabilityTab);
            app.UIStability.ColumnName = {'1'; '2'; '3'; '4'};
            app.UIStability.RowName = {};
            app.UIStability.ColumnEditable = true;
            app.Label_21 = uilabel(app.stabilityTab);
            app.Label_21.Text = '';
            app.Label_22 = uilabel(app.stabilityTab);
            app.Label_22.Text = '';
            app.Label_23 = uilabel(app.stabilityTab);
            app.Label_23.Text = '';
            app.Label_24 = uilabel(app.stabilityTab);
            app.Label_24.Text = '';
            app.autoscaleStabilityButton = uibutton(app.stabilityTab, 'push');
            app.autoscaleStabilityButton.ButtonPushedFcn = createCallbackFcn(app, @autoscaleButtonPushed, true);
            app.autoscaleStabilityButton.Tag = 'stabilitywaveforms';
            app.autoscaleStabilityButton.Text = 'autoscale';
            app.analysisTab = uitab(app.TabGroup2);
            app.analysisTab.Title = 'analysis';
            app.UIOutputAxes_2 = uiaxes(app.analysisTab);
            title(app.UIOutputAxes_2, 'analysis data')
            xlabel(app.UIOutputAxes_2, 'time (msec)')
            app.UIOutputAxes_2.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.analysisChannelDropDown = uidropdown(app.analysisTab);
            app.analysisChannelDropDown.Items = {'channel 1', 'channel 2', 'channel 3', 'channel 4'};
            app.analysisChannelDropDown.Value = 'channel 1';
            app.clearStabilityButton_2 = uibutton(app.analysisTab, 'push');
            app.clearStabilityButton_2.Text = 'clear';
            app.outputsPanel = uipanel(app.LCSMSpatchclampUIFigure);
            app.outputsPanel.Title = 'outputs';
            app.UIOutputData = uitable(app.outputsPanel);
            app.UIOutputData.ColumnName = {'Time'; 'Duration'; 'Amplitude'};
            app.UIOutputData.RowName = {};
            app.UIOutputData.ColumnEditable = true;
            app.UIOutputChannels = uitable(app.outputsPanel);
            app.UIOutputChannels.ColumnName = {'1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'};
            app.UIOutputChannels.RowName = {};
            app.UIOutputChannels.ColumnEditable = true;
            app.UIOutputChannels.CellSelectionCallback = createCallbackFcn(app, @UIOutputChannelsCellSelection, true);
            app.loadoutputsButton = uibutton(app.outputsPanel, 'push');
            app.loadoutputsButton.ButtonPushedFcn = createCallbackFcn(app, @loadoutputsButtonPushed, true);
            app.loadoutputsButton.Text = 'load outputs';
            app.saveoutputsButton = uibutton(app.outputsPanel, 'push');
            app.saveoutputsButton.ButtonPushedFcn = createCallbackFcn(app, @saveoutputsButtonPushed, true);
            app.saveoutputsButton.Text = 'save outputs';
            app.repetitionsEditFieldLabel = uilabel(app.outputsPanel);
            app.repetitionsEditFieldLabel.Text = 'repetitions';
            app.repetitionsEditField = uieditfield(app.outputsPanel, 'numeric');
            app.repetitionsEditField.Limits = [1 Inf];
            app.repetitionsEditField.ValueDisplayFormat = '%.0f';
            app.repetitionsEditField.Value = 1;
            app.periodEditFieldLabel = uilabel(app.outputsPanel);
            app.periodEditFieldLabel.Text = 'period';
            app.periodEditField = uieditfield(app.outputsPanel, 'numeric');
            app.periodEditField.Limits = [0 Inf];
            app.periodEditField.ValueDisplayFormat = '%.0f';
            app.periodEditField.Value = 1137;
            app.durationEditFieldLabel = uilabel(app.outputsPanel);
            app.durationEditFieldLabel.Text = 'duration';
            app.durationEditField = uieditfield(app.outputsPanel, 'numeric');
            app.durationEditField.Limits = [0 Inf];
            app.durationEditField.ValueDisplayFormat = '%.0f';
            app.durationEditField.Value = 1000;
            app.shuffleCheckBox = uicheckbox(app.outputsPanel);
            app.shuffleCheckBox.Text = 'shuffle';
            app.Label = uilabel(app.outputsPanel);
            app.Label.Text = '';
            app.Label_2 = uilabel(app.outputsPanel);
            app.Label_2.Text = '';
            app.Label_3 = uilabel(app.outputsPanel);
            app.Label_3.Text = '';
            app.Label_4 = uilabel(app.outputsPanel);
            app.Label_4.Text = '';
            app.Label_17 = uilabel(app.outputsPanel);
            app.Label_17.Text = '';
            app.Label_18 = uilabel(app.outputsPanel);
            app.Label_18.Text = '';
            app.Label_19 = uilabel(app.outputsPanel);
            app.Label_19.Text = '';
            app.Label_20 = uilabel(app.outputsPanel);
            app.Label_20.Text = '';
            app.digitalLabel = uilabel(app.outputsPanel);
            app.digitalLabel.Text = 'digital';
            app.updateoutputsButton = uibutton(app.outputsPanel, 'push');
            app.updateoutputsButton.ButtonPushedFcn = createCallbackFcn(app, @updateoutputsButtonPushed, true);
            app.updateoutputsButton.Text = 'update outputs';
            app.analogLabel = uilabel(app.outputsPanel);
            app.analogLabel.Text = 'analog';
            app.protocolEditFieldLabel = uilabel(app.outputsPanel);
            app.protocolEditFieldLabel.Text = 'protocol';
            app.protocolEditField = uieditfield(app.outputsPanel, 'text');
            app.recordingPanel = uipanel(app.LCSMSpatchclampUIFigure);
            app.recordingPanel.Title = 'recording';
            app.descriptorEditFieldLabel = uilabel(app.recordingPanel);
            app.descriptorEditFieldLabel.Text = 'descriptor';
            app.descriptorEditField = uieditfield(app.recordingPanel, 'text');
            app.descriptorEditField.ValueChangedFcn = createCallbackFcn(app, @descriptorEditFieldValueChanged, true);
            app.notesTextAreaLabel = uilabel(app.recordingPanel);
            app.notesTextAreaLabel.Text = 'notes';
            app.notesTextArea = uitextarea(app.recordingPanel);
            app.mouseidEditFieldLabel = uilabel(app.recordingPanel);
            app.mouseidEditFieldLabel.Text = 'mouse id';
            app.mouseidEditField = uieditfield(app.recordingPanel, 'text');
            app.DOBDatePickerLabel = uilabel(app.recordingPanel);
            app.DOBDatePickerLabel.Text = 'DOB';
            app.DOBDatePicker = uidatepicker(app.recordingPanel);
            app.sexSwitch = uiswitch(app.recordingPanel, 'slider');
            app.sexSwitch.Items = {'female', 'male'};
            app.sexSwitch.Value = 'female';
            app.startButton = uibutton(app.recordingPanel, 'push');
            app.startButton.ButtonPushedFcn = createCallbackFcn(app, @startButtonPushed, true);
            app.startButton.Text = 'start';
            app.savelastpreviewButton = uibutton(app.recordingPanel, 'push');
            app.savelastpreviewButton.ButtonPushedFcn = createCallbackFcn(app, @savelastpreviewButtonPushed, true);
            app.savelastpreviewButton.Text = 'save last preview';
            app.saveDropDown = uidropdown(app.recordingPanel);
            app.saveDropDown.Items = {'save data', 'preview'};
            app.saveDropDown.ValueChangedFcn = createCallbackFcn(app, @saveDropDownValueChanged, true);
            app.saveDropDown.Value = 'save data';
            app.episodicDropDown = uidropdown(app.recordingPanel);
            app.episodicDropDown.Items = {'episodic', 'gap-free'};
            app.episodicDropDown.Value = 'episodic';
            app.analysisPanel = uipanel(app.LCSMSpatchclampUIFigure);
            app.analysisPanel.Title = 'analysis';
            app.stabilityButtonGroup = uibuttongroup(app.analysisPanel);
            app.stabilityButtonGroup.Title = 'stability?';
            app.donotcheckButton = uitogglebutton(app.stabilityButtonGroup);
            app.donotcheckButton.Text = 'do not check';
            app.donotcheckButton.Value = true;
            app.checkButton = uitogglebutton(app.stabilityButtonGroup);
            app.checkButton.Text = 'check';
            app.otheranalysesDropDownLabel = uilabel(app.analysisPanel);
            app.otheranalysesDropDownLabel.Text = 'other analyses';
            app.otheranalysesDropDown = uidropdown(app.analysisPanel);
            app.otheranalysesDropDown.Items = {'none', 'PSC amplitudes', 'PSP slopes'};
            app.otheranalysesDropDown.Value = 'none';
            app.inputsPanel = uipanel(app.LCSMSpatchclampUIFigure);
            app.inputsPanel.Title = 'inputs';
            app.UIInputAxes1 = uiaxes(app.inputsPanel);
            title(app.UIInputAxes1, 'graph 1')
            ylabel(app.UIInputAxes1, 'mV or pA')
            app.UIInputAxes2 = uiaxes(app.inputsPanel);
            title(app.UIInputAxes2, 'graph 2')
            xlabel(app.UIInputAxes2, 'time (msec)')
            ylabel(app.UIInputAxes2, 'mV or pA')
            app.UIPlottingChannels = uitable(app.inputsPanel);
            app.UIPlottingChannels.ColumnName = {'1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'};
            app.UIPlottingChannels.RowName = {};
            app.UIPlottingChannels.ColumnEditable = true;
            app.graph1Label = uilabel(app.inputsPanel);
            app.graph1Label.Text = 'graph 1';
            app.graph2Label = uilabel(app.inputsPanel);
            app.graph2Label.Text = 'graph 2';
            app.Label_5 = uilabel(app.inputsPanel);
            app.Label_5.Text = '';
            app.Label_6 = uilabel(app.inputsPanel);
            app.Label_6.Text = '';
            app.Label_7 = uilabel(app.inputsPanel);
            app.Label_7.Text = '';
            app.Label_8 = uilabel(app.inputsPanel);
            app.Label_8.Text = '';
            app.Label_9 = uilabel(app.inputsPanel);
            app.Label_9.Text = '';
            app.Label_10 = uilabel(app.inputsPanel);
            app.Label_10.Text = '';
            app.Label_11 = uilabel(app.inputsPanel);
            app.Label_11.Text = '';
            app.Label_12 = uilabel(app.inputsPanel);
            app.Label_12.Text = '';
            app.popupSwitchLabel = uilabel(app.inputsPanel);
            app.popupSwitchLabel.Text = 'popup';
            app.popupSwitch_1 = uiswitch(app.inputsPanel, 'slider');
            app.popupSwitch_2Label = uilabel(app.inputsPanel);
            app.popupSwitch_2Label.Text = 'popup';
            app.popupSwitch_2 = uiswitch(app.inputsPanel, 'slider');
            app.autoscaleGraph1Button = uibutton(app.inputsPanel, 'push');
            app.autoscaleGraph1Button.ButtonPushedFcn = createCallbackFcn(app, @autoscaleButtonPushed, true);
            app.autoscaleGraph1Button.Tag = 'autoscale_uiinputaxes1';
            app.autoscaleGraph1Button.Text = 'autoscale';
            app.autoscaleGraph2Button = uibutton(app.inputsPanel, 'push');
            app.autoscaleGraph2Button.ButtonPushedFcn = createCallbackFcn(app, @autoscaleButtonPushed, true);
            app.autoscaleGraph2Button.Tag = 'autoscale_uiinputaxes2';
            app.autoscaleGraph2Button.Text = 'autoscale';
            app.TabGroup3 = uitabgroup(app.LCSMSpatchclampUIFigure);
            app.testpulseTab = uitab(app.TabGroup3);
            app.testpulseTab.Title = 'test pulse';
            app.UITestPulse = uitable(app.testpulseTab);
            app.UITestPulse.ColumnName = {'1'; '2'; '3'; '4'};
            app.UITestPulse.RowName = {};
            app.UITestPulse.ColumnEditable = true;
            app.Label_25 = uilabel(app.testpulseTab);
            app.Label_25.Text = '';
            app.Label_26 = uilabel(app.testpulseTab);
            app.Label_26.Text = '';
            app.Label_27 = uilabel(app.testpulseTab);
            app.Label_27.Text = '';
            app.Label_28 = uilabel(app.testpulseTab);
            app.Label_28.Text = '';
            app.testButton = uibutton(app.testpulseTab, 'push');
            app.testButton.ButtonPushedFcn = createCallbackFcn(app, @testButtonPushed, true);
            app.testButton.Text = 'test';
            app.amplitudeEditFieldLabel = uilabel(app.testpulseTab);
            app.amplitudeEditFieldLabel.Text = 'amplitude';
            app.amplitudeTestEditField = uieditfield(app.testpulseTab, 'numeric');
            app.amplitudeTestEditField.Limits = [-500 500];
            app.amplitudeTestEditField.Value = -10;
            app.durationEditField_2Label = uilabel(app.testpulseTab);
            app.durationEditField_2Label.Text = 'duration';
            app.durationTestEditField = uieditfield(app.testpulseTab, 'numeric');
            app.durationTestEditField.Limits = [10 1000];
            app.durationTestEditField.Value = 50;
            app.zeroButton = uibutton(app.testpulseTab, 'push');
            app.zeroButton.ButtonPushedFcn = createCallbackFcn(app, @zeroButtonPushed, true);
            app.zeroButton.Text = 'zero';
            app.resistanceholdingTab = uitab(app.TabGroup3);
            app.resistanceholdingTab.Title = 'resistance & holding';
            app.channel1holdOnButton = uibutton(app.resistanceholdingTab, 'push');
            app.channel1holdOnButton.ButtonPushedFcn = createCallbackFcn(app, @channel1holdOnButtonPushed, true);
            app.channel1holdOnButton.Text = {'-65 mV'; ''};
            app.channel2holdOnButton = uibutton(app.resistanceholdingTab, 'push');
            app.channel2holdOnButton.ButtonPushedFcn = createCallbackFcn(app, @channel2holdOnButtonPushed, true);
            app.channel2holdOnButton.Text = {'-65 mV'; ''};
            app.channel3holdOnButton = uibutton(app.resistanceholdingTab, 'push');
            app.channel3holdOnButton.ButtonPushedFcn = createCallbackFcn(app, @channel3holdOnButtonPushed, true);
            app.channel3holdOnButton.Text = {'-65 mV'; ''};
            app.channel4holdOnButton = uibutton(app.resistanceholdingTab, 'push');
            app.channel4holdOnButton.ButtonPushedFcn = createCallbackFcn(app, @channel4holdOnButtonPushed, true);
            app.channel4holdOnButton.Text = {'-65 mV'; ''};
            app.channel1Label = uilabel(app.resistanceholdingTab);
            app.channel1Label.Text = 'channel 1';
            app.channel2Label = uilabel(app.resistanceholdingTab);
            app.channel2Label.Text = 'channel 2';
            app.channel3Label = uilabel(app.resistanceholdingTab);
            app.channel3Label.Text = 'channel 3';
            app.channel4Label = uilabel(app.resistanceholdingTab);
            app.channel4Label.Text = 'channel 4';
            app.capcompButton_1 = uibutton(app.resistanceholdingTab, 'push');
            app.capcompButton_1.ButtonPushedFcn = createCallbackFcn(app, @capcompButton_1Pushed, true);
            app.capcompButton_1.Tag = 'capcomp1';
            app.capcompButton_1.Text = 'cap comp';
            app.capcompButton_2 = uibutton(app.resistanceholdingTab, 'push');
            app.capcompButton_2.ButtonPushedFcn = createCallbackFcn(app, @capcompButton_1Pushed, true);
            app.capcompButton_2.Tag = 'capcomp2';
            app.capcompButton_2.Text = 'cap comp';
            app.capcompButton_3 = uibutton(app.resistanceholdingTab, 'push');
            app.capcompButton_3.ButtonPushedFcn = createCallbackFcn(app, @capcompButton_1Pushed, true);
            app.capcompButton_3.Tag = 'capcomp3';
            app.capcompButton_3.Text = 'cap comp';
            app.capcompButton_4 = uibutton(app.resistanceholdingTab, 'push');
            app.capcompButton_4.ButtonPushedFcn = createCallbackFcn(app, @capcompButton_1Pushed, true);
            app.capcompButton_4.Tag = 'capcomp4';
            app.capcompButton_4.Text = 'cap comp';
            app.checkTab = uitab(app.TabGroup3);
            app.checkTab.Title = 'check';
            app.seriesresistanceEditFieldLabel = uilabel(app.checkTab);
            app.seriesresistanceEditFieldLabel.Text = 'series resistance';
            app.seriesresistanceEditField = uieditfield(app.checkTab, 'numeric');
            app.seriesresistanceEditField.ValueDisplayFormat = '%.0f';
            app.inputresistanceEditFieldLabel = uilabel(app.checkTab);
            app.inputresistanceEditFieldLabel.Text = 'input resistance';
            app.inputresistanceEditField = uieditfield(app.checkTab, 'numeric');
            app.inputresistanceEditField.ValueDisplayFormat = '%.0f';
            app.holdingcurrentEditFieldLabel = uilabel(app.checkTab);
            app.holdingcurrentEditFieldLabel.Text = 'holding current';
            app.holdingcurrentEditField = uieditfield(app.checkTab, 'numeric');
            app.holdingcurrentEditField.ValueDisplayFormat = '%.0f';
            app.channelDropDownLabel = uilabel(app.checkTab);
            app.channelDropDownLabel.Text = 'channel';
            app.channelDropDown = uidropdown(app.checkTab);
            app.channelDropDown.Items = {'1', '2', '3', '4'};
            app.channelDropDown.Value = '1';
            app.checkPropertiesButton = uibutton(app.checkTab, 'state');
            app.checkPropertiesButton.Text = 'check';
            app.resetButton = uibutton(app.LCSMSpatchclampUIFigure, 'push');
            app.resetButton.ButtonPushedFcn = createCallbackFcn(app, @resetButtonPushed, true);
            app.resetButton.Text = 'reset';
            app.sequenceButton = uibutton(app.LCSMSpatchclampUIFigure, 'push');
            app.sequenceButton.ButtonPushedFcn = createCallbackFcn(app, @sequenceButtonPushed, true);
            app.sequenceButton.Text = 'sequence';
            app.databrowserButton = uibutton(app.LCSMSpatchclampUIFigure, 'push');
            app.databrowserButton.ButtonPushedFcn = createCallbackFcn(app, @databrowserButtonPushed, true);
            app.databrowserButton.Text = 'data browser';
            % Show the figure after all components are created
            app.LCSMSpatchclampUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LCSMS_patch_clamp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LCSMSpatchclampUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LCSMSpatchclampUIFigure)
        end
    end
end