classdef stimuli < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        analysistracesListBoxLabel  matlab.ui.control.Label
        analysistracesListBox       matlab.ui.control.ListBox
        refreshButton               matlab.ui.control.Button
        channelsButtonGroup         matlab.ui.container.ButtonGroup
        selectedchannelButton       matlab.ui.control.ToggleButton
        allchannelsButton           matlab.ui.control.ToggleButton
        clearButton                 matlab.ui.control.Button
        legendEditField_1           matlab.ui.control.EditField
        legendEditField_2           matlab.ui.control.EditField
        legendEditField_3           matlab.ui.control.EditField
        legendEditField_4           matlab.ui.control.EditField
        legendEditField_5           matlab.ui.control.EditField
        legendEditField_6           matlab.ui.control.EditField
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CallingApp % handle to the LCSMS_analysis app
    end
    
    methods (Access = public)
        
        function [] = pushRefreshListButton(app)
            refreshButtonPushed(app)
        end
        
    end
    
    methods (Access = private)
        
        function [] = clearlegend(app)
            for ii = 1:6
                legendStr = ['legendEditField_',num2str(ii)];
                app.(legendStr).Value = "";
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.CallingApp = mainapp;
            hold(app.UIAxes,'on')
            refreshButtonPushed(app)

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.CallingApp.StimuliApp = [];
            delete(app)
        end

        % Button pushed function: refreshButton
        function refreshButtonPushed(app, event)
            try
                if app.CallingApp.TraceNo
                    items = strings(app.CallingApp.TraceNo,1);
                    for ii = 1:app.CallingApp.TraceNo
                        fStr = ['legendEditField_',num2str(ii)];
                        value0 = app.CallingApp.(fStr).Value;
                        items{ii} = value0;
                    end
                    app.analysistracesListBox.Items = items;
                else
                    app.analysistracesListBox.Items = "";
                end
                analysistracesListBoxValueChanged(app);
            catch
                % do nothing                
            end
            
        end

        % Value changed function: analysistracesListBox
        function analysistracesListBoxValueChanged(app, event)
            value = app.analysistracesListBox.Value;
            items = app.analysistracesListBox.Items;
            [~,valueIdx] = ismember(value,items);
            cla(app.UIAxes)
            clearlegend(app)
            if isempty(value{1})
                return
            end
            for ii = 1:numel(valueIdx)
                traceNo = valueIdx(ii);
                load(app.CallingApp.FileNames{traceNo},'outputData','Pars');
                dt = 1000/Pars.sampleRate;
                channelNo = app.CallingApp.TraceInfo{traceNo}.channel;
                sweeps = app.CallingApp.TraceInfo{traceNo}.sweeps;
                steps = unique(Pars.orderOfSteps(sweeps(1):sweeps(2)));
                traceColor = app.CallingApp.ColorOrder(traceNo,:);
                outputData1 = outputData(:,steps,channelNo==Pars.outputChannels);
                xdata = (1:size(outputData1,1))*dt - dt;
                if app.allchannelsButton.Value
                    outputData2 = outputData(:,steps,:); 
                    for jj = 1:size(outputData2,3)
                        plot(app.UIAxes,xdata,outputData2(:,:,jj),'color',traceColor)
                    end
                elseif ~isempty(outputData1)
                    plot(app.UIAxes,xdata,outputData1,'color',traceColor,'LineWidth',1.5)
                end                        
                legendStr = ['legendEditField_',num2str(ii)]; 
                app.(legendStr).Value = value{ii};
                app.(legendStr).FontColor = traceColor;
            end
            app.UIAxes.XLabel.String = 'Time (msec)';
            yStr = '';
            status = 'off';
            for jj = 1:numel(Pars.channelStatus)
                if ~strcmp(Pars.channelStatus(jj),'off')
                    status = Pars.channelStatus(jj);
                end
            end
            if strcmp(status,'current clamp')
                yStr = 'current (pA)';
            end
            if strcmp(status,'voltage clamp')
                yStr = 'Holding potential (mV)';
            end
            app.UIAxes.YLabel.String = yStr;
            
        end

        % Button pushed function: clearButton
        function clearButtonPushed(app, event)
            cla(app.UIAxes)
            clearlegend(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.analysistracesListBoxLabel = uilabel(app.UIFigure);
            app.analysistracesListBoxLabel.Text = 'analysis traces';
            app.analysistracesListBox = uilistbox(app.UIFigure);
            app.analysistracesListBox.Items = {};
            app.analysistracesListBox.Multiselect = 'on';
            app.analysistracesListBox.ValueChangedFcn = createCallbackFcn(app, @analysistracesListBoxValueChanged, true);
            app.analysistracesListBox.Value = {};
            app.refreshButton = uibutton(app.UIFigure, 'push');
            app.refreshButton.ButtonPushedFcn = createCallbackFcn(app, @refreshButtonPushed, true);
            app.refreshButton.Text = 'refresh';
            app.channelsButtonGroup = uibuttongroup(app.UIFigure);
            app.channelsButtonGroup.Title = 'channels';
            app.selectedchannelButton = uitogglebutton(app.channelsButtonGroup);
            app.selectedchannelButton.Text = 'selected channel';
            app.selectedchannelButton.Value = true;
            app.allchannelsButton = uitogglebutton(app.channelsButtonGroup);
            app.allchannelsButton.Text = 'all channels';
            app.clearButton = uibutton(app.UIFigure, 'push');
            app.clearButton.ButtonPushedFcn = createCallbackFcn(app, @clearButtonPushed, true);
            app.clearButton.Text = 'clear';
            app.legendEditField_1 = uieditfield(app.UIFigure, 'text');
            app.legendEditField_1.Editable = 'off';
            app.legendEditField_2 = uieditfield(app.UIFigure, 'text');
            app.legendEditField_2.Editable = 'off';
            app.legendEditField_3 = uieditfield(app.UIFigure, 'text');
            app.legendEditField_3.Editable = 'off';
            app.legendEditField_4 = uieditfield(app.UIFigure, 'text');
            app.legendEditField_4.Editable = 'off';
            app.legendEditField_5 = uieditfield(app.UIFigure, 'text');
            app.legendEditField_5.Editable = 'off';
            app.legendEditField_6 = uieditfield(app.UIFigure, 'text');
            app.legendEditField_6.Editable = 'off';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'stimuli')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = stimuli(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end