classdef converttocsv < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        converttoCSVButton        matlab.ui.control.Button
        savefolderEditField       matlab.ui.control.EditField
        savefolderEditFieldLabel  matlab.ui.control.Label
        savefolderButton          matlab.ui.control.Button
        datafolderEditField       matlab.ui.control.EditField
        datafolderEditFieldLabel  matlab.ui.control.Label
        datafolderButton          matlab.ui.control.Button
        ConvertdatatoCSVLabel     matlab.ui.control.Label
        filelistListBox           matlab.ui.control.ListBox
        filelistListBoxLabel      matlab.ui.control.Label
    end

    
    properties (Access = private)
        DataDirectory 
        SaveDirectory
    end
    
    methods (Access = private)
        
        function [] = updatedatalist(app)
            app.datafolderEditField.Value = app.DataDirectory;
            files = dir([app.DataDirectory,filesep,'*experiment*.mat']);
            fileNames = struct2cell(files);
            app.filelistListBox.Items = fileNames(1,:);
            if ~isempty(app.filelistListBox.Items)
                app.filelistListBox.Value = app.filelistListBox.Items{1};
            end
            app.SaveDirectory = userpath;
            app.savefolderEditField.Value = app.SaveDirectory;
            
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
          
            app.DataDirectory = pwd;
            updatedatalist(app)
            app.SaveDirectory = userpath;
            addpath([userpath,filesep,'patchclamp',filesep,'data_management'])
            
        end

        % Button pushed function: datafolderButton
        function datafolderButtonPushed(app, event)
           
            app.DataDirectory = uigetdir(pwd,'Pick a data directory');
            updatedatalist(app)
         
            
        end

        % Button pushed function: savefolderButton
        function savefolderButtonPushed(app, event)
            
            foo = uigetdir(userpath,'Pick a save directory');
            if foo==0
                return
            end
            app.SaveDirectory = foo;
            app.savefolderEditField.Value = app.SaveDirectory;
            
        end

        % Button pushed function: converttoCSVButton
        function converttoCSVButtonPushed(app, event)
            nFiles = numel(app.filelistListBox.Value);
            for ii = 1:nFiles
                fStr = [app.DataDirectory,filesep,app.filelistListBox.Value{ii}];
                convertdatafilestocsv(fStr,app.SaveDirectory)
            end            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.filelistListBoxLabel = uilabel(app.UIFigure);
            app.filelistListBoxLabel.Text = 'file list';
            app.filelistListBox = uilistbox(app.UIFigure);
            app.filelistListBox.Multiselect = 'on';
            app.filelistListBox.Value = {'Item 1'};
            app.ConvertdatatoCSVLabel = uilabel(app.UIFigure);
            app.ConvertdatatoCSVLabel.Text = 'Convert data to CSV';
            app.datafolderButton = uibutton(app.UIFigure, 'push');
            app.datafolderButton.ButtonPushedFcn = createCallbackFcn(app, @datafolderButtonPushed, true);
            app.datafolderButton.Text = 'data folder';
            app.datafolderEditFieldLabel = uilabel(app.UIFigure);
            app.datafolderEditFieldLabel.Text = 'data folder';
            app.datafolderEditField = uieditfield(app.UIFigure, 'text');
            app.datafolderEditField.Editable = 'off';
            app.savefolderButton = uibutton(app.UIFigure, 'push');
            app.savefolderButton.ButtonPushedFcn = createCallbackFcn(app, @savefolderButtonPushed, true);
            app.savefolderButton.Text = 'save folder';
            app.savefolderEditFieldLabel = uilabel(app.UIFigure);
            app.savefolderEditFieldLabel.Text = 'save folder';
            app.savefolderEditField = uieditfield(app.UIFigure, 'text');
            app.savefolderEditField.Editable = 'off';
            app.converttoCSVButton = uibutton(app.UIFigure, 'push');
            app.converttoCSVButton.ButtonPushedFcn = createCallbackFcn(app, @converttoCSVButtonPushed, true);
            app.converttoCSVButton.Text = 'convert to CSV';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = converttocsv

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end