classdef manualcheck < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        manualcheckofevokedcurrentsLabel  matlab.ui.control.Label
        scrollPanel                   matlab.ui.container.Panel
        BACKWARDButton                matlab.ui.control.Button
        FORWARDButton                 matlab.ui.control.Button
        ACCEPTButton                  matlab.ui.control.Button
        REJECTButton                  matlab.ui.control.Button
        runningtotalPanel             matlab.ui.container.Panel
        totaltrialsEditFieldLabel     matlab.ui.control.Label
        totaltrialsEditField          matlab.ui.control.NumericEditField
        acceptedtrialsEditFieldLabel  matlab.ui.control.Label
        acceptedtrialsEditField       matlab.ui.control.NumericEditField
        rejectedtrialsEditFieldLabel  matlab.ui.control.Label
        rejectedtrialsEditField       matlab.ui.control.NumericEditField
        resultPanel                   matlab.ui.container.Panel
        overlayButton                 matlab.ui.control.Button
        clearButton                   matlab.ui.control.Button
        acceptButton                  matlab.ui.control.Button
        startButton                   matlab.ui.control.Button
        currenttrialEditFieldLabel    matlab.ui.control.Label
        currenttrialEditField         matlab.ui.control.NumericEditField
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Data % Data traces
        MainApp % LCSMS analysis
        dt % time step (msec)
        AcceptIdx % 1 if accept, 0 if reject
        StimTimes % stimulus times
        BaselineTimes % baseline times
        PeakTimes % peak times
    end
    
    methods (Access = private)
        
        function [] = fillinrunningtotal(app)
            app.acceptedtrialsEditField.Value = nnz(app.AcceptIdx);
            app.rejectedtrialsEditField.Value = numel(app.AcceptIdx)-nnz(app.AcceptIdx);
     
        end
        
        function [] = plottrace(app)
            value = app.currenttrialEditField.Value;
            ydata = app.Data(:,value);
            xdata = (1:length(ydata))*app.dt - app.dt;
            if app.AcceptIdx(value)
                c = 'b';
            else
                c = 'r';
            end
            plot(app.UIAxes,xdata,ydata,'color',c)
            addshading(app)
        end
        
        function [] = addshading(app)
            hold(app.UIAxes,'on')
            y = ylim(app.UIAxes);
            for ii = 1:numel(app.StimTimes)
                st = app.StimTimes(ii);
                base = st + app.BaselineTimes;
                peak = st + app.PeakTimes;
                patch(app.UIAxes,[peak(1) peak(2) peak(2) peak(1)],[y(1) y(1) y(2) y(2)],'g','FaceAlpha',0.1,...
                    'LineStyle','none')
                patch(app.UIAxes,[base(1) base(2) base(2) base(1)],[y(1) y(1) y(2) y(2)],'r','FaceAlpha',0.1,...
                    'LineStyle','none')
            end
            hold(app.UIAxes,'off')
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, data, dt, MainApp, stimTime, ap)
            global acceptIdx
            
            app.MainApp = MainApp;
            app.Data = data;
            app.dt = dt;
            acceptIdx = ones(size(app.Data,2),1);
            app.AcceptIdx = acceptIdx;
            cla(app.UIAxes)
            app.StimTimes = stimTime;
            app.BaselineTimes = ap.baseline;
            app.PeakTimes = ap.peak;
            t = (1:size(app.Data,1))*app.dt - app.dt;
            plot(app.UIAxes,t,app.Data,'b')
            hold(app.UIAxes,'on')
            plot(app.UIAxes,t,mean(app.Data,2),'g','linewidth',3)
            addshading(app)
            hold(app.UIAxes,'off')
            app.totaltrialsEditField.Value = size(app.Data,2);
            app.acceptedtrialsEditField.Value = size(app.Data,2);
            app.rejectedtrialsEditField.Value = 0;
            
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end

        % Button pushed function: startButton
        function startButtonPushed(app, event)

            cla(app.UIAxes)
            hold(app.UIAxes,'off')
            t = (1:size(app.Data,1))*app.dt - app.dt;
            plot(app.UIAxes,t,app.Data(:,1),'b')
            addshading(app)
            app.currenttrialEditField.Value = 1;
            app.AcceptIdx = ones(size(app.Data,2),1);            

        end

        % Button pushed function: ACCEPTButton
        function ACCEPTButtonPushed(app, event)
            value = app.currenttrialEditField.Value;
            if value==0
                disp('Press start button first')
                return
            end
            app.AcceptIdx(value) = 1;
            fillinrunningtotal(app)
            ydata = app.Data(:,value);
            xdata = (1:length(ydata))*app.dt - app.dt;
            plot(app.UIAxes,xdata,ydata,'b')
            if value<app.totaltrialsEditField.Value
                app.currenttrialEditField.Value = app.currenttrialEditField.Value + 1;
                plottrace(app)
            end
            
        end

        % Button pushed function: REJECTButton
        function REJECTButtonPushed(app, event)
            value = app.currenttrialEditField.Value;
            if value==0
                disp('Press start button first')
                return
            end
            app.AcceptIdx(value) = 0;
            fillinrunningtotal(app)
            ydata = app.Data(:,value);
            xdata = (1:length(ydata))*app.dt - app.dt;
            plot(app.UIAxes,xdata,ydata,'r')
            if value<app.totaltrialsEditField.Value
                app.currenttrialEditField.Value = app.currenttrialEditField.Value + 1;
                plottrace(app)
            end
            
        end

        % Button pushed function: FORWARDButton
        function FORWARDButtonPushed(app, event)
            value = app.currenttrialEditField.Value;
            if value==0
                disp('Press start button first')
                return
            end
            if value<app.totaltrialsEditField.Value
                app.currenttrialEditField.Value = app.currenttrialEditField.Value + 1;
                plottrace(app)                
            end
          
        end

        % Button pushed function: BACKWARDButton
        function BACKWARDButtonPushed(app, event)
            value = app.currenttrialEditField.Value;
            if value==0
                disp('Press start button first')
                return
            end
            if value>1
                app.currenttrialEditField.Value = app.currenttrialEditField.Value - 1;
                plottrace(app)                
            end
            
        end

        % Button pushed function: overlayButton
        function overlayButtonPushed(app, event)
            app.currenttrialEditField.Value = 0;
            cla(app.UIAxes)
            t = (1:size(app.Data,1))*app.dt - app.dt;
            plot(app.UIAxes,t,app.Data(:,find(app.AcceptIdx)),'b')
            hold(app.UIAxes,'on')
            plot(app.UIAxes,t,mean(app.Data(:,find(app.AcceptIdx)),2),'g','linewidth',3)
            y = ylim(app.UIAxes);
            for ii = 1:numel(app.StimTimes)
                st = app.StimTimes(ii);
                base = st + app.BaselineTimes;
                peak = st + app.PeakTimes;
                patch(app.UIAxes,[peak(1) peak(2) peak(2) peak(1)],[y(1) y(1) y(2) y(2)],'g','FaceAlpha',0.1,...
                    'LineStyle','none')
                patch(app.UIAxes,[base(1) base(2) base(2) base(1)],[y(1) y(1) y(2) y(2)],'r','FaceAlpha',0.1,...
                    'LineStyle','none')
            end  
            hold(app.UIAxes,'off')
        end

        % Button pushed function: clearButton
        function clearButtonPushed(app, event)
            app.currenttrialEditField.Value = 0;
            app.AcceptIdx = ones(app.totaltrialsEditField.Value,1);
            fillinrunningtotal(app)
            cla(app.UIAxes) 
            t = (1:size(app.Data,1))*app.dt - app.dt;
            plot(app.UIAxes,t,app.Data,'b')
            hold(app.UIAxes,'on')
            plot(app.UIAxes,t,mean(app.Data,2),'g','linewidth',3)
            hold(app.UIAxes,'off')
            
        end

        % Button pushed function: acceptButton
        function acceptButtonPushed(app, event)
            global acceptIdx
            
            acceptIdx = app.AcceptIdx;
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.manualcheckofevokedcurrentsLabel = uilabel(app.UIFigure);
            app.manualcheckofevokedcurrentsLabel.Text = 'manual check of evoked currents';
            app.scrollPanel = uipanel(app.UIFigure);
            app.scrollPanel.Title = 'scroll';
            app.BACKWARDButton = uibutton(app.scrollPanel, 'push');
            app.BACKWARDButton.ButtonPushedFcn = createCallbackFcn(app, @BACKWARDButtonPushed, true);
            app.BACKWARDButton.Text = 'BACKWARD';
            app.FORWARDButton = uibutton(app.scrollPanel, 'push');
            app.FORWARDButton.ButtonPushedFcn = createCallbackFcn(app, @FORWARDButtonPushed, true);
            app.FORWARDButton.Text = 'FORWARD';
            app.ACCEPTButton = uibutton(app.scrollPanel, 'push');
            app.ACCEPTButton.ButtonPushedFcn = createCallbackFcn(app, @ACCEPTButtonPushed, true);
            app.ACCEPTButton.Text = 'ACCEPT';
            app.REJECTButton = uibutton(app.scrollPanel, 'push');
            app.REJECTButton.ButtonPushedFcn = createCallbackFcn(app, @REJECTButtonPushed, true);
            app.REJECTButton.Text = 'REJECT';
            app.runningtotalPanel = uipanel(app.UIFigure);
            app.runningtotalPanel.Title = 'running total';
            app.totaltrialsEditFieldLabel = uilabel(app.runningtotalPanel);
            app.totaltrialsEditFieldLabel.Text = 'total trials';
            app.totaltrialsEditField = uieditfield(app.runningtotalPanel, 'numeric');
            app.totaltrialsEditField.Editable = 'off';
            app.acceptedtrialsEditFieldLabel = uilabel(app.runningtotalPanel);
            app.acceptedtrialsEditFieldLabel.Text = 'accepted trials';
            app.acceptedtrialsEditField = uieditfield(app.runningtotalPanel, 'numeric');
            app.acceptedtrialsEditField.Editable = 'off';
            app.rejectedtrialsEditFieldLabel = uilabel(app.runningtotalPanel);
            app.rejectedtrialsEditFieldLabel.Text = 'rejected trials';
            app.rejectedtrialsEditField = uieditfield(app.runningtotalPanel, 'numeric');
            app.rejectedtrialsEditField.Editable = 'off';
            app.resultPanel = uipanel(app.UIFigure);
            app.resultPanel.Title = 'result';
            app.overlayButton = uibutton(app.resultPanel, 'push');
            app.overlayButton.ButtonPushedFcn = createCallbackFcn(app, @overlayButtonPushed, true);
            app.overlayButton.Text = 'overlay';
            app.clearButton = uibutton(app.resultPanel, 'push');
            app.clearButton.ButtonPushedFcn = createCallbackFcn(app, @clearButtonPushed, true);
            app.clearButton.Text = 'clear';
            app.acceptButton = uibutton(app.resultPanel, 'push');
            app.acceptButton.ButtonPushedFcn = createCallbackFcn(app, @acceptButtonPushed, true);
            app.acceptButton.Text = 'accept';
            app.startButton = uibutton(app.UIFigure, 'push');
            app.startButton.ButtonPushedFcn = createCallbackFcn(app, @startButtonPushed, true);
            app.startButton.Text = 'start';
            app.currenttrialEditFieldLabel = uilabel(app.UIFigure);
            app.currenttrialEditFieldLabel.Text = 'current trial';
            app.currenttrialEditField = uieditfield(app.UIFigure, 'numeric');
            app.currenttrialEditField.Editable = 'off';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'msec')
            ylabel(app.UIAxes, 'pA')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = manualcheck(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end