classdef parameters < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        AnalysisParametersLabel        matlab.ui.control.Label
        spikethresholdmVmsDropDownLabel  matlab.ui.control.Label
        spikethresholdmVmsDropDown     matlab.ui.control.DropDown
        stimulitimesEditFieldLabel     matlab.ui.control.Label
        stimulitimesEditField          matlab.ui.control.EditField
        Examples500forstimulusatt500msLabel  matlab.ui.control.Label
        forstimuliatt500msand550msLabel  matlab.ui.control.Label
        for10stimuliat100Hzstartingatt500msLabel  matlab.ui.control.Label
        synapticcurrentsDropDownLabel  matlab.ui.control.Label
        synapticcurrentsDropDown       matlab.ui.control.DropDown
        sPSCrisetimeEditFieldLabel     matlab.ui.control.Label
        sPSCrisetimeEditField          matlab.ui.control.NumericEditField
        sPSCamplitudeEditFieldLabel    matlab.ui.control.Label
        sPSCamplitudeEditField         matlab.ui.control.NumericEditField
        sPSCareaEditFieldLabel         matlab.ui.control.Label
        sPSCareaEditField              matlab.ui.control.NumericEditField
        acceptButton                   matlab.ui.control.Button
        defaultButton                  matlab.ui.control.Button
        cancelButton                   matlab.ui.control.Button
        minimumspikeheightmVEditFieldLabel  matlab.ui.control.Label
        minimumspikeheightEditField    matlab.ui.control.NumericEditField
        SixtyHzFilterCheckBox          matlab.ui.control.CheckBox
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: cancelButton
        function cancelButtonPushed(app, event)
            delete(app)
        end

        % Button pushed function: acceptButton
        function acceptButtonPushed(app, event)
            ap.spikeThreshold = str2double(app.spikethresholdmVmsDropDown.Value);
            ap.minimumSpikeHeight = app.minimumspikeheightEditField.Value;
            ap.stimulusTimes = str2num(app.stimulitimesEditField.Value); %#ok<ST2NM> 
            if strcmp(app.synapticcurrentsDropDown.Value,'inward')
                ap.synapticDirection = -1;
            else
                ap.synapticDirection = 1;
            end
            ap.riseTime = app.sPSCrisetimeEditField.Value;
            ap.amplitude = app.sPSCamplitudeEditField.Value;
            ap.area = app.sPSCareaEditField.Value;
            ap.sixtyHz = app.SixtyHzFilterCheckBox.Value;
            
            filename = [pwd,filesep,'analysisParameters.mat'];
            save(filename,'ap')
            
        end

        % Button pushed function: defaultButton
        function defaultButtonPushed(app, event)
            app.spikethresholdmVmsDropDown.Value = '20';
            app.minimumspikeheightEditField.Value = 0;
            app.stimulitimesEditField.Value = '500 550';
            app.synapticcurrentsDropDown.Value = 'inward';
            app.sPSCrisetimeEditField.Value = 1;
            app.sPSCamplitudeEditField.Value = 5;
            app.sPSCareaEditField.Value = 10;
            app.SixtyHzFilterCheckBox = true;
            acceptButtonPushed(app);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AnalysisParametersLabel = uilabel(app.UIFigure);
            app.AnalysisParametersLabel.Text = 'Analysis Parameters';
            app.spikethresholdmVmsDropDownLabel = uilabel(app.UIFigure);
            app.spikethresholdmVmsDropDownLabel.Text = 'spike threshold (mV/ms)';
            app.spikethresholdmVmsDropDown = uidropdown(app.UIFigure);
            app.spikethresholdmVmsDropDown.Items = {'10', '20', '30', '40'};
            app.spikethresholdmVmsDropDown.Value = '20';
            app.stimulitimesEditFieldLabel = uilabel(app.UIFigure);
            app.stimulitimesEditFieldLabel.Text = 'stimuli times';
            app.stimulitimesEditField = uieditfield(app.UIFigure, 'text');
            app.stimulitimesEditField.Value = '500';
            app.Examples500forstimulusatt500msLabel = uilabel(app.UIFigure);
            app.Examples500forstimulusatt500msLabel.Text = 'Examples:    500 for stimulus at t = 500 ms';
            app.forstimuliatt500msand550msLabel = uilabel(app.UIFigure);
            app.forstimuliatt500msand550msLabel.Text = '[500 550] for stimuli at t = 500 ms and 550 ms';
            app.for10stimuliat100Hzstartingatt500msLabel = uilabel(app.UIFigure);
            app.for10stimuliat100Hzstartingatt500msLabel.Text = '500:10:590 for 10 stimuli at 100 Hz starting at t = 500 ms';
            app.synapticcurrentsDropDownLabel = uilabel(app.UIFigure);
            app.synapticcurrentsDropDownLabel.Text = 'synaptic currents';
            app.synapticcurrentsDropDown = uidropdown(app.UIFigure);
            app.synapticcurrentsDropDown.Items = {'inward', 'outward'};
            app.synapticcurrentsDropDown.Value = 'inward';
            app.sPSCrisetimeEditFieldLabel = uilabel(app.UIFigure);
            app.sPSCrisetimeEditFieldLabel.Text = 'sPSC rise time';
            app.sPSCrisetimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.sPSCrisetimeEditField.Limits = [0.2 10];
            app.sPSCrisetimeEditField.ValueDisplayFormat = '%5.1f';
            app.sPSCrisetimeEditField.Value = 1;
            app.sPSCamplitudeEditFieldLabel = uilabel(app.UIFigure);
            app.sPSCamplitudeEditFieldLabel.Text = 'sPSC amplitude';
            app.sPSCamplitudeEditField = uieditfield(app.UIFigure, 'numeric');
            app.sPSCamplitudeEditField.Limits = [1 50];
            app.sPSCamplitudeEditField.RoundFractionalValues = 'on';
            app.sPSCamplitudeEditField.ValueDisplayFormat = '%.0f';
            app.sPSCamplitudeEditField.Value = 5;
            app.sPSCareaEditFieldLabel = uilabel(app.UIFigure);
            app.sPSCareaEditFieldLabel.Text = 'sPSC area';
            app.sPSCareaEditField = uieditfield(app.UIFigure, 'numeric');
            app.sPSCareaEditField.Limits = [10 500];
            app.sPSCareaEditField.RoundFractionalValues = 'on';
            app.sPSCareaEditField.ValueDisplayFormat = '%.0f';
            app.sPSCareaEditField.Value = 10;
            app.acceptButton = uibutton(app.UIFigure, 'push');
            app.acceptButton.ButtonPushedFcn = createCallbackFcn(app, @acceptButtonPushed, true);
            app.acceptButton.Text = 'accept';
            app.defaultButton = uibutton(app.UIFigure, 'push');
            app.defaultButton.ButtonPushedFcn = createCallbackFcn(app, @defaultButtonPushed, true);
            app.defaultButton.Text = 'default';
            app.cancelButton = uibutton(app.UIFigure, 'push');
            app.cancelButton.ButtonPushedFcn = createCallbackFcn(app, @cancelButtonPushed, true);
            app.cancelButton.Text = 'cancel';
            app.minimumspikeheightmVEditFieldLabel = uilabel(app.UIFigure);
            app.minimumspikeheightmVEditFieldLabel.Text = 'minimum spike height (mV)';
            app.minimumspikeheightEditField = uieditfield(app.UIFigure, 'numeric');
            app.minimumspikeheightEditField.Limits = [-40 50];
            app.minimumspikeheightEditField.RoundFractionalValues = 'on';
            app.minimumspikeheightEditField.ValueDisplayFormat = '%.0f';
            app.SixtyHzFilterCheckBox = uicheckbox(app.UIFigure);
            app.SixtyHzFilterCheckBox.Text = '60 Hz filter';
            app.SixtyHzFilterCheckBox.Value = true;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = parameters

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end