classdef databrowser < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        trialsPanel             matlab.ui.container.Panel
        replotButton            matlab.ui.control.Button
        trialNoSpinner          matlab.ui.control.Spinner
        trialNoSpinnerLabel     matlab.ui.control.Label
        replaceCheckBox         matlab.ui.control.CheckBox
        displaytypeButtonGroup  matlab.ui.container.ButtonGroup
        displayallButton        matlab.ui.control.ToggleButton
        scrollthroughButton     matlab.ui.control.ToggleButton
        clearButton             matlab.ui.control.Button
        channelNoDropDownLabel  matlab.ui.control.Label
        channelNoDropDown       matlab.ui.control.DropDown
        databrowserLabel        matlab.ui.control.Label
        refreshButton           matlab.ui.control.Button
        filesPanel              matlab.ui.container.Panel
        fileNoSpinner           matlab.ui.control.Spinner
        fileNoSpinnerLabel      matlab.ui.control.Label
        filenameEditField       matlab.ui.control.EditField
        lastacquiredButton      matlab.ui.control.Button
        UIAxes                  matlab.ui.control.UIAxes
    end


    properties (Access = private)
        CallingApp % handle to the main data acquisition app
        Files % data files that have been acquired
        Data % data files (Pars, inputData, outputData) loaded into memory
        TimeSeries % time series
        ScrollThrough % true (if scroll through trials) and false (if display all trials)
        TrialNo % current trial number
        Colors % colors to use for plots; corresponds to channel colors of main app
        ChannelNo % number of recording channel to browse
    end

    methods (Access = private)

        function [] = displaytrial(app)
            if isempty(app.Files)
                return
            end
            data = app.Data(:,app.TrialNo);
            if app.replaceCheckBox.Value
                cla(app.UIAxes)
            else
                hold(app.UIAxes,'on')
            end
            plot(app.UIAxes,app.TimeSeries,data,'Color',app.Colors(app.ChannelNo,:))
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.CallingApp = mainapp;
            load('plottingColors.mat','colors')
            app.Colors = colors;
            app.ChannelNo = str2double(app.channelNoDropDown.Value);
            zoomObj = zoomInteraction;
            panObj = panInteraction;
            app.UIAxes.Interactions = [zoomObj, panObj];
            app.TimeSeries = [];
            app.Data = [];
            app.Files = [];
            refreshButtonPushed(app)
        end

        % Button pushed function: refreshButton
        function refreshButtonPushed(app, event)
            global DAQPARS

            files = dir([DAQPARS.saveDirectory,'*.mat']);
            if isempty(files)
                disp('No data files have been acquired yet. Nothing to browse.')
                return
            end
            nextDataFile = [DAQPARS.fileName,'.mat'];
            if strcmp(nextDataFile,files(end).name)
                files(end) = []; % these data are still being acquired; do not include them
            end
            nFiles = numel(files);
            app.fileNoSpinner.Limits = [1 max(nFiles,2)];
            app.Files = files;
            app.displayallButton.Value = true;
            lastacquiredButtonPushed(app)

        end

        % Button pushed function: lastacquiredButton
        function lastacquiredButtonPushed(app, event)

            if isempty(app.Files)
                return
            end
            nFiles = numel(app.Files);
            app.fileNoSpinner.Value = nFiles;
            fileNoSpinnerValueChanged(app)

        end

        % Value changed function: fileNoSpinner
        function fileNoSpinnerValueChanged(app, event)
            if isempty(app.Files)
                return
            end
            value = app.fileNoSpinner.Value;
            if value > numel(app.Files)
                app.fileNoSpinner.Value = numel(app.Files);
                value = numel(app.Files);
            end
            app.filenameEditField.Value = app.Files(value).name(1:end-4);
            filename = [app.Files(value).folder,filesep,app.Files(value).name];
            data = load(filename,'Pars','inputData');
            [channelAcquired,channelIdx] = ismember(app.ChannelNo,data.Pars.inputChannels);
            cla(app.UIAxes)
            if channelAcquired
                dt = 1000/data.Pars.sampleRate; % time step in msec
                app.Data = data.inputData(:,:,channelIdx);
                app.TimeSeries = (1:size(app.Data,1))*dt - dt;
                nTrials = max(size(app.Data,2),2);
                app.trialNoSpinner.Value = 1;
                app.trialNoSpinner.Limits = [1 nTrials];
                if app.displayallButton.Value
                    plot(app.UIAxes,app.TimeSeries,app.Data,'Color',app.Colors(app.ChannelNo,:))
                else
                    displaytrial(app)
                end
            end

        end

        % Value changed function: channelNoDropDown
        function channelNoDropDownValueChanged(app, event)
            if isempty(app.Files)
                return
            end
            app.ChannelNo = str2double(app.channelNoDropDown.Value);
            fileNoSpinnerValueChanged(app)
        end

        % Button pushed function: clearButton
        function clearButtonPushed(app, event)
            cla(app.UIAxes)
        end

        % Selection changed function: displaytypeButtonGroup
        function displaytypeButtonGroupSelectionChanged(app, event)
            if isempty(app.Files)
                return
            end
            selectedButton = app.displaytypeButtonGroup.SelectedObject;
            if strcmp(selectedButton.Text,'display all') % display all trials
                cla(app.UIAxes)
                plot(app.UIAxes,app.TimeSeries,app.Data,'Color',app.Colors(app.ChannelNo,:))
                app.ScrollThrough = false;
            else % scroll through trials
                app.ScrollThrough = true;
                app.TrialNo = app.trialNoSpinner.Value;
                displaytrial(app)
            end
        end

        % Value changed function: trialNoSpinner
        function trialNoSpinnerValueChanged(app, event)
            if isempty(app.Files)
                return
            end
            app.TrialNo = app.trialNoSpinner.Value;
            displaytrial(app)

        end

        % Button pushed function: replotButton
        function replotButtonPushed(app, event)
            if isempty(app.Files)
                return
            end
            if app.ScrollThrough
                displaytrial(app)
            else
                cla(app.UIAxes)
                plot(app.UIAxes,app.TimeSeries,app.Data,'Color',app.Colors(app.ChannelNo,:))
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.filesPanel = uipanel(app.UIFigure);
            app.filesPanel.Title = 'files';
            app.lastacquiredButton = uibutton(app.filesPanel, 'push');
            app.lastacquiredButton.ButtonPushedFcn = createCallbackFcn(app, @lastacquiredButtonPushed, true);
            app.lastacquiredButton.Text = 'last acquired';
            app.filenameEditField = uieditfield(app.filesPanel, 'text');
            app.filenameEditField.Editable = 'off';
            app.fileNoSpinnerLabel = uilabel(app.filesPanel);
            app.fileNoSpinnerLabel.Text = 'fileNo';
            app.fileNoSpinner = uispinner(app.filesPanel);
            app.fileNoSpinner.Limits = [1 Inf];
            app.fileNoSpinner.ValueChangedFcn = createCallbackFcn(app, @fileNoSpinnerValueChanged, true);
            app.fileNoSpinner.Value = 1;
            app.refreshButton = uibutton(app.UIFigure, 'push');
            app.refreshButton.ButtonPushedFcn = createCallbackFcn(app, @refreshButtonPushed, true);
            app.refreshButton.Icon = 'Refresh_icon.svg';
            app.refreshButton.Text = 'refresh';
            app.databrowserLabel = uilabel(app.UIFigure);
            app.databrowserLabel.Text = 'data browser';
            app.channelNoDropDown = uidropdown(app.UIFigure);
            app.channelNoDropDown.Items = {'1', '2', '3', '4'};
            app.channelNoDropDown.ValueChangedFcn = createCallbackFcn(app, @channelNoDropDownValueChanged, true);
            app.channelNoDropDown.Value = '1';
            app.channelNoDropDownLabel = uilabel(app.UIFigure);
            app.channelNoDropDownLabel.Text = 'channelNo';
            app.trialsPanel = uipanel(app.UIFigure);
            app.trialsPanel.Title = 'trials';
            app.clearButton = uibutton(app.trialsPanel, 'push');
            app.clearButton.ButtonPushedFcn = createCallbackFcn(app, @clearButtonPushed, true);
            app.clearButton.Text = 'clear';
            app.displaytypeButtonGroup = uibuttongroup(app.trialsPanel);
            app.displaytypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @displaytypeButtonGroupSelectionChanged, true);
            app.displaytypeButtonGroup.Title = 'display type';
            app.scrollthroughButton = uitogglebutton(app.displaytypeButtonGroup);
            app.scrollthroughButton.Text = 'scroll through';
            app.displayallButton = uitogglebutton(app.displaytypeButtonGroup);
            app.displayallButton.Text = 'display all';
            app.displayallButton.Value = true;
            app.replaceCheckBox = uicheckbox(app.trialsPanel);
            app.replaceCheckBox.Text = 'replace';
            app.replaceCheckBox.Value = true;
            app.trialNoSpinnerLabel = uilabel(app.trialsPanel);
            app.trialNoSpinnerLabel.Text = 'trialNo';
            app.trialNoSpinner = uispinner(app.trialsPanel);
            app.trialNoSpinner.Limits = [1 Inf];
            app.trialNoSpinner.ValueChangedFcn = createCallbackFcn(app, @trialNoSpinnerValueChanged, true);
            app.trialNoSpinner.Value = 1;
            app.replotButton = uibutton(app.trialsPanel, 'push');
            app.replotButton.ButtonPushedFcn = createCallbackFcn(app, @replotButtonPushed, true);
            app.replotButton.Text = 'replot';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = databrowser(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end