classdef graph1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        totalsweepsEditField           matlab.ui.control.NumericEditField
        totalsweepsEditFieldLabel      matlab.ui.control.Label
        completedsweepsEditField       matlab.ui.control.NumericEditField
        completedsweepsEditFieldLabel  matlab.ui.control.Label
        autoscaleButton                matlab.ui.control.Button
        zoompanButtonGroup             matlab.ui.container.ButtonGroup
        bothButton                     matlab.ui.control.ToggleButton
        recordingButton                matlab.ui.control.ToggleButton
        timeButton                     matlab.ui.control.ToggleButton
        trialEditField                 matlab.ui.control.EditField
        trialEditFieldLabel            matlab.ui.control.Label
        experimentEditField            matlab.ui.control.EditField
        experimentEditFieldLabel       matlab.ui.control.Label
        startstopButton                matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
    end
    
    
    methods (Access = private)
        
    end
    
    methods (Access = public)

        function [] = setScales(app)
            global DAQPARS

            duration = DAQPARS.duration;
            ticks = duration/10;
            app.UIAxes.XLim = [0,duration];
            app.UIAxes.XTick = 0:ticks:duration;


        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            axtoolbar(app.UIAxes,{'zoomin','zoomout','restoreview'});
            zoomObj = zoomInteraction('Dimensions','x');
            panObj = panInteraction('Dimensions','x');
            app.UIAxes.Interactions = [zoomObj, panObj];


        end

        % Button pushed function: startstopButton
        function startstopButtonPushed(app, event)
            global DAQPARS %#ok<*GVMIS> 

            pushStartButton(DAQPARS.MainApp)

            
        end

        % Button pushed function: autoscaleButton
        function autoscaleButtonPushed(app, event)
            axis(app.UIAxes,'auto');
        end

        % Selection changed function: zoompanButtonGroup
        function zoompanButtonGroupSelectionChanged(app, event)

            selectedButton = app.zoompanButtonGroup.SelectedObject;
            switch selectedButton.Text
                case 'time'
                    zoomObj = zoomInteraction('Dimensions','x');
                    panObj = panInteraction('Dimensions','x');
                case 'recording'
                    zoomObj = zoomInteraction('Dimensions','y');
                    panObj = panInteraction('Dimensions','y');
                otherwise
                    zoomObj = zoomInteraction;
                    panObj = panInteraction;
            end
            app.UIAxes.Interactions = [zoomObj, panObj];


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.Tag = 'graph1';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Graph 1')
            xlabel(app.UIAxes, 'msec')
            ylabel(app.UIAxes, 'mV or pA')
            app.startstopButton = uibutton(app.UIFigure, 'push');
            app.startstopButton.ButtonPushedFcn = createCallbackFcn(app, @startstopButtonPushed, true);
            app.startstopButton.Text = 'start/stop';
            app.experimentEditFieldLabel = uilabel(app.UIFigure);
            app.experimentEditFieldLabel.Text = 'experiment';
            app.experimentEditField = uieditfield(app.UIFigure, 'text');
            app.experimentEditField.Editable = 'off';
            app.trialEditFieldLabel = uilabel(app.UIFigure);
            app.trialEditFieldLabel.Text = 'trial';
            app.trialEditField = uieditfield(app.UIFigure, 'text');
            app.trialEditField.Editable = 'off';
            app.zoompanButtonGroup = uibuttongroup(app.UIFigure);
            app.zoompanButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @zoompanButtonGroupSelectionChanged, true);
            app.zoompanButtonGroup.Title = 'zoom & pan';
            app.timeButton = uitogglebutton(app.zoompanButtonGroup);
            app.timeButton.Text = 'time';
            app.timeButton.Value = true;
            app.recordingButton = uitogglebutton(app.zoompanButtonGroup);
            app.recordingButton.Text = 'recording';
            app.bothButton = uitogglebutton(app.zoompanButtonGroup);
            app.bothButton.Text = 'both';
            app.autoscaleButton = uibutton(app.UIFigure, 'push');
            app.autoscaleButton.ButtonPushedFcn = createCallbackFcn(app, @autoscaleButtonPushed, true);
            app.autoscaleButton.Text = 'autoscale';
            app.completedsweepsEditFieldLabel = uilabel(app.UIFigure);
            app.completedsweepsEditFieldLabel.Text = 'completed sweeps';
            app.completedsweepsEditField = uieditfield(app.UIFigure, 'numeric');
            app.completedsweepsEditField.Limits = [0 Inf];
            app.completedsweepsEditField.ValueDisplayFormat = '%.0f';
            app.completedsweepsEditField.Tag = 'popup1_completedsweeps';
            app.completedsweepsEditField.Editable = 'off';
            app.totalsweepsEditFieldLabel = uilabel(app.UIFigure);
            app.totalsweepsEditFieldLabel.Text = 'total sweeps';
            app.totalsweepsEditField = uieditfield(app.UIFigure, 'numeric');
            app.totalsweepsEditField.Limits = [0 Inf];
            app.totalsweepsEditField.ValueDisplayFormat = '%.0f';
            app.totalsweepsEditField.Tag = 'popup1_totalsweeps';
            app.totalsweepsEditField.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = graph1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end