classdef graph2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        trialEditField            matlab.ui.control.EditField
        trialEditFieldLabel       matlab.ui.control.Label
        experimentEditField       matlab.ui.control.EditField
        experimentEditFieldLabel  matlab.ui.control.Label
        amplitudelimitsPanel      matlab.ui.container.Panel
        upperSpinner              matlab.ui.control.Spinner
        upperSpinnerLabel         matlab.ui.control.Label
        lowerSpinner              matlab.ui.control.Spinner
        lowerSpinnerLabel         matlab.ui.control.Label
        amplitudescalePanel       matlab.ui.container.Panel
        timescalePanel            matlab.ui.container.Panel
        stopButton                matlab.ui.control.Button
        UIAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        xScaleSlider % slider to determine x scale 
        axX % Description
        axY % Description
        yScaleSlider % Description
    end
    
    
    methods (Access = private)
        
        function [] = xSliderMoving(app,source)
            source.Position(1,1) = round(source.Position(1,1),1);
            source.Position(2,1) = round(source.Position(2,1),1);
            xlim(app.UIAxes, [source.Position(1,1), source.Position(2,1)])
        end
        
        
        function [] = ySliderMoving(app,source)
            source.Position(1,2) = round(source.Position(1,2),1);
            source.Position(2,2) = round(source.Position(2,2),1);
            ylim(app.UIAxes, [source.Position(1,2), source.Position(2,2)])            
        end
        
        
    end
    
    methods (Access = public)
        
        function [] = setScales(app,alreadyOpen)
            global DAQPARS
            
            duration = DAQPARS.duration;
            ticks = duration/10;
            app.axX.XLim = [0,duration];
            app.axX.XTick = 0:ticks:duration;
            app.xScaleSlider.Position = [0,0;duration,0];
            xlim(app.UIAxes,[app.xScaleSlider.Position(1,1), app.xScaleSlider.Position(2,1)])
           
            if ~alreadyOpen
                yLimit = 100;
                ticks = round(yLimit/5);
                app.axY.YLim = [-yLimit,yLimit];
                app.axY.YTick = -yLimit:ticks:yLimit;
                app.yScaleSlider.Position = [0,-yLimit;0,yLimit];
                app.lowerSpinner.Value = -yLimit;
                app.upperSpinner.Value = yLimit;
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global DAQPARS 
            
            duration = DAQPARS.duration;
            ticks = round(duration/10);
            app.axX = axes( ...
                app.timescalePanel,'Color','none','YColor','none','XLim',[0,duration],'YTick',[], ...
                'XTick',0:ticks:duration,'TickDir','both','TickLength',[0.03,0.035],'XMinorTick', ...
                'on','Units','pixels','Position',[20,45,500,0] ...
                );
            disableDefaultInteractivity(app.axX);
            app.axX.Toolbar.Visible = 'off';
            app.xScaleSlider = images.roi.Line(app.axX,'Position',[0,0;duration,0]);
            addlistener(app.xScaleSlider,'MovingROI',@(varargin)xSliderMoving(app,app.xScaleSlider));
            xlim(app.UIAxes,[app.xScaleSlider.Position(1,1), app.xScaleSlider.Position(2,1)])
            
            
            yLimit = 100;
            ticks = round(yLimit/5);
            app.axY = axes( ...
                app.amplitudescalePanel,'Color','none','XColor','none','YLim',[-yLimit,yLimit],'XTick',[], ...
                'YTick',-yLimit:ticks:yLimit,'TickDir','both','TickLength',[0.03,0.035],'YMinorTick', ...
                'on','Units','pixels','Position',[75,45,0,500] ...
                );
            disableDefaultInteractivity(app.axY);
            app.axY.Toolbar.Visible = 'off';
            app.yScaleSlider = images.roi.Line(app.axY,'Position',[0,-yLimit;0,yLimit]);
            addlistener(app.yScaleSlider,'MovingROI',@(varargin)ySliderMoving(app,app.yScaleSlider));
            ylim(app.UIAxes,[app.yScaleSlider.Position(1,2), app.yScaleSlider.Position(2,2)])
            
            zoomObj = zoomInteraction;
            panObj = panInteraction;
            app.UIAxes.Interactions = [zoomObj, panObj];
            
        end

        % Button pushed function: stopButton
        function stopButtonPushed(app, event)
            global DAQPARS
            
            if DAQPARS.MainApp.Recording
                pushStartButton(DAQPARS.MainApp)
            end
            
        end

        % Value changed function: lowerSpinner
        function lowerSpinnerValueChanged(app, event)
            lower = app.lowerSpinner.Value;
            upper = app.upperSpinner.Value;
            if lower>=upper
                app.upperSpinner.Value = lower+10;
                upper = lower+10;
            end
            ticks = round((upper-lower)/10);
            app.axY.YLim = [lower,upper];
            app.axY.YTick = lower:ticks:upper;
            app.yScaleSlider.Position = [0,lower;0,upper]; 
            ylim(app.UIAxes,[app.yScaleSlider.Position(1,2), app.yScaleSlider.Position(2,2)])            
            
        end

        % Value changed function: upperSpinner
        function upperSpinnerValueChanged(app, event)
            upper = app.upperSpinner.Value;
            lower = app.lowerSpinner.Value;
            if upper<=lower
                app.lowerSpinner.Value = upper-10;
                lower = upper-10;
            end
            ticks = round((upper-lower)/10);
            app.axY.YLim = [lower,upper];
            app.axY.YTick = lower:ticks:upper;
            app.yScaleSlider.Position = [0,lower;0,upper];
            ylim(app.UIAxes,[app.yScaleSlider.Position(1,2), app.yScaleSlider.Position(2,2)])            
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.Tag = 'graph2';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Graph 2')
            xlabel(app.UIAxes, 'msec')
            ylabel(app.UIAxes, 'mV or pA')
            app.stopButton = uibutton(app.UIFigure, 'push');
            app.stopButton.ButtonPushedFcn = createCallbackFcn(app, @stopButtonPushed, true);
            app.stopButton.Text = 'stop';
            app.timescalePanel = uipanel(app.UIFigure);
            app.timescalePanel.Title = 'time scale';
            app.amplitudescalePanel = uipanel(app.UIFigure);
            app.amplitudescalePanel.Title = 'amplitude scale';
            app.amplitudelimitsPanel = uipanel(app.UIFigure);
            app.amplitudelimitsPanel.Title = 'amplitude limits';
            app.lowerSpinnerLabel = uilabel(app.amplitudelimitsPanel);
            app.lowerSpinnerLabel.Text = 'lower';
            app.lowerSpinner = uispinner(app.amplitudelimitsPanel);
            app.lowerSpinner.Limits = [-10000 10000];
            app.lowerSpinner.RoundFractionalValues = 'on';
            app.lowerSpinner.ValueDisplayFormat = '%.0f';
            app.lowerSpinner.ValueChangedFcn = createCallbackFcn(app, @lowerSpinnerValueChanged, true);
            app.lowerSpinner.Value = -100;
            app.upperSpinnerLabel = uilabel(app.amplitudelimitsPanel);
            app.upperSpinnerLabel.Text = 'upper';
            app.upperSpinner = uispinner(app.amplitudelimitsPanel);
            app.upperSpinner.Limits = [-10000 10000];
            app.upperSpinner.RoundFractionalValues = 'on';
            app.upperSpinner.ValueDisplayFormat = '%.0f';
            app.upperSpinner.ValueChangedFcn = createCallbackFcn(app, @upperSpinnerValueChanged, true);
            app.upperSpinner.Value = 100;
            app.experimentEditFieldLabel = uilabel(app.UIFigure);
            app.experimentEditFieldLabel.Text = 'experiment';
            app.experimentEditField = uieditfield(app.UIFigure, 'text');
            app.experimentEditField.Editable = 'off';
            app.trialEditFieldLabel = uilabel(app.UIFigure);
            app.trialEditFieldLabel.Text = 'trial';
            app.trialEditField = uieditfield(app.UIFigure, 'text');
            app.trialEditField.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = graph2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end