classdef hardware < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        HardwareUIFigure               matlab.ui.Figure
        cancelButton                   matlab.ui.control.Button
        finalizeButton                 matlab.ui.control.Button
        patchclampamplifierPanel       matlab.ui.container.Panel
        currentclampOutputEditField    matlab.ui.control.NumericEditField
        currentclampInputEditField     matlab.ui.control.NumericEditField
        voltageclampOutputEditField    matlab.ui.control.NumericEditField
        voltageclampInputEditField     matlab.ui.control.NumericEditField
        updateamplifiersettingsButton  matlab.ui.control.Button
        mVVLabel_4                     matlab.ui.control.Label
        mVVLabel_3                     matlab.ui.control.Label
        pAVLabel_3                     matlab.ui.control.Label
        pAVLabel_4                     matlab.ui.control.Label
        inputLabel_2                   matlab.ui.control.Label
        outputLabel                    matlab.ui.control.Label
        currentclampLabel              matlab.ui.control.Label
        voltageclampLabel              matlab.ui.control.Label
        amplifierButtonGroup           matlab.ui.container.ButtonGroup
        manualButton                   matlab.ui.control.ToggleButton
        MulticlampB2Button             matlab.ui.control.ToggleButton
        MulticlampB1Button             matlab.ui.control.ToggleButton
        MulticlampA2Button             matlab.ui.control.ToggleButton
        MulticlampA1Button             matlab.ui.control.ToggleButton
        channelnumberDropDown          matlab.ui.control.DropDown
        channelnumberDropDownLabel     matlab.ui.control.Label
        daqboardPanel                  matlab.ui.container.Panel
        updatechannelsButton           matlab.ui.control.Button
        channels58Panel                matlab.ui.container.Panel
        digitaloutputsLabel            matlab.ui.control.Label
        analoginputsLabel_2            matlab.ui.control.Label
        AIChannel_8                    matlab.ui.control.DropDown
        AIChannel_7                    matlab.ui.control.DropDown
        AIChannel_6                    matlab.ui.control.DropDown
        AIChannel_5                    matlab.ui.control.DropDown
        DIOChannel_4                   matlab.ui.control.DropDown
        DIOChannel_3                   matlab.ui.control.DropDown
        DIOChannel_2                   matlab.ui.control.DropDown
        DIOChannel_1                   matlab.ui.control.DropDown
        channels14Panel                matlab.ui.container.Panel
        analogoutputsLabel             matlab.ui.control.Label
        analoginputsLabel              matlab.ui.control.Label
        AIChannel_4                    matlab.ui.control.DropDown
        AIChannel_3                    matlab.ui.control.DropDown
        AIChannel_2                    matlab.ui.control.DropDown
        AIChannel_1                    matlab.ui.control.DropDown
        AOChannel_4                    matlab.ui.control.DropDown
        AOChannel_3                    matlab.ui.control.DropDown
        AOChannel_2                    matlab.ui.control.DropDown
        AOChannel_1                    matlab.ui.control.DropDown
        boardnameDropDown              matlab.ui.control.DropDown
        boardnameDropDownLabel         matlab.ui.control.Label
    end

    
    properties (Access = private)
        Boards % names of available National Instruments boards
        BoardIdx % index of selected board
        DeviceID % Description
        AIchannels % Description
        AOchannels % Description
        DIOchannels % Description
        ChannelsOkay % Description
        AmplifierChannelAssignments % Description
        AmplifierChannelNames % Description
        AmplifierInfo % Description
        CallingApp % Description
    end
    
    methods (Access = private)
        
        function boardOkay = updatechannels(app)
            deviceInfo = app.Boards.DeviceInfo(app.BoardIdx);
            
            aiChannelNames = deviceInfo.Subsystems(1).ChannelNames;
            aoChannelNames = deviceInfo.Subsystems(2).ChannelNames;
            digitalChannelNames = deviceInfo.Subsystems(3).ChannelNames;
            nAOChannels = numel(aoChannelNames);
            aoChannelNames{end+1} = 'off';
            
            % sort the digital channels first by port number and then by
            % line number
            digitalIndices = zeros(numel(digitalChannelNames),2);
            for ii = 1:numel(digitalChannelNames)
                name = digitalChannelNames{ii};
                slash = strfind(name,'/');
                portNumber = str2double(name(slash-1));
                lineNumber = str2double(name(slash+5:end));
                digitalIndices(ii,:) = [portNumber lineNumber];
            end
            [~,y] = sortrows(digitalIndices,[1 2]);
            digitalChannelNames = digitalChannelNames(y);
            
            % put the channel numbers in the GUI
            try
                for ii = 1:8
                    fStr = ['AIChannel_',num2str(ii)];
                    app.(fStr).Items = aiChannelNames;
                    app.(fStr).Value = aiChannelNames{ii};
                end
                for ii = 1:4
                    fStr = ['AOChannel_',num2str(ii)];
                    gStr = ['DIOChannel_',num2str(ii)];
                    app.(fStr).Items = aoChannelNames;
                    app.(gStr).Items = digitalChannelNames;
                    if ii <= nAOChannels   
                        app.(fStr).Value = aoChannelNames{ii};
                    else  % in case the board only has two (2) analog outputs
                        app.(fStr).Value = aoChannelNames{end};
                    end
                    app.(gStr).Value = digitalChannelNames{ii};
                end
                boardOkay = 1;
            catch
                disp('')
                boardOkay = 0;
            end
            
        end
        
        function numberOfMulticlampChannels = checkformulticlamp(app)
            MulticlampTelegraph('start')
            multiclampIDs = MulticlampTelegraph('getAllElectrodeIDS');
            numberOfMulticlampChannels = numel(multiclampIDs);
            MulticlampTelegraph('stop')
            amplifierInfo = [];
            if numberOfMulticlampChannels > 0
                f = waitbar(0,'Please wait as we probe the amplifiers ...');
                amplifierInfo = getmulticlampinfo(f);
                waitbar(1,f,'Finishing up ...');
                pause(0.25)
                y = sortmulticlampchannels(amplifierInfo,multiclampIDs);          
                amplifierInfo = amplifierInfo(y);
            end
            % We want eight possibilities: four Multiclamp options and
            % four manual options. We enter default values below for the
            % manual options. If there are fewer than four Multiclamp
            % options (because there are fewer than two Multiclamp
            % amplifiers), we fill in those with default values too.
            for ii = numberOfMulticlampChannels+1:8
                amplifierInfo(ii).name = 'manual';
                amplifierInfo(ii).inputScalingVoltageClamp = 200;
                amplifierInfo(ii).outputScalingVoltageClamp = 20; %#ok<*AGROW> 
                amplifierInfo(ii).inputScalingCurrentClamp = 100;
                amplifierInfo(ii).outputScalingCurrentClamp = 400;
                amplifierInfo(ii).outputScalingOther = 1;
                amplifierInfo(ii).inputScalingOther = 1;
                amplifierInfo(ii).detected = 0;
            end 
            app.AmplifierInfo = amplifierInfo;
            if exist('f','var')
                close(f)
            end
        end
        
        function [] = populatewithsaved(app)

            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.HardwareUIFigure.Visible = "off";
            app.CallingApp = mainapp;
            app.Boards = daqlist("ni");     % only National Instruments boards
            modelNames = app.Boards{:,3};   % model names are in column 3
            app.boardnameDropDown.Items = modelNames;
            app.BoardIdx = 1;
            app.DeviceID = app.Boards{app.BoardIdx,1};
            app.boardnameDropDown.Value = modelNames{app.BoardIdx};
            boardOkay = updatechannels(app);
            updatechannelsButtonPushed(app);
            if boardOkay == 0 || app.ChannelsOkay == 0
                disp('Error. Please check DAQ board and/or channels configuration.')
                return
            end
            numberOfMulticlampChannels = checkformulticlamp(app);
            app.AmplifierChannelNames = {'Multiclamp A1','Multiclamp A2','Multiclamp B1','Multiclamp B2','manual'};
            if numberOfMulticlampChannels < 4  % only one Multiclamp found
                app.MulticlampB1Button.Enable = 0;
                app.MulticlampB2Button.Enable = 0;
            end
            if numberOfMulticlampChannels < 2 % no Multiclamps found
                app.MulticlampA1Button.Enable = 0;
                app.MulticlampA2Button.Enable = 0;
            end
            app.channelnumberDropDown.Value = '1';
            if numberOfMulticlampChannels > 0
                channelIdx = 1;
                app.MulticlampA1Button.Value = true;
            else
                channelIdx = 5;
                app.manualButton.Value = true;
            end
            app.voltageclampOutputEditField.Value = app.AmplifierInfo(channelIdx).outputScalingVoltageClamp;
            app.voltageclampInputEditField.Value = app.AmplifierInfo(channelIdx).inputScalingVoltageClamp;
            app.currentclampOutputEditField.Value = app.AmplifierInfo(channelIdx).outputScalingCurrentClamp;
            app.currentclampInputEditField.Value = app.AmplifierInfo(channelIdx).inputScalingCurrentClamp;
            % initial amplifier channel assignments
            app.AmplifierChannelAssignments = 5:8;
            if numberOfMulticlampChannels > 0
                app.AmplifierChannelAssignments(1:2) = 1:2;
            end
            if numberOfMulticlampChannels > 2
                app.AmplifierChannelAssignments(3:4) = 3:4;
            end
            app.HardwareUIFigure.Visible = "on";
        end

        % Value changed function: boardnameDropDown
        function boardnameDropDownValueChanged(app, event)
            value = app.boardnameDropDown.Value;
            items = app.boardnameDropDown.Items;
            app.BoardIdx = find(contains(items,value));
            boardOkay = updatechannels(app);
            if boardOkay == 0
                disp('Error. Please check DAQ board.')
            end
        end

        % Button pushed function: updatechannelsButton
        function updatechannelsButtonPushed(app, event)
            channelsOkay = true;
            aiChannels = cell(8,1);
            aoChannels = cell(4,1);
            digitalChannels = cell(4,1);
            for ii = 1:8
                fStr = ['AIChannel_',num2str(ii)];
                aiChannels{ii} = app.(fStr).Value;
                foo = find(strcmp(aiChannels,aiChannels{ii}));
                if numel(foo) > 1
                     warndlg('Error. An AI channel has been selected more than once.', 'Check AI selections');
                     channelsOkay = false;
                end
            end
            for ii = 1:4
                fStr = ['AOChannel_',num2str(ii)];
                aoChannels{ii} = app.(fStr).Value;
                gStr = ['DIOChannel_',num2str(ii)];
                digitalChannels{ii} = app.(gStr).Value;
                foo = find(strcmp(aoChannels,aoChannels{ii}));
                boo = find(strcmp(digitalChannels,digitalChannels{ii}));
                if numel(foo) > 1 && ~strcmp(aoChannels{ii},'off')
                    warndlg('Error. An AO channel has been selected more than once.', 'Check AO selections');
                    channelsOkay = false;
                end
                if numel(boo) > 1
                    warndlg('Error. A DIO channel has been selected more than once.', 'Check DIO selections');
                    channelsOkay = false;
                end
            end
            if channelsOkay
                app.AIchannels = aiChannels;
                app.AOchannels = aoChannels;
                app.DIOchannels = digitalChannels;
                % eliminate 'off' channels from the choices in the
                % amplifier subpanel
                foo = find(~strcmp(aoChannels,'off'));
                foo = cellstr(num2str(foo));
                app.channelnumberDropDown.Items = foo;
            else
                % do not change anything
            end
            app.ChannelsOkay = channelsOkay;
        end

        % Selection changed function: amplifierButtonGroup
        function amplifierButtonGroupSelectionChanged(app, event)
            selectedButton = app.amplifierButtonGroup.SelectedObject;
            channelNumber = str2double(app.channelnumberDropDown.Value);
            for ii = 1:numel(app.AmplifierChannelNames)
                if strcmp(app.AmplifierChannelNames(ii),selectedButton.Text)
                    idx = ii;
                end
            end
            if idx==5
                idx = channelNumber + 4;
            end
            app.voltageclampOutputEditField.Value = app.AmplifierInfo(idx).outputScalingVoltageClamp;
            app.voltageclampInputEditField.Value = app.AmplifierInfo(idx).inputScalingVoltageClamp;
            app.currentclampOutputEditField.Value = app.AmplifierInfo(idx).outputScalingCurrentClamp;
            app.currentclampInputEditField.Value = app.AmplifierInfo(idx).inputScalingCurrentClamp;
            app.AmplifierChannelAssignments(channelNumber) = idx;
            if idx < 5
                editable = 0;
            else
                editable = 1;
            end
            app.voltageclampInputEditField.Editable = editable;
            app.voltageclampOutputEditField.Editable = editable;
            app.currentclampInputEditField.Editable = editable;
            app.currentclampOutputEditField.Editable = editable;
        end

        % Value changed function: channelnumberDropDown
        function channelnumberDropDownValueChanged(app, event)
            channelIdx = str2double(app.channelnumberDropDown.Value);
            buttonNumber = app.AmplifierChannelAssignments(channelIdx);
            if buttonNumber>4, buttonNumber=5; end
            buttonName = strrep(app.AmplifierChannelNames{buttonNumber},' ','');
            app.([buttonName,'Button']).Value = true;
            app.amplifierButtonGroupSelectionChanged;
        end

        % Value changed function: voltageclampInputEditField
        function voltageclampInputEditFieldValueChanged(app, event)
            value = app.voltageclampInputEditField.Value;
            channelNumber = str2double(app.channelnumberDropDown.Value);
            idx = channelNumber + 4; % index of "manual" entry
            app.AmplifierInfo(idx).inputScalingVoltageClamp = value;
        end

        % Value changed function: currentclampInputEditField
        function currentclampInputEditFieldValueChanged(app, event)
            value = app.currentclampInputEditField.Value;
            channelNumber = str2double(app.channelnumberDropDown.Value);
            idx = channelNumber + 4; % index of "manual" entry
            app.AmplifierInfo(idx).inputScalingCurrentClamp = value;
        end

        % Value changed function: voltageclampOutputEditField
        function voltageclampOutputEditFieldValueChanged(app, event)
            value = app.voltageclampOutputEditField.Value;
            channelNumber = str2double(app.channelnumberDropDown.Value);
            idx = channelNumber + 4; % index of "manual" entry
            app.AmplifierInfo(idx).outputScalingVoltageClamp = value;
        end

        % Value changed function: currentclampOutputEditField
        function currentclampOutputEditFieldValueChanged(app, event)
            value = app.currentclampOutputEditField.Value;
            channelNumber = str2double(app.channelnumberDropDown.Value);
            idx = channelNumber + 4; % index of "manual" entry
            app.AmplifierInfo(idx).outputScalingCurrentClamp = value;
        end

        % Button pushed function: updateamplifiersettingsButton
        function updateamplifiersettingsButtonPushed(app, event)
            % check that each Multiclamp channel is used only once
            numbers = app.AmplifierChannelAssignments;
            numbers(numbers>4) = []; % get rid of "manual"
            if numel(numbers) ~= numel(unique(numbers))
                warndlg('A Multiclamp channel has been selected more than once.', 'Check selections');
                return
            end
        end

        % Button pushed function: finalizeButton
        function finalizeButtonPushed(app, event)
            global DAQPARS %#ok<*GVMIS> 
            hConfig.amplifierInfo = app.AmplifierInfo;
            hConfig.amplifierIdx = app.AmplifierChannelAssignments;
            hConfig.amplifierIdx(end+1:end+4) = 8; % channels 5-8 are manual (other)
            idx = app.BoardIdx;
            hConfig.daqBoardInfo.Terminals = app.Boards.DeviceInfo(idx).Terminals;
            hConfig.daqBoardInfo.ID = app.Boards.DeviceInfo(idx).ID;
            hConfig.daqBoardInfo.Model = app.Boards.DeviceInfo(idx).Model;
            hConfig.daqBoardInfo.Description = app.Boards.DeviceInfo(idx).Description;
            hConfig.daqBoardIdx = app.BoardIdx;
            hConfig.daqBoardChannels = [app.AIchannels'; app.AOchannels' app.DIOchannels'];
            app.CallingApp.HardwareConfig = hConfig;
            fileName = [DAQPARS.daqFolder,'\parameters_and_gui\hardwareConfiguration.mat'];
            save(fileName,'-struct','hConfig')
            hardwareOkay = checkhardware;
            if hardwareOkay 
                if isfield(DAQPARS,'inputChannels')
                    DAQPARS.daqObj = nidaqboard;
                end
            else
                error('Hardware problem. Please check.')
            end
            delete(app)
        end

        % Button pushed function: cancelButton
        function cancelButtonPushed(app, event)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.HardwareUIFigure = uifigure('Visible', 'off');
            app.HardwareUIFigure.Name = 'Hardware';
            app.HardwareUIFigure.Tag = 'LCSMS_hardware';
            app.daqboardPanel = uipanel(app.HardwareUIFigure);
            app.daqboardPanel.Title = 'daq board';
            app.boardnameDropDownLabel = uilabel(app.daqboardPanel);
            app.boardnameDropDownLabel.Text = 'board name';
            app.boardnameDropDown = uidropdown(app.daqboardPanel);
            app.boardnameDropDown.ValueChangedFcn = createCallbackFcn(app, @boardnameDropDownValueChanged, true);
            app.channels14Panel = uipanel(app.daqboardPanel);
            app.channels14Panel.Title = 'channels 1-4';
            app.AOChannel_1 = uidropdown(app.channels14Panel);
            app.AOChannel_1.Items = {'ao0', 'ao1', 'ao2', 'ao3'};
            app.AOChannel_1.Value = 'ao0';
            app.AOChannel_2 = uidropdown(app.channels14Panel);
            app.AOChannel_2.Items = {'ao0', 'ao1', 'ao2', 'ao3'};
            app.AOChannel_2.Value = 'ao1';
            app.AOChannel_3 = uidropdown(app.channels14Panel);
            app.AOChannel_3.Items = {'ao0', 'ao1', 'ao2', 'ao3'};
            app.AOChannel_3.Value = 'ao2';
            app.AOChannel_4 = uidropdown(app.channels14Panel);
            app.AOChannel_4.Items = {'ao0', 'ao1', 'ao2', 'ao3'};
            app.AOChannel_4.Value = 'ao3';
            app.AIChannel_1 = uidropdown(app.channels14Panel);
            app.AIChannel_1.Items = {'ai0', 'ai1', 'ai2', 'ai3', 'ai4', 'ai5', 'ai6', 'ai7', 'ai8'};
            app.AIChannel_1.Value = 'ai0';
            app.AIChannel_2 = uidropdown(app.channels14Panel);
            app.AIChannel_2.Items = {'ai0', 'ai1', 'ai2', 'ai3', 'ai4', 'ai5', 'ai6', 'ai7', 'ai8'};
            app.AIChannel_2.Value = 'ai1';
            app.AIChannel_3 = uidropdown(app.channels14Panel);
            app.AIChannel_3.Items = {'ai0', 'ai1', 'ai2', 'ai3', 'ai4', 'ai5', 'ai6', 'ai7', 'ai8'};
            app.AIChannel_3.Value = 'ai2';
            app.AIChannel_4 = uidropdown(app.channels14Panel);
            app.AIChannel_4.Items = {'ai0', 'ai1', 'ai2', 'ai3', 'ai4', 'ai5', 'ai6', 'ai7', 'ai8'};
            app.AIChannel_4.Value = 'ai3';
            app.analoginputsLabel = uilabel(app.channels14Panel);
            app.analoginputsLabel.Text = 'analog inputs';
            app.analogoutputsLabel = uilabel(app.channels14Panel);
            app.analogoutputsLabel.Text = 'analog outputs';
            app.channels58Panel = uipanel(app.daqboardPanel);
            app.channels58Panel.Title = 'channels 5-8';
            app.DIOChannel_1 = uidropdown(app.channels58Panel);
            app.DIOChannel_1.Items = {'P0/L0', 'P0/L1', 'P0/L2', 'P0/L3'};
            app.DIOChannel_1.Value = 'P0/L0';
            app.DIOChannel_2 = uidropdown(app.channels58Panel);
            app.DIOChannel_2.Items = {'P0/L0', 'P0/L1', 'P0/L2', 'P0/L3'};
            app.DIOChannel_2.Value = 'P0/L1';
            app.DIOChannel_3 = uidropdown(app.channels58Panel);
            app.DIOChannel_3.Items = {'P0/L0', 'P0/L1', 'P0/L2', 'P0/L3'};
            app.DIOChannel_3.Value = 'P0/L2';
            app.DIOChannel_4 = uidropdown(app.channels58Panel);
            app.DIOChannel_4.Items = {'P0/L0', 'P0/L1', 'P0/L2', 'P0/L3'};
            app.DIOChannel_4.Value = 'P0/L3';
            app.AIChannel_5 = uidropdown(app.channels58Panel);
            app.AIChannel_5.Items = {'ai0', 'ai1', 'ai2', 'ai3', 'ai4', 'ai5', 'ai6', 'ai7', 'ai8'};
            app.AIChannel_5.Value = 'ai4';
            app.AIChannel_6 = uidropdown(app.channels58Panel);
            app.AIChannel_6.Items = {'ai0', 'ai1', 'ai2', 'ai3', 'ai4', 'ai5', 'ai6', 'ai7', 'ai8'};
            app.AIChannel_6.Value = 'ai5';
            app.AIChannel_7 = uidropdown(app.channels58Panel);
            app.AIChannel_7.Items = {'ai0', 'ai1', 'ai2', 'ai3', 'ai4', 'ai5', 'ai6', 'ai7', 'ai8'};
            app.AIChannel_7.Value = 'ai6';
            app.AIChannel_8 = uidropdown(app.channels58Panel);
            app.AIChannel_8.Items = {'ai0', 'ai1', 'ai2', 'ai3', 'ai4', 'ai5', 'ai6', 'ai7', 'ai8'};
            app.AIChannel_8.Value = 'ai7';
            app.analoginputsLabel_2 = uilabel(app.channels58Panel);
            app.analoginputsLabel_2.Text = 'analog inputs';
            app.digitaloutputsLabel = uilabel(app.channels58Panel);
            app.digitaloutputsLabel.Text = 'digital outputs';
            app.updatechannelsButton = uibutton(app.daqboardPanel, 'push');
            app.updatechannelsButton.ButtonPushedFcn = createCallbackFcn(app, @updatechannelsButtonPushed, true);
            app.updatechannelsButton.Text = 'update channels';
            app.patchclampamplifierPanel = uipanel(app.HardwareUIFigure);
            app.patchclampamplifierPanel.Title = 'patch clamp amplifier';
            app.channelnumberDropDownLabel = uilabel(app.patchclampamplifierPanel);
            app.channelnumberDropDownLabel.Text = 'channel number';
            app.channelnumberDropDown = uidropdown(app.patchclampamplifierPanel);
            app.channelnumberDropDown.Items = {'1', '2', '3', '4'};
            app.channelnumberDropDown.ValueChangedFcn = createCallbackFcn(app, @channelnumberDropDownValueChanged, true);
            app.channelnumberDropDown.Value = '1';
            app.amplifierButtonGroup = uibuttongroup(app.patchclampamplifierPanel);
            app.amplifierButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @amplifierButtonGroupSelectionChanged, true);
            app.amplifierButtonGroup.Title = 'amplifier';
            app.MulticlampA1Button = uitogglebutton(app.amplifierButtonGroup);
            app.MulticlampA1Button.Text = 'Multiclamp A1';
            app.MulticlampA1Button.Value = true;
            app.MulticlampA2Button = uitogglebutton(app.amplifierButtonGroup);
            app.MulticlampA2Button.Text = 'Multiclamp A2';
            app.MulticlampB1Button = uitogglebutton(app.amplifierButtonGroup);
            app.MulticlampB1Button.Text = 'Multiclamp B1';
            app.MulticlampB2Button = uitogglebutton(app.amplifierButtonGroup);
            app.MulticlampB2Button.Text = 'Multiclamp B2';
            app.manualButton = uitogglebutton(app.amplifierButtonGroup);
            app.manualButton.Text = 'manual';
            app.voltageclampLabel = uilabel(app.patchclampamplifierPanel);
            app.voltageclampLabel.Text = 'voltage clamp';
            app.currentclampLabel = uilabel(app.patchclampamplifierPanel);
            app.currentclampLabel.Text = 'current clamp';
            app.outputLabel = uilabel(app.patchclampamplifierPanel);
            app.outputLabel.Text = 'output';
            app.inputLabel_2 = uilabel(app.patchclampamplifierPanel);
            app.inputLabel_2.Text = 'input';
            app.pAVLabel_4 = uilabel(app.patchclampamplifierPanel);
            app.pAVLabel_4.Text = 'pA/V';
            app.pAVLabel_3 = uilabel(app.patchclampamplifierPanel);
            app.pAVLabel_3.Text = 'pA/V';
            app.mVVLabel_3 = uilabel(app.patchclampamplifierPanel);
            app.mVVLabel_3.Text = 'mV/V';
            app.mVVLabel_4 = uilabel(app.patchclampamplifierPanel);
            app.mVVLabel_4.Text = 'mV/V';
            app.updateamplifiersettingsButton = uibutton(app.patchclampamplifierPanel, 'push');
            app.updateamplifiersettingsButton.ButtonPushedFcn = createCallbackFcn(app, @updateamplifiersettingsButtonPushed, true);
            app.updateamplifiersettingsButton.Text = 'update amplifier settings';
            app.voltageclampInputEditField = uieditfield(app.patchclampamplifierPanel, 'numeric');
            app.voltageclampInputEditField.Limits = [0 10000];
            app.voltageclampInputEditField.RoundFractionalValues = 'on';
            app.voltageclampInputEditField.ValueDisplayFormat = '%.0f';
            app.voltageclampInputEditField.ValueChangedFcn = createCallbackFcn(app, @voltageclampInputEditFieldValueChanged, true);
            app.voltageclampInputEditField.Editable = 'off';
            app.voltageclampOutputEditField = uieditfield(app.patchclampamplifierPanel, 'numeric');
            app.voltageclampOutputEditField.Limits = [0 10000];
            app.voltageclampOutputEditField.ValueDisplayFormat = '%.0f';
            app.voltageclampOutputEditField.ValueChangedFcn = createCallbackFcn(app, @voltageclampOutputEditFieldValueChanged, true);
            app.voltageclampOutputEditField.Editable = 'off';
            app.currentclampInputEditField = uieditfield(app.patchclampamplifierPanel, 'numeric');
            app.currentclampInputEditField.Limits = [0 10000];
            app.currentclampInputEditField.ValueDisplayFormat = '%.0f';
            app.currentclampInputEditField.ValueChangedFcn = createCallbackFcn(app, @currentclampInputEditFieldValueChanged, true);
            app.currentclampOutputEditField = uieditfield(app.patchclampamplifierPanel, 'numeric');
            app.currentclampOutputEditField.Limits = [0 10000];
            app.currentclampOutputEditField.ValueDisplayFormat = '%.0f';
            app.currentclampOutputEditField.ValueChangedFcn = createCallbackFcn(app, @currentclampOutputEditFieldValueChanged, true);
            app.finalizeButton = uibutton(app.HardwareUIFigure, 'push');
            app.finalizeButton.ButtonPushedFcn = createCallbackFcn(app, @finalizeButtonPushed, true);
            app.finalizeButton.Text = 'finalize';
            app.cancelButton = uibutton(app.HardwareUIFigure, 'push');
            app.cancelButton.ButtonPushedFcn = createCallbackFcn(app, @cancelButtonPushed, true);
            app.cancelButton.Text = 'cancel';
            % Show the figure after all components are created
            app.HardwareUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = hardware(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.HardwareUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.HardwareUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.HardwareUIFigure)
        end
    end
end